classdef Hamilton_source < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HAMILTONUIFigure            matlab.ui.Figure
        savesetting                 matlab.ui.container.Menu
        Save_exit                   matlab.ui.container.Menu
        Save_now                    matlab.ui.container.Menu
        View                        matlab.ui.container.Menu
        Percentage_of_RowColumn     matlab.ui.container.Menu
        RowColumn_Completed         matlab.ui.container.Menu
        Total_bars                  matlab.ui.container.Menu
        Wait_bars                   matlab.ui.container.Menu
        Help                        matlab.ui.container.Menu
        About                       matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        panelFrequencyModulationIndicator  matlab.ui.container.Panel
        I_3Label                    matlab.ui.control.Label
        I3_o                        matlab.ui.control.NumericEditField
        uipanel18                   matlab.ui.container.Panel
        pushbutton_level            matlab.ui.control.Button
        pushbutton_FMI              matlab.ui.control.Button
        pushbutton_freq             matlab.ui.control.Button
        uipanel10                   matlab.ui.container.Panel
        leqI_2leqLabel              matlab.ui.control.Label
        step_2                      matlab.ui.control.NumericEditField
        I2_fin                      matlab.ui.control.NumericEditField
        I2_in                       matlab.ui.control.NumericEditField
        text4                       matlab.ui.control.Label
        uipanel9                    matlab.ui.container.Panel
        leqI_1leqLabel              matlab.ui.control.Label
        step_1                      matlab.ui.control.NumericEditField
        I1_fin                      matlab.ui.control.NumericEditField
        I1_in                       matlab.ui.control.NumericEditField
        text3                       matlab.ui.control.Label
        panel_Freq1                 matlab.ui.container.Panel
        Step_FMI1                   matlab.ui.control.NumericEditField
        freq1_max                   matlab.ui.control.NumericEditField
        freq1_min                   matlab.ui.control.NumericEditField
        text10                      matlab.ui.control.Label
        text9                       matlab.ui.control.Label
        text8                       matlab.ui.control.Label
        text7                       matlab.ui.control.Label
        panel_Freq3                 matlab.ui.container.Panel
        Step_FMI3                   matlab.ui.control.NumericEditField
        freq3_max                   matlab.ui.control.NumericEditField
        freq3_min                   matlab.ui.control.NumericEditField
        text14                      matlab.ui.control.Label
        text13                      matlab.ui.control.Label
        text12                      matlab.ui.control.Label
        text11                      matlab.ui.control.Label
        panel_Freq2                 matlab.ui.container.Panel
        Step_FMI2                   matlab.ui.control.NumericEditField
        freq2_max                   matlab.ui.control.NumericEditField
        freq2_min                   matlab.ui.control.NumericEditField
        text18                      matlab.ui.control.Label
        text17                      matlab.ui.control.Label
        text16                      matlab.ui.control.Label
        text15                      matlab.ui.control.Label
        pushbutton_Calculate        matlab.ui.control.Button
        pushbutton_Slave            matlab.ui.control.Button
        panelTimeFrequencyAnalysis  matlab.ui.container.Panel
        I3_FMFT                     matlab.ui.control.NumericEditField
        I_3EditFieldLabel           matlab.ui.control.Label
        I2_FMFT                     matlab.ui.control.NumericEditField
        I_2EditFieldLabel           matlab.ui.control.Label
        I1_FMFT                     matlab.ui.control.NumericEditField
        I_1EditFieldLabel           matlab.ui.control.Label
        panel_Wavelet               matlab.ui.container.Panel
        edit_variance               matlab.ui.control.NumericEditField
        edit_max_freq               matlab.ui.control.NumericEditField
        edit_min_freq               matlab.ui.control.NumericEditField
        text42                      matlab.ui.control.Label
        text41                      matlab.ui.control.Label
        text40                      matlab.ui.control.Label
        pushbutton_wavelet          matlab.ui.control.Button
        panel_FFT                   matlab.ui.container.Panel
        check_log                   matlab.ui.control.CheckBox
        pushbutton_FFT              matlab.ui.control.Button
        panel_FMFT                  matlab.ui.container.Panel
        edit_tofreq                 matlab.ui.control.NumericEditField
        edit_fromfreq               matlab.ui.control.NumericEditField
        edit_nfreq                  matlab.ui.control.NumericEditField
        pushbutton_FMFT             matlab.ui.control.Button
        popupmenu_FMFT_flag         matlab.ui.control.DropDown
        text39                      matlab.ui.control.Label
        text38                      matlab.ui.control.Label
        text37                      matlab.ui.control.Label
        text36                      matlab.ui.control.Label
        text35                      matlab.ui.control.Label
        panelODEs0lver              matlab.ui.container.Panel
        panel_opt_ODE               matlab.ui.container.Panel
        RelTol                      matlab.ui.control.NumericEditField
        RelativeTolEditFieldLabel   matlab.ui.control.Label
        AbsTol                      matlab.ui.control.NumericEditField
        AbsoluteTolEditFieldLabel   matlab.ui.control.Label
        step_ODE                    matlab.ui.control.NumericEditField
        OutputstepEditFieldLabel    matlab.ui.control.Label
        popupmenuODE                matlab.ui.control.DropDown
        panelHamiltonian            matlab.ui.container.Panel
        edit_time                   matlab.ui.control.NumericEditField
        IntegrationintervalEditFieldLabel  matlab.ui.control.Label
        edit_eps                    matlab.ui.control.NumericEditField
        PerturbativeparameterEditFieldLabel  matlab.ui.control.Label
        popupHamilton               matlab.ui.control.DropDown
    end

    
    properties (Access = private)
HamiltonDir
PhSpGeoDir 
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function HAMILTON_startup(app)
app.HamiltonDir = cd;
cd ..
cd ..
cd ..
app.PhSpGeoDir = cd;
cd(app.HamiltonDir)
delete('*.dat')
delete('*.txt')

if isdeployed
    %do nothing
else
    path([app.HamiltonDir,'/Help'],path);
    path([app.HamiltonDir,'/FunctionScript'],path);
    path([app.PhSpGeoDir,'/Util'], path);
    path([app.PhSpGeoDir,'/Portraits'], path);
    path([app.PhSpGeoDir,'/Util/Multicore'], path);
    path([app.PhSpGeoDir,'/AddedODEsolver/dop853'], path);
    path([app.PhSpGeoDir,'/AddedODEsolver/BulirschStoer'], path);
    path([app.PhSpGeoDir,'/AddedODEsolver/ABM8'], path);
    path([app.PhSpGeoDir,'/AddedODEsolver/ShampineGordon'], path); 
    path(app.HamiltonDir, path);
end

app.pushbutton_Calculate.Icon = 'PhSpGeo.jpg';
app.pushbutton_Slave.Icon = 'Schiavo.jpg';
app.pushbutton_FFT.Icon = 'FFT.jpg';
app.pushbutton_FMFT.Icon = 'FMFT.jpg';
app.pushbutton_wavelet.Icon = 'onda.jpg';


%%%%%%%%%%%%%%%% Load what was saved %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
cd(app.HamiltonDir)
cd ..
if exist('Setting.mat','file')
    load('Setting.mat')                                                    %#ok<*LOAD>

    set(app.popupHamilton,'Value',Hamiltonian);
    set(app.edit_eps,'Value',epsilon);
    set(app.edit_time,'Value',time);

    set(app.popupmenuODE,'Value',ODE);
    set(app.RelTol,'Value',RelTol);
    set(app.AbsTol,'Value',AbsTol);
    set(app.step_ODE,'Value',stepODE);
    
    set(app.I1_in,'Value',I1_in);
    set(app.I1_fin,'Value',I1_fin);
    set(app.step_1,'Value',step_1);
    set(app.I2_in,'Value',I2_in);
    set(app.I2_fin,'Value',I2_fin);
    set(app.step_2,'Value',step_2);
    set(app.I3_o,'Value',I3_o);
    set(app.freq1_min,'Value',freq1_min);
    set(app.freq1_max,'Value',freq1_max);
    set(app.Step_FMI1,'Value',Step_FMI1);
    set(app.freq2_min,'Value',freq2_min);
    set(app.freq2_max,'Value',freq2_max);
    set(app.Step_FMI2,'Value',Step_FMI2);
    set(app.freq3_min,'Value',freq3_min);
    set(app.freq3_max,'Value',freq3_max);
    set(app.Step_FMI3,'Value',Step_FMI3);
    set(app.Percentage_of_RowColumn, 'Checked',View1);
    set(app.RowColumn_Completed, 'Checked',View2);
    set(app.Total_bars, 'Checked',View3);
    set(app.Wait_bars, 'Checked',View4);   

    if get(app.step_1,'Value') == 0
        set(app.I1_fin,'Enable','off')
        set(app.leqI_1leqLabel,'Text','$=I_1$','Position',[84 34 50 22])
    end
    if get(app.step_2,'Value') == 0
        set(app.I2_fin,'Enable','off')
        set(app.leqI_2leqLabel,'Text','$=I_2$','Position',[84 34 50 22])
    end
end
cd(app.HamiltonDir)
 
%%%%%%%%%%%%%%% Possibly make Slave button visible %%%%%%%%%%%%%%%%%%%%%%%%
if ~isnan(findstr(app.HamiltonDir, 'Slave'))                               %#ok<*FSTR> 
    set(app.pushbutton_Slave, 'Visible', 'on')
    set(app.pushbutton_Calculate, 'Visible', 'off')

    set(app.popupHamilton, 'Enable', 'off');
    set(app.edit_eps, 'Enable', 'off');
    set(app.edit_time, 'Enable', 'off');
    
    set(app.popupmenuODE, 'Enable', 'off');
    set(app.RelTol, 'Enable', 'off');
    set(app.AbsTol, 'Enable', 'off');
    set(app.step_ODE, 'Enable', 'off');

    set(app.I1_FMFT, 'Enable', 'off');
    set(app.I2_FMFT, 'Enable', 'off');
    set(app.I3_FMFT, 'Enable', 'off');
    set(app.check_log, 'Enable', 'off');
    set(app.pushbutton_FFT, 'Enable', 'off');
    set(app.edit_nfreq, 'Enable', 'off');
    set(app.edit_fromfreq, 'Enable', 'off');
    set(app.edit_tofreq, 'Enable', 'off');
    set(app.popupmenu_FMFT_flag, 'Enable', 'off');
    set(app.pushbutton_FMFT, 'Enable', 'off');
    set(app.edit_min_freq, 'Enable', 'off');
    set(app.edit_max_freq, 'Enable', 'off');
    set(app.edit_variance, 'Enable', 'off');
    set(app.pushbutton_wavelet, 'Enable', 'off');

    set(app.I1_in, 'Enable', 'off');
    set(app.I1_fin, 'Enable', 'off');
    set(app.step_1, 'Enable', 'off');
    set(app.I2_in, 'Enable', 'off');
    set(app.I2_fin, 'Enable', 'off');
    set(app.step_2, 'Enable', 'off');
    set(app.I3_o, 'Enable', 'off');
    set(app.freq1_min, 'Enable', 'off');
    set(app.freq1_max, 'Enable', 'off');
    set(app.Step_FMI1, 'Enable', 'off');
    set(app.freq2_min, 'Enable', 'off');
    set(app.freq2_max, 'Enable', 'off');
    set(app.Step_FMI2, 'Enable', 'off');
    set(app.freq3_min, 'Enable', 'off');
    set(app.freq3_max, 'Enable', 'off');
    set(app.Step_FMI3, 'Enable', 'off');
  
else
    cd ..
    if exist('multicorefiles','dir')
        rmdir('multicorefiles','s')
    end
    mkdir('multicorefiles')
    cd(app.HamiltonDir)
end
             
%%%%%%%%%%%%%%%%%%%%%% Set Name %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
list = strread(cd, '%s','delimiter',strrep(filesep,'\','\\'));             %#ok<*DSTRRD> 
dim = size(list);
curdir = [list{dim(1,1), dim(1,2)},' - ',list{dim(1,1) - 1, dim(1,2)}];
set(app.HAMILTONUIFigure, 'Name', curdir)

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Button pushed function: pushbutton_FFT
        function exec_FFT(app, event)
hh = helpdlg(sprintf('Please wait, HAMILTON is calculating the Fast Fourier Transform'),'FFT');
global epsilon
DiffEquation = get(app.popupHamilton,'Value');
epsilon = get(app.edit_eps,'Value');
tau_total = get(app.edit_time,'Value');
ODE = get(app.popupmenuODE,'Value');
Rel_Tol = get(app.RelTol,'Value');
Abs_Tol = get(app.AbsTol,'Value');
OutStep = get(app.step_ODE,'Value');
OutStep_string = num2str(OutStep);
freq_max = 2*pi/OutStep;
freq_max = 2*pi*freq_max;
I1_o = get(app.I1_FMFT,'Value');
I2_o = get(app.I2_FMFT,'Value');
I3_o = get(app.I3_FMFT,'Value');
Xo = [0;  0;  0;  I1_o;  I2_o;  I3_o];
if strcmp(ODE, 'BulirschStoer')
    options = Abs_Tol;
else
   options = odeset('RelTol',[Rel_Tol], 'AbsTol',[Abs_Tol]);
end
[tau,W] = eval([ODE, '(@',DiffEquation,', [0 :',OutStep_string,': tau_total], Xo, options)']);
close(hh)
d = size(W(:,1),1);
nump = 2^(floor(log2(d)));    
freq = [0 :freq_max/nump: freq_max];
freq(nump) = [];    
freq = (freq - freq_max/2)/(2*pi);
valCheck = get(app.check_log,'Value');    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
%                     Plot FFT of I1   
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
F = fft((W(:,4).*cos(W(:,1)) + i*W(:,4).*sin(W(:,1))), nump);    
figure    
Farray = sqrt(real(F).^2 + imag(F).^2);
Farray1 = Farray(1:nump/2, 1);    
Farray2 = Farray(nump/2+1:nump, 1);    
Farray = [Farray2;  Farray1];
if valCheck == 0;    
    plot(freq, Farray/nump)    
else    
    semilogy(freq, Farray/nump)    
end    
title({'Fast Fourier Transform for \itI_1  \phi_1'})    
xlabel('Frequency', 'FontSize',10), ylabel('Amplitude', 'FontSize',10)    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
%                     Plot FFT of I2   
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
F = fft((W(:,5).*cos(W(:,2)) + i*W(:,5).*sin(W(:,2))), nump);    
figure    
Farray = sqrt(real(F).^2 + imag(F).^2);
Farray1 = Farray(1:nump/2, 1);    
Farray2 = Farray(nump/2+1:nump, 1);    
Farray = [Farray2;  Farray1];
if valCheck == 0;    
    plot(freq, Farray/nump)    
else    
    semilogy(freq, Farray/nump)    
end    
title({'Fast Fourier Transform for \itI_2  \phi_2'})    
xlabel('Frequency', 'FontSize',10), ylabel('Amplitude', 'FontSize',10)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
%                     Plot FFT of I3   
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
F = fft((W(:,6).*cos(W(:,3)) + i*W(:,6).*sin(W(:,3))), nump);    
figure    
Farray = sqrt(real(F).^2 + imag(F).^2);
Farray1 = Farray(1:nump/2, 1);    
Farray2 = Farray(nump/2+1:nump, 1);    
Farray = [Farray2;  Farray1];
if valCheck == 0;    
    plot(freq, Farray/nump)    
else    
    semilogy(freq, Farray/nump)    
end    
title({'Fast Fourier Transform for \itI_3  \phi_3'})    
xlabel('Frequency', 'FontSize',10), ylabel('Amplitude', 'FontSize',10)
          
        end

        % Button pushed function: pushbutton_FMFT
        function exec_FMFT(app, event)
hh = helpdlg(sprintf('Please wait, HAMILTON is calculating the Fast Modified Fourier Transform.'),'FMFT');
global epsilon
HamiltonDir = app.HamiltonDir;
PhSpGeoDir = app.PhSpGeoDir;
cd(HamiltonDir)
DiffEquation = get(app.popupHamilton,'Value');
epsilon = get(app.edit_eps,'Value');
tau_total = get(app.edit_time,'Value');
ODE = get(app.popupmenuODE,'Value');
Rel_Tol = get(app.RelTol,'Value');
Abs_Tol = get(app.AbsTol,'Value');
OutStep = get(app.step_ODE,'Value');
OutStep_string = num2str(OutStep);
freq_max = 2*pi/OutStep;
%freq_max = 2*pi*freq_max;
I1_o = get(app.I1_FMFT,'Value');
I2_o = get(app.I2_FMFT,'Value');
I3_o = get(app.I3_FMFT,'Value');
Xo = [0;  0;  0;  I1_o;  I2_o;  I3_o];
if strcmp(ODE, 'BulirschStoer')
    options = Abs_Tol;
else
   options = odeset('RelTol',[Rel_Tol], 'AbsTol',[Abs_Tol]);
end
[tau,W] = eval([ODE, '(@',DiffEquation,', [0 :',OutStep_string,': tau_total], Xo, options)']);
min_f = num2str(1000*get(app.edit_fromfreq,'Value'),'%6.12f');
max_f = num2str(1000*get(app.edit_tofreq,'Value'),'%6.12f');    
flag_FMFT = num2str(get(app.popupmenu_FMFT_flag,'Value'));    
nfreq = num2str(get(app.edit_nfreq,'Value'));
data_sep = num2str(100000*OutStep);
d = size(W(:,1),1);    
ndata = num2str(2^(floor(log2(d))));
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%              Write inputAct1.txt
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
Act1x = W(:,4).*cos(W(:,1));
Act1y = W(:,4).*sin(W(:,1));
fid = fopen(['inputAct1.txt'], 'wt');
fprintf(fid, '%14.10f %14.10f %14.10f\n',...
    [tau'; Act1x'; Act1y']);
fclose(fid);
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%              Write inputAct2.txt
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
Act2x = W(:,5).*cos(W(:,2));
Act2y = W(:,5).*sin(W(:,2));
fid = fopen(['inputAct2.txt'], 'wt');
fprintf(fid, '%14.10f %14.10f %14.10f\n',...
    [tau'; Act2x'; Act2y']);
fclose(fid);
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%              Write inputAct3.txt
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
Act3x = W(:,6).*cos(W(:,3));
Act3y = W(:,6).*sin(W(:,3));
fid = fopen(['inputAct3.txt'], 'wt');
fprintf(fid, '%14.10f %14.10f %14.10f\n',...
    [tau'; Act3x'; Act3y']);
fclose(fid);
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
cmd_string = ['!',PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...    
    data_sep,' ',ndata,' ','<inputAct1.txt',' ','>FMFT_of_Act1.txt'];    
eval(cmd_string)    
delete inputAct1.txt    
cmd_string = ['!',PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...    
    data_sep,' ',ndata,' ','<inputAct2.txt',' ','>FMFT_of_Act2.txt'];    
eval(cmd_string)    
delete inputAct2.txt    
cmd_string = ['!',PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...    
    data_sep,' ',ndata,' ','<inputAct3.txt',' ','>FMFT_of_Act3.txt'];    
eval(cmd_string)    
delete inputAct3.txt
close(hh);

[Freq  Amp Phase] = textread('FMFT_of_Act1.txt', '%f %f %f', 'headerlines', 4);
figure
bar(Freq/1000,(log10(Amp) + ceil(abs(min(log10(Amp))))),0.2)     
title('FMFT for Action 1')    
xlabel('Frequency', 'FontSize',10)

[Freq  Amp Phase] = textread('FMFT_of_Act2.txt', '%f %f %f', 'headerlines', 4);
figure
bar(Freq/1000,(log10(Amp) + ceil(abs(min(log10(Amp))))),0.2)     
title('FMFT for Action 2')    
xlabel('Frequency', 'FontSize',10)

[Freq  Amp Phase] = textread('FMFT_of_Act3.txt', '%f %f %f', 'headerlines', 4);
figure
bar(Freq/1000,(log10(Amp) + ceil(abs(min(log10(Amp))))),0.2)     
title('FMFT for Action 3')    
xlabel('Frequency', 'FontSize',10)
        end

        % Button pushed function: pushbutton_wavelet
        function exec_Wavelet(app, event)
hh = helpdlg(sprintf('Please wait, HAMILTON is calculating the Wavelet Transform.'),'Wavelet Transform');
global epsilon
HamiltonDir = app.HamiltonDir;
cd(HamiltonDir)
DiffEquation = get(app.popupHamilton,'Value');
epsilon = get(app.edit_eps,'Value');
ODE = get(app.popupmenuODE,'Value'); 
min_freq = get(app.edit_min_freq,'Value');
max_freq = get(app.edit_max_freq,'Value');
eta = 1;
sigma2 = (2*pi*get(app.edit_variance,'Value'))^2;
fourier_factor = 2/(eta + sqrt(2/sigma2 + eta^2));
OutStep = get(app.step_ODE, 'Value');
OutStep_string = num2str(OutStep);
Rel_Tol = get(app.RelTol,'Value');
Abs_Tol = get(app.AbsTol,'Value');
tau_total = get(app.edit_time,'Value');
I1_o = get(app.I1_FMFT,'Value');
I2_o = get(app.I2_FMFT,'Value');
I3_o = get(app.I3_FMFT,'Value');
Xo = [0;  0;  0;  I1_o;  I2_o;  I3_o];
if strcmp(ODE, 'BulirschStoer')
    options = Abs_Tol;
else
   options = odeset('RelTol',[Rel_Tol], 'AbsTol',[Abs_Tol]);
end
[tau,W] = eval([ODE, '(@',DiffEquation,', [0 :',OutStep_string,': tau_total], Xo, options)']);

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
signal = W(:,4).*cos(W(:,1)) + i*W(:,4).*sin(W(:,1));
[wt, Frequency] = wavelet_transf(signal, OutStep/2/pi,min_freq, max_freq, eta, sigma2, fourier_factor);
spectrum = (abs(wt)).^2;
%if spectrum < 1000*eps
%    spectrum = 1000*eps;
%else
try
    figure
    colormap(gray)
    contour(tau, Frequency, spectrum)
    xlabel('Revolution number', 'FontSize',12), ylabel('Frequency \times 1000', 'FontSize',12),
    title('Frequency for  (\itI_1, \phi_1)', 'FontSize',12)
catch ME
    popupmessage('Error_msg.txt')
end

signal = W(:,5).*cos(W(:,2)) + i*W(:,5).*sin(W(:,2));
[wt, Frequency] = wavelet_transf(signal, OutStep/2/pi,min_freq, max_freq, eta, sigma2, fourier_factor);
spectrum = (abs(wt)).^2;
try
    figure
    colormap(gray)
    contour(tau, Frequency, spectrum)
    xlabel('Revolution number', 'FontSize',12), ylabel('Frequency \times 1000', 'FontSize',12),
    title('Frequency for  (\itI_2, \phi_2)', 'FontSize',12)
catch ME
    popupmessage('Error_msg.txt')
end

signal = W(:,6).*cos(W(:,3)) + i*W(:,6).*sin(W(:,3));
[wt, Frequency] = wavelet_transf(signal, OutStep/2/pi,min_freq, max_freq, eta, sigma2, fourier_factor);
spectrum = (abs(wt)).^2;
try
    figure
    colormap(gray)
    contour(tau, Frequency, spectrum)
    xlabel('Revolution number', 'FontSize',12), ylabel('Frequency \times 1000', 'FontSize',12),
    title('Frequency for (\itI_3, \phi_3)', 'FontSize',12)
catch ME
    popupmessage('Error_msg.txt')
end

close(hh)
        end

        % Button pushed function: pushbutton_Calculate
        function exec_CalculateFMI(app, event)
Disable_all
set(app.pushbutton_Calculate,'Enable','off')
HamiltonDir = app.HamiltonDir;
cd(HamiltonDir)

Hamiltonian = get(app.popupHamilton,'Value');
epsilon =     get(app.edit_eps,'Value');
total_time =  get(app.edit_time,'Value');
ODE =         get(app.popupmenuODE,'Value');
RelTol =      get(app.RelTol,'Value');
AbsTol =      get(app.AbsTol,'Value');
OutStep =     get(app.step_ODE,'Value');

I1_in =      get(app.I1_in,'Value');
I1_fin =     get(app.I1_fin,'Value');
numstep_I1 = get(app.step_1,'Value');
I2_in =      get(app.I2_in,'Value');
I2_fin =     get(app.I2_fin,'Value');
numstep_I2 = get(app.step_2,'Value');
I3_o =       get(app.I3_o,'Value');

freq1_min =  get(app.freq1_min,'Value');
freq1_max =  get(app.freq1_max,'Value');
Step_FMI1 =  get(app.Step_FMI1,'Value');
freq2_min =  get(app.freq2_min,'Value');
freq2_max =  get(app.freq2_max,'Value');
Step_FMI2 =  get(app.Step_FMI2,'Value');
freq3_min =  get(app.freq3_min,'Value');
freq3_max =  get(app.freq3_max,'Value');
Step_FMI3 =  get(app.Step_FMI3,'Value');

View1 =  get(app.Percentage_of_RowColumn, 'Checked');
View2 =  get(app.RowColumn_Completed, 'Checked');
View3 =  get(app.Total_bars, 'Checked');
View4 =  get(app.Wait_bars, 'Checked');

Init_Data ={Hamiltonian,epsilon,total_time,ODE,RelTol,AbsTol,OutStep, ...
                I1_in,I1_fin,numstep_I1,I2_in,I2_fin,numstep_I2,I3_o, ...
                     freq1_min,freq1_max,Step_FMI1,freq2_min,freq2_max,Step_FMI2,freq3_min,freq3_max,Step_FMI3, ...
                         View1,View2,View3,View4};

row_tot = numstep_I2 + 1;
col_tot = numstep_I1 + 1;
ext_tot = max(row_tot, col_tot);
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
begin = datestr(now, 'dd mmm HH:MM');
if ~isnan(findstr(HamiltonDir, 'Slave'))
% do nothing
else
    Totalbar = waitbar(0,'Please wait..., HAMILTON is calculating.',...
        'Units', 'pixel', 'Position', [392 570 380 70]);
end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
display('                                                                 ')
display('###################################################################')
display(['    STARTING COMPUTATION with ',ODE,'!!!'])
disp('    ')
tic
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%---------------------- Start multicore -----------------------------------
parameterCell = cell(1,ext_tot);
subcell = cell(1,4);
for ext = 1:ext_tot
    subcell{1,1} = [ext];
    subcell{1,2} = [Init_Data];
    subcell{1,3} = [Totalbar];
    subcell{1,4} = [begin];      
    parameterCell{1,ext} = {subcell};
end

cd ..
fid = fopen('WaitBar.txt','w');
fwrite(fid,'0');
fclose(fid);
cd(HamiltonDir)

warning('off')
settings.multicoreDir = '../multicorefiles';
settings.useWaitbar = false;
settings.nrOfEvalsAtOnce = 1;
resultCell = startmulticoremaster(@paralfor, parameterCell, settings);
warning('on')
%---------------------- End multicore -----------------------------------

freq_1 = [resultCell{1,1}(1,:)];
for k = 2 : ext_tot
    freq_1 = [freq_1; resultCell{1,k}(1,:)];
end
freq_2 = [resultCell{1,1}(2,:)];
for k = 2 : ext_tot
    freq_2 = [freq_2; resultCell{1,k}(2,:)];
end
freq_3 = [resultCell{1,1}(3,:)];
for k = 2 : ext_tot
    freq_3 = [freq_3; resultCell{1,k}(3,:)];
end
FMI_1 = [resultCell{1,1}(4,:)];
for k = 2 : ext_tot
    FMI_1 = [FMI_1; resultCell{1,k}(4,:)];
end
FMI_2 = [resultCell{1,1}(5,:)];
for k = 2 : ext_tot
    FMI_2 = [FMI_2; resultCell{1,k}(5,:)];
end
FMI_3 = [resultCell{1,1}(6,:)];
for k = 2 : ext_tot
    FMI_3 = [FMI_3; resultCell{1,k}(6,:)];
end

if row_tot <= col_tot
    freq_1 = freq_1';
    freq_2 = freq_2';
    freq_3 = freq_3';
    FMI_1 = FMI_1';
    FMI_2 = FMI_2';
    FMI_3 = FMI_3';
end
                   
%%%%%%%%%%%%%%%%%%%%% Save to file %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    save freq1.dat freq_1 -ascii
    save freq2.dat freq_2 -ascii
    save freq3.dat freq_3 -ascii
    save FMI1.dat FMI_1 -ascii
    save FMI2.dat FMI_2 -ascii
    save FMI3.dat FMI_3 -ascii
    delete *.txt
%%%%%%%%%%%%%%%%%%%%% Enable buttons %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
set(app.pushbutton_Calculate,'Enable','on')
set(app.pushbutton_FMI, 'Enable', 'on')
set(app.pushbutton_freq, 'Enable', 'on')
if (numstep_I1 ~= 0) & (numstep_I2 ~= 0)
    set(app.pushbutton_level, 'Enable', 'on')
end
display('-------------------------------------------------------------------')
display(['            FINISH with ',ODE,'!!!'])
toc
display('###################################################################')

           
        end

        % Button pushed function: pushbutton_Slave
        function Start_Slave_ButtonPushed(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
list = strread(cd, '%s','delimiter',strrep(filesep,'\','\\'));             %#ok<*DSTRRD> 
dim = size(list);
Slave = list{dim(1,1), dim(1,2)};
set(app.pushbutton_Slave, 'Enable', 'off', 'Text', [Slave,' is running'], ...
      'FontSize', 16, 'Icon', 'Runner.jpg', 'Position', [39 86 211 72])
startmulticoreslave('../multicorefiles')
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
        end

        % Button pushed function: pushbutton_FMI
        function plot_FMI(app, event)
load FMI1.dat
load FMI2.dat
load FMI3.dat

I1_in =      get(app.I1_in,'Value');
I1_fin =     get(app.I1_fin,'Value');
numstep_I1 = get(app.step_1,'Value');
I2_in =      get(app.I2_in,'Value');
I2_fin =     get(app.I2_fin,'Value');
numstep_I2 = get(app.step_2,'Value');
I1 = I1_in : (I1_fin - I1_in)/numstep_I1 : I1_fin;
I2 = I2_in : (I2_fin - I2_in)/numstep_I2 : I2_fin;


if (numstep_I1 == 0) & (numstep_I2 > 0)
    if get(app.Step_FMI1,'Value') > 0;
        figure
        plot(I2, FMI1(:,:))
        title(['FMI 1 \itvs \itI_2 \rmwith \itI_1 = ', num2str(get(app.I1_in,'Value'))], 'FontSize',12)
        xlabel('\itI_2', 'FontSize',14), ylabel('FMI 1', 'FontSize',14)
    end

    if get(app.Step_FMI2,'Value') > 0;
        figure
        plot(I2, FMI2(:,:))
        title(['FMI 2 \itvs \itI_2 \rmwith \itI_1 = ', num2str(get(app.I1_in,'Value'))], 'FontSize',12)
        xlabel('\itI_2', 'FontSize',14), ylabel('FMI 2', 'FontSize',14)
    end

    if get(app.Step_FMI3,'Value') > 0;
        figure
        plot(I2, FMI3(:,:))
        title(['FMI 3 \itvs \itI_2 \rmwith \itI_1 = ', num2str(get(app.I1_in,'Value'))], 'FontSize',12)
        xlabel('\itI_2', 'FontSize',14), ylabel('FMI 3', 'FontSize',14)
    end
elseif (numstep_I2 == 0) & (numstep_I1 > 0)
    if get(app.Step_FMI1,'Value') > 0;
        figure
        plot(I1, FMI1(:,:))
        title(['FMI 1 \itvs \itI_1 \rmwith \itI_2 = ', num2str(get(app.I2_in,'Value'))], 'FontSize',12)
        xlabel('\itI_1', 'FontSize',14), ylabel('FMI 1', 'FontSize',14)
    end

    if get(app.Step_FMI2,'Value') > 0;
        figure
        plot(I1, FMI2(:,:))
        title(['FMI 2 \itvs \itI_1 \rmwith \itI_2 = ', num2str(get(app.I2_in,'Value'))], 'FontSize',12)
        xlabel('\itI_1', 'FontSize',14), ylabel('FMI 2', 'FontSize',14)
    end

    if get(app.Step_FMI3,'Value') > 0;
        figure
        plot(I1, FMI3(:,:))
        title(['FMI 3 \itvs \itI_1 \rmwith \itI_2 = ', num2str(get(app.I2_in,'Value'))], 'FontSize',12)
        xlabel('\itI_1', 'FontSize',14), ylabel('FMI 3', 'FontSize',14)
    end
else (numstep_I1 > 0) & (numstep_I2 > 0);
    if get(app.Step_FMI1,'Value') > 0;
        figure
        colormap(gray)
        surf(I1, I2, FMI1, 'FaceColor', 'interp', 'LineStyle', 'none');
        view(2)
        xlabel('\itI_1', 'FontSize',14), ylabel('\itI_2', 'FontSize',14)
        title({'FMI 1'}, 'FontSize',14)
    end

    if get(app.Step_FMI2,'Value') > 0;
        figure
        colormap(gray)
        surf(I1, I2, FMI2, 'FaceColor', 'interp', 'LineStyle', 'none');
        view(2)
        xlabel('\itI_1', 'FontSize',14), ylabel('\itI_2', 'FontSize',14)
        title({'FMI 2'}, 'FontSize',14)
    end

    if get(app.Step_FMI3,'Value') > 0;
        figure
        colormap(gray)
        surf(I1, I2, FMI3, 'FaceColor', 'interp', 'LineStyle', 'none');
        view(2)
        xlabel('\itI_1', 'FontSize',14), ylabel('\itI_2', 'FontSize',14)
        title({'FMI 3'}, 'FontSize',14)
    end
end          
        end

        % Button pushed function: pushbutton_freq
        function plot_Frequency(app, event)
load freq1.dat
load freq2.dat
load freq3.dat

I1_in =      get(app.I1_in,'Value');
I1_fin =     get(app.I1_fin,'Value');
numstep_I1 = get(app.step_1,'Value');
I2_in =      get(app.I2_in,'Value');
I2_fin =     get(app.I2_fin,'Value');
numstep_I2 = get(app.step_2,'Value');
I1 = I1_in : (I1_fin - I1_in)/numstep_I1 : I1_fin;
I2 = I2_in : (I2_fin - I2_in)/numstep_I2 : I2_fin;

if (numstep_I1 == 0) & (numstep_I2 > 0)
    figure
    hold on
    plot(I2, freq1(:,:),'r')
    plot(I2, freq2(:,:),'k')
%    plot(I2, freq3(:,:),'b')
    legend('Frequency 1','Frequency 2')
    hold off
    title(['Frequencies \times1000 \itvs \itI_2 \rmwith \itI_1 = \rm',num2str(get(app.I1_in,'Value'))],'FontSize',12)
    xlabel('\itI_2', 'FontSize',14), ylabel('Frequencies', 'FontSize',14)
elseif (numstep_I2 == 0) & (numstep_I1 > 0)
    figure
    hold on
    plot(I1, freq1(:,:),'r')
    plot(I1, freq2(:,:),'k')
%    plot(I1, freq3(:,:),'b')
    legend('Frequency 1','Frequency 2')
    hold off
    title(['Frequencies \times1000 \itvs \itI_1 \rmwith \itI_2 = \rm',num2str(get(app.I2_in,'Value'))],'FontSize',12)
    xlabel('\itI_1', 'FontSize',14), ylabel('Frequencies', 'FontSize',14)
else (numstep_I1 > 0) & (numstep_I2 > 0);
    figure
    colormap(gray)
    surfc(I1, I2, freq1, 'FaceColor', 'interp', 'LineStyle', ':');
    xlabel('\itI_1', 'FontSize',14), ylabel('\itI_2', 'FontSize',14)
    title('Frequency 1', 'FontSize',14)

    figure
    colormap(gray)
    surfc(I1, I2, freq2, 'FaceColor', 'interp', 'LineStyle', ':');
    xlabel('\itI_1', 'FontSize',14), ylabel('\itI_2', 'FontSize',14)
    title('Frequency 2', 'FontSize',14)
%{
    figure
    colormap(gray)
    surfc(I1, I2, freq3, 'FaceColor', 'interp', 'LineStyle', ':');
    xlabel('\itI_1', 'FontSize',14), ylabel('\itI_2', 'FontSize',14)
    title('Frequency 3', 'FontSize',14)
%}      
end           
        end

        % Button pushed function: pushbutton_level
        function plot_Frequency_level(app, event)
load freq1.dat
load freq2.dat
load freq3.dat

I1_in =      get(app.I1_in,'Value');
I1_fin =     get(app.I1_fin,'Value');
numstep_I1 = get(app.step_1,'Value');
I2_in =      get(app.I2_in,'Value');
I2_fin =     get(app.I2_fin,'Value');
numstep_I2 = get(app.step_2,'Value');
I1 = I1_in : (I1_fin - I1_in)/numstep_I1 : I1_fin;
I2 = I2_in : (I2_fin - I2_in)/numstep_I2 : I2_fin;

figure
[C,h] = contour(I1,I2, freq1);
clabel(C,h);
xlabel('\itI_1', 'FontSize',14), ylabel('\itI_2', 'FontSize',14)
title('Frequency 1', 'FontSize',14)

figure
[C,h] = contour(I1,I2, freq2);
clabel(C,h);
xlabel('\itI_1', 'FontSize',14), ylabel('\itI_2', 'FontSize',14)
title('Frequency 2', 'FontSize',14)

%{
figure
[C,h] = contour(I1,I2, freq3);
clabel(C,h);
xlabel('\itI_1', 'FontSize',14), ylabel('\itI_2', 'FontSize',14)
title('Frequency 3', 'FontSize',14)
%}
        end

        % Menu selected function: Percentage_of_RowColumn
        function Percentage_of_RowColumnMenuSelected(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
if strcmp(get(gcbo, 'Checked'),'on')
    set(gcbo, 'Checked','off');
else
    set(gcbo, 'Checked','on')
end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Menu selected function: RowColumn_Completed
        function RowColumn_CompletedMenuSelected(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
if strcmp(get(gcbo, 'Checked'),'on')
    set(gcbo, 'Checked','off');
else
    set(gcbo, 'Checked','on')
end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
        end

        % Menu selected function: Total_bars
        function Total_barsMenuSelected(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
if strcmp(get(gcbo, 'Checked'),'on')
    set(gcbo, 'Checked','off');
else
    set(gcbo, 'Checked','on')
end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
        end

        % Menu selected function: Wait_bars
        function Wait_barsMenuSelected(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
if strcmp(get(gcbo, 'Checked'),'on')
    set(gcbo, 'Checked','off');
else
    set(gcbo, 'Checked','on')
end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
        end

        % Menu selected function: Save_exit
        function Save_exit_Menu(app, event)
if strcmp(get(gcbo, 'Checked'),'on')
    set(gcbo, 'Checked','off');
else
    set(gcbo, 'Checked','on')
end            
        end

        % Menu selected function: Save_now
        function Save_now_Menu(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
InitStructure
cd(app.HamiltonDir)
cd ..        
save('Setting.mat', '-struct', 'InitStr')
cd(app.HamiltonDir)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Value changed function: popupHamilton
        function popupHamilton_ValueChanged(app, event)
Disable_all
delete *.dat
        end

        % Value changed function: edit_eps
        function edit_eps_ValueChanged(app, event)
Disable_all
delete *.dat
        end

        % Value changed function: edit_time
        function edit_time_ValueChanged(app, event)
Disable_all
delete *.dat
        end

        % Value changed function: popupmenuODE
        function popupmenuODE_ValueChanged(app, event)
Disable_all
delete *.dat
        end

        % Value changed function: RelTol
        function RelTol_ValueChanged(app, event)
Disable_all
delete *.dat
        end

        % Value changed function: AbsTol
        function AbsTol_ValueChanged(app, event)
Disable_all
delete *.dat
        end

        % Value changed function: step_ODE
        function step_ODE_ValueChanged(app, event)
Disable_all
delete *.dat
        end

        % Value changed function: I1_in
        function I1_in_ValueChanged(app, event)
Disable_all
delete *.dat  
        end

        % Value changed function: I1_fin
        function I1_fin_ValueChanged(app, event)
Disable_all
delete *.dat
        end

        % Value changed function: step_1
        function step_1_ValueChanged(app, event)
if get(app.step_1,'Value') == 0
    set(app.I1_fin,'Enable','off')
    set(app.leqI_1leqLabel,'Text','$=I_1$','Position',[84 34 50 22])
else
    set(app.I1_fin,'Enable','on')
    set(app.leqI_1leqLabel,'Text','$\leq I_1 \leq$','Position',[97 34 50 22])
end
Disable_all
delete *.dat
        end

        % Value changed function: I2_in
        function I2_in_ValueChanged(app, event)
Disable_all
delete *.dat
        end

        % Value changed function: I2_fin
        function I2_fin_ValueChanged(app, event)
Disable_all
delete *.dat
        end

        % Value changed function: step_2
        function step_2_ValueChanged(app, event)
if get(app.step_2,'Value') == 0
    set(app.I2_fin,'Enable','off')
    set(app.leqI_2leqLabel,'Text','$=I_2$','Position',[84 34 50 22])
else
    set(app.I2_fin,'Enable','on')
    set(app.leqI_2leqLabel,'Text','$\leq I_2 \leq$','Position',[97 34 50 22])
end
Disable_all
delete *.dat
        end

        % Value changed function: I3_o
        function I3_o_ValueChanged(app, event)
Disable_all
delete *.dat
        end

        % Value changed function: freq1_min
        function freq1_min_ValueChanged(app, event)
Disable_all
delete *.dat
        end

        % Value changed function: freq1_max
        function freq1_max_ValueChanged(app, event)
Disable_all
delete *.dat
        end

        % Value changed function: Step_FMI1
        function Step_FMI1_ValueChanged(app, event)
Disable_all
delete *.dat
        end

        % Value changed function: freq2_min
        function freq2_min_ValueChanged(app, event)
Disable_all
delete *.dat
        end

        % Value changed function: freq2_max
        function freq2_max_ValueChanged(app, event)
Disable_all
delete *.dat
        end

        % Value changed function: Step_FMI2
        function Step_FMI2_ValueChanged(app, event)
Disable_all
delete *.dat
        end

        % Value changed function: freq3_min
        function freq3_min_ValueChanged(app, event)
Disable_all
delete *.dat
        end

        % Value changed function: freq3_max
        function freq3_max_ValueChanged(app, event)
Disable_all
delete *.dat
        end

        % Value changed function: Step_FMI3
        function Step_FMI3_ValueChanged(app, event)
            value = app.Step_FMI3.Value;
            
        end

        % Menu selected function: About
        function About_HAMILTON(app, event)
            About_HAMILTON
        end

        % Menu selected function: HelpMenu
        function Help_menu(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
popupmessage('Help.txt', 'Hamilton Help');
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
        end

        % Close request function: HAMILTONUIFigure
        function HAMILTON_closing(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
if ~isnan(findstr(app.HamiltonDir, 'Slave'))
    %do nothing
else
    if strcmp(get(app.Save_exit, 'Checked'),'on')    
        InitStructure
        cd(app.HamiltonDir)
        cd ..        
        save('Setting.mat', '-struct', 'InitStr')
        cd(app.HamiltonDir)
    end
end
delete('*.dat')
delete(app)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HAMILTONUIFigure = uifigure('Visible', 'off');
            app.HAMILTONUIFigure.Name = 'HAMILTON';
            app.HAMILTONUIFigure.Icon = 'Hamilton.jpg';
            app.HAMILTONUIFigure.CloseRequestFcn = createCallbackFcn(app, @HAMILTON_closing, true);
            app.savesetting = uimenu(app.HAMILTONUIFigure);
            app.savesetting.Text = 'Save setting';
            app.savesetting.Tag = 'savesetting';
            app.Save_exit = uimenu(app.savesetting);
            app.Save_exit.MenuSelectedFcn = createCallbackFcn(app, @Save_exit_Menu, true);
            app.Save_exit.Checked = 'on';
            app.Save_exit.Text = 'Save setting on exit';
            app.Save_exit.Tag = 'Save_exit';
            app.Save_now = uimenu(app.savesetting);
            app.Save_now.MenuSelectedFcn = createCallbackFcn(app, @Save_now_Menu, true);
            app.Save_now.Text = 'Save setting now';
            app.Save_now.Tag = 'Save_now';
            app.View = uimenu(app.HAMILTONUIFigure);
            app.View.Text = '   View';
            app.Percentage_of_RowColumn = uimenu(app.View);
            app.Percentage_of_RowColumn.MenuSelectedFcn = createCallbackFcn(app, @Percentage_of_RowColumnMenuSelected, true);
            app.Percentage_of_RowColumn.Checked = 'on';
            app.Percentage_of_RowColumn.Text = 'Percentage of Row/Column';
            app.RowColumn_Completed = uimenu(app.View);
            app.RowColumn_Completed.MenuSelectedFcn = createCallbackFcn(app, @RowColumn_CompletedMenuSelected, true);
            app.RowColumn_Completed.Checked = 'on';
            app.RowColumn_Completed.Text = 'Rows/Columns Completed';
            app.Total_bars = uimenu(app.View);
            app.Total_bars.MenuSelectedFcn = createCallbackFcn(app, @Total_barsMenuSelected, true);
            app.Total_bars.Checked = 'on';
            app.Total_bars.Text = 'Total Progress';
            app.Wait_bars = uimenu(app.View);
            app.Wait_bars.MenuSelectedFcn = createCallbackFcn(app, @Wait_barsMenuSelected, true);
            app.Wait_bars.Checked = 'on';
            app.Wait_bars.Text = 'Total Progress Waitbars';
            app.Help = uimenu(app.HAMILTONUIFigure);
            app.Help.Text = '   Help';
            app.Help.Tag = 'Help';
            app.About = uimenu(app.Help);
            app.About.MenuSelectedFcn = createCallbackFcn(app, @About_HAMILTON, true);
            app.About.Text = 'About HAMILTON';
            app.About.Tag = 'About';
            app.HelpMenu = uimenu(app.Help);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @Help_menu, true);
            app.HelpMenu.Text = 'Help';
            app.panelHamiltonian = uipanel(app.HAMILTONUIFigure);
            app.panelHamiltonian.Title = ' Hamiltonian ';
            app.panelHamiltonian.Tag = 'uipanel1';
            app.popupHamilton = uidropdown(app.panelHamiltonian);
            app.popupHamilton.Items = {'FGL', 'FGLmod', 'ERSD', 'User'};
            app.popupHamilton.ValueChangedFcn = createCallbackFcn(app, @popupHamilton_ValueChanged, true);
            app.popupHamilton.Tag = 'popupHamilton';
            app.popupHamilton.Value = 'FGL';
            app.PerturbativeparameterEditFieldLabel = uilabel(app.panelHamiltonian);
            app.PerturbativeparameterEditFieldLabel.Text = 'Perturbative parameter';
            app.edit_eps = uieditfield(app.panelHamiltonian, 'numeric');
            app.edit_eps.ValueChangedFcn = createCallbackFcn(app, @edit_eps_ValueChanged, true);
            app.edit_eps.Value = 0.01;
            app.IntegrationintervalEditFieldLabel = uilabel(app.panelHamiltonian);
            app.IntegrationintervalEditFieldLabel.Text = 'Integration interval';
            app.edit_time = uieditfield(app.panelHamiltonian, 'numeric');
            app.edit_time.ValueChangedFcn = createCallbackFcn(app, @edit_time_ValueChanged, true);
            app.edit_time.Value = 1000;
            app.panelODEs0lver = uipanel(app.HAMILTONUIFigure);
            app.panelODEs0lver.Title = ' ODE solver ';
            app.panelODEs0lver.Tag = 'uipanel12';
            app.popupmenuODE = uidropdown(app.panelODEs0lver);
            app.popupmenuODE.Items = {'ode113', 'ode45', 'ode78', 'ode89', 'dop853'};
            app.popupmenuODE.ValueChangedFcn = createCallbackFcn(app, @popupmenuODE_ValueChanged, true);
            app.popupmenuODE.Tag = 'popupmenuODE';
            app.popupmenuODE.Value = 'ode113';
            app.panel_opt_ODE = uipanel(app.panelODEs0lver);
            app.panel_opt_ODE.Title = 'Options';
            app.panel_opt_ODE.Tag = 'opt_ODE';
            app.OutputstepEditFieldLabel = uilabel(app.panel_opt_ODE);
            app.OutputstepEditFieldLabel.Text = {'Output step'; ''};
            app.step_ODE = uieditfield(app.panel_opt_ODE, 'numeric');
            app.step_ODE.ValueChangedFcn = createCallbackFcn(app, @step_ODE_ValueChanged, true);
            app.step_ODE.Value = 0.01;
            app.AbsoluteTolEditFieldLabel = uilabel(app.panel_opt_ODE);
            app.AbsoluteTolEditFieldLabel.Text = {'Absolute Tol'; ''};
            app.AbsTol = uieditfield(app.panel_opt_ODE, 'numeric');
            app.AbsTol.ValueChangedFcn = createCallbackFcn(app, @AbsTol_ValueChanged, true);
            app.AbsTol.Value = 1e-14;
            app.RelativeTolEditFieldLabel = uilabel(app.panel_opt_ODE);
            app.RelativeTolEditFieldLabel.Text = {'Relative Tol'; ''};
            app.RelTol = uieditfield(app.panel_opt_ODE, 'numeric');
            app.RelTol.ValueChangedFcn = createCallbackFcn(app, @RelTol_ValueChanged, true);
            app.RelTol.Value = 1e-10;
            app.panelTimeFrequencyAnalysis = uipanel(app.HAMILTONUIFigure);
            app.panelTimeFrequencyAnalysis.Title = 'Time-Frequency analysis ';
            app.panelTimeFrequencyAnalysis.Tag = 'uipanel17';
            app.panel_FMFT = uipanel(app.panelTimeFrequencyAnalysis);
            app.panel_FMFT.Tag = 'uipanel19';
            app.text35 = uilabel(app.panel_FMFT);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Compute the first';
            app.text36 = uilabel(app.panel_FMFT);
            app.text36.Tag = 'text36';
            app.text36.Text = 'frequencies';
            app.text37 = uilabel(app.panel_FMFT);
            app.text37.Tag = 'text37';
            app.text37.Text = 'between freq.';
            app.text38 = uilabel(app.panel_FMFT);
            app.text38.Tag = 'text38';
            app.text38.Text = 'and freq.';
            app.text39 = uilabel(app.panel_FMFT);
            app.text39.Tag = 'text39';
            app.text39.Text = 'with method';
            app.popupmenu_FMFT_flag = uidropdown(app.panel_FMFT);
            app.popupmenu_FMFT_flag.Items = {'FMFT base', 'FMFT linear correction', 'FMFT nonlinear correction'};
            app.popupmenu_FMFT_flag.ItemsData = [1 2 3];
            app.popupmenu_FMFT_flag.Tag = 'popupmenu_FMFT_flag';
            app.popupmenu_FMFT_flag.Value = 1;
            app.pushbutton_FMFT = uibutton(app.panel_FMFT, 'push');
            app.pushbutton_FMFT.ButtonPushedFcn = createCallbackFcn(app, @exec_FMFT, true);
            app.pushbutton_FMFT.Tag = 'pushbutton_FMFT';
            app.pushbutton_FMFT.Text = 'FMFT';
            app.edit_nfreq = uieditfield(app.panel_FMFT, 'numeric');
            app.edit_nfreq.Value = 8;
            app.edit_fromfreq = uieditfield(app.panel_FMFT, 'numeric');
            app.edit_fromfreq.Value = -5;
            app.edit_tofreq = uieditfield(app.panel_FMFT, 'numeric');
            app.edit_tofreq.Value = 5;
            app.panel_FFT = uipanel(app.panelTimeFrequencyAnalysis);
            app.panel_FFT.Tag = 'uipanel22';
            app.pushbutton_FFT = uibutton(app.panel_FFT, 'push');
            app.pushbutton_FFT.ButtonPushedFcn = createCallbackFcn(app, @exec_FFT, true);
            app.pushbutton_FFT.Tag = 'pushbutton_FFT';
            app.pushbutton_FFT.Text = {'Fast Fourier'; 'Transform'};
            app.check_log = uicheckbox(app.panel_FFT);
            app.check_log.Tag = 'check_log';
            app.check_log.Text = 'log scale';
            app.check_log.Value = true;
            app.panel_Wavelet = uipanel(app.panelTimeFrequencyAnalysis);
            app.panel_Wavelet.Tag = 'uipanel23';
            app.pushbutton_wavelet = uibutton(app.panel_Wavelet, 'push');
            app.pushbutton_wavelet.ButtonPushedFcn = createCallbackFcn(app, @exec_Wavelet, true);
            app.pushbutton_wavelet.Tag = 'pushbutton_wavelet';
            app.pushbutton_wavelet.Text = {'Wavelet '; 'Transform'; ''};
            app.text40 = uilabel(app.panel_Wavelet);
            app.text40.Tag = 'text40';
            app.text40.Text = 'Between freq.';
            app.text41 = uilabel(app.panel_Wavelet);
            app.text41.Tag = 'text41';
            app.text41.Text = 'and freq.';
            app.text42 = uilabel(app.panel_Wavelet);
            app.text42.Tag = 'text42';
            app.text42.Text = 'Variance';
            app.edit_min_freq = uieditfield(app.panel_Wavelet, 'numeric');
            app.edit_min_freq.Value = 0.5;
            app.edit_max_freq = uieditfield(app.panel_Wavelet, 'numeric');
            app.edit_max_freq.Value = 1.8;
            app.edit_variance = uieditfield(app.panel_Wavelet, 'numeric');
            app.edit_variance.Value = 30;
            app.I_1EditFieldLabel = uilabel(app.panelTimeFrequencyAnalysis);
            app.I_1EditFieldLabel.Interpreter = 'latex';
            app.I_1EditFieldLabel.Text = '$I_1=$';
            app.I1_FMFT = uieditfield(app.panelTimeFrequencyAnalysis, 'numeric');
            app.I1_FMFT.Value = 0.3;
            app.I_2EditFieldLabel = uilabel(app.panelTimeFrequencyAnalysis);
            app.I_2EditFieldLabel.Interpreter = 'latex';
            app.I_2EditFieldLabel.Text = '$I_2=$';
            app.I2_FMFT = uieditfield(app.panelTimeFrequencyAnalysis, 'numeric');
            app.I2_FMFT.Value = 0.3;
            app.I_3EditFieldLabel = uilabel(app.panelTimeFrequencyAnalysis);
            app.I_3EditFieldLabel.Interpreter = 'latex';
            app.I_3EditFieldLabel.Text = '$I_3=$';
            app.I3_FMFT = uieditfield(app.panelTimeFrequencyAnalysis, 'numeric');
            app.I3_FMFT.Value = 0.3;
            app.panelFrequencyModulationIndicator = uipanel(app.HAMILTONUIFigure);
            app.panelFrequencyModulationIndicator.Title = ' Frequency Modulation Indicator ';
            app.panelFrequencyModulationIndicator.Tag = 'uipanel3';
            app.pushbutton_Slave = uibutton(app.panelFrequencyModulationIndicator, 'push');
            app.pushbutton_Slave.ButtonPushedFcn = createCallbackFcn(app, @Start_Slave_ButtonPushed, true);
            app.pushbutton_Slave.Visible = 'off';
            app.pushbutton_Slave.Text = {'Start'; 'Slave'};
            app.pushbutton_Calculate = uibutton(app.panelFrequencyModulationIndicator, 'push');
            app.pushbutton_Calculate.ButtonPushedFcn = createCallbackFcn(app, @exec_CalculateFMI, true);
            app.pushbutton_Calculate.Tag = 'pushbutton_Calculate';
            app.pushbutton_Calculate.Text = {'Calculate'; 'FMI ...'};
            app.panel_Freq2 = uipanel(app.panelFrequencyModulationIndicator);
            app.panel_Freq2.Tag = 'uipanel5';
            app.text15 = uilabel(app.panel_Freq2);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Frequency 2';
            app.text16 = uilabel(app.panel_Freq2);
            app.text16.Tag = 'text16';
            app.text16.Text = {'Compute the first'; 'frequency between'};
            app.text17 = uilabel(app.panel_Freq2);
            app.text17.Tag = 'text17';
            app.text17.Text = 'and';
            app.text18 = uilabel(app.panel_Freq2);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Step number for FMI2';
            app.freq2_min = uieditfield(app.panel_Freq2, 'numeric');
            app.freq2_min.ValueChangedFcn = createCallbackFcn(app, @freq2_min_ValueChanged, true);
            app.freq2_min.Value = -5;
            app.freq2_max = uieditfield(app.panel_Freq2, 'numeric');
            app.freq2_max.ValueChangedFcn = createCallbackFcn(app, @freq2_max_ValueChanged, true);
            app.freq2_max.Value = 5;
            app.Step_FMI2 = uieditfield(app.panel_Freq2, 'numeric');
            app.Step_FMI2.ValueChangedFcn = createCallbackFcn(app, @Step_FMI2_ValueChanged, true);
            app.panel_Freq3 = uipanel(app.panelFrequencyModulationIndicator);
            app.panel_Freq3.Tag = 'uipanel6';
            app.text11 = uilabel(app.panel_Freq3);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Frequency 3';
            app.text12 = uilabel(app.panel_Freq3);
            app.text12.Tag = 'text12';
            app.text12.Text = {'Compute the first'; 'frequency between'};
            app.text13 = uilabel(app.panel_Freq3);
            app.text13.Tag = 'text13';
            app.text13.Text = 'and';
            app.text14 = uilabel(app.panel_Freq3);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Step number for FMI3';
            app.freq3_min = uieditfield(app.panel_Freq3, 'numeric');
            app.freq3_min.ValueChangedFcn = createCallbackFcn(app, @freq3_min_ValueChanged, true);
            app.freq3_min.Editable = 'off';
            app.freq3_min.Enable = 'off';
            app.freq3_min.Value = -5;
            app.freq3_max = uieditfield(app.panel_Freq3, 'numeric');
            app.freq3_max.ValueChangedFcn = createCallbackFcn(app, @freq3_max_ValueChanged, true);
            app.freq3_max.Editable = 'off';
            app.freq3_max.Enable = 'off';
            app.freq3_max.Value = 5;
            app.Step_FMI3 = uieditfield(app.panel_Freq3, 'numeric');
            app.Step_FMI3.ValueChangedFcn = createCallbackFcn(app, @Step_FMI3_ValueChanged, true);
            app.Step_FMI3.Editable = 'off';
            app.Step_FMI3.Enable = 'off';
            app.panel_Freq1 = uipanel(app.panelFrequencyModulationIndicator);
            app.panel_Freq1.Tag = 'uipanel7';
            app.text7 = uilabel(app.panel_Freq1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Frequency 1';
            app.text8 = uilabel(app.panel_Freq1);
            app.text8.Tag = 'text8';
            app.text8.Text = {'Compute the first'; 'frequency between'};
            app.text9 = uilabel(app.panel_Freq1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'and';
            app.text10 = uilabel(app.panel_Freq1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Step number for FMI1';
            app.freq1_min = uieditfield(app.panel_Freq1, 'numeric');
            app.freq1_min.ValueChangedFcn = createCallbackFcn(app, @freq1_min_ValueChanged, true);
            app.freq1_min.Value = -5;
            app.freq1_max = uieditfield(app.panel_Freq1, 'numeric');
            app.freq1_max.ValueChangedFcn = createCallbackFcn(app, @freq1_max_ValueChanged, true);
            app.freq1_max.Value = 5;
            app.Step_FMI1 = uieditfield(app.panel_Freq1, 'numeric');
            app.Step_FMI1.ValueChangedFcn = createCallbackFcn(app, @Step_FMI1_ValueChanged, true);
            app.Step_FMI1.Value = 6;
            app.uipanel9 = uipanel(app.panelFrequencyModulationIndicator);
            app.uipanel9.Tag = 'uipanel9';
            app.text3 = uilabel(app.uipanel9);
            app.text3.Tag = 'text3';
            app.text3.Text = 'with step number ';
            app.I1_in = uieditfield(app.uipanel9, 'numeric');
            app.I1_in.ValueChangedFcn = createCallbackFcn(app, @I1_in_ValueChanged, true);
            app.I1_in.Value = 0.3;
            app.I1_fin = uieditfield(app.uipanel9, 'numeric');
            app.I1_fin.ValueChangedFcn = createCallbackFcn(app, @I1_fin_ValueChanged, true);
            app.I1_fin.Value = 0.7;
            app.step_1 = uieditfield(app.uipanel9, 'numeric');
            app.step_1.ValueChangedFcn = createCallbackFcn(app, @step_1_ValueChanged, true);
            app.step_1.Value = 4;
            app.leqI_1leqLabel = uilabel(app.uipanel9);
            app.leqI_1leqLabel.Interpreter = 'latex';
            app.leqI_1leqLabel.Text = '$\leq I_1 \leq$';
            app.uipanel10 = uipanel(app.panelFrequencyModulationIndicator);
            app.uipanel10.Tag = 'uipanel10';
            app.text4 = uilabel(app.uipanel10);
            app.text4.Tag = 'text4';
            app.text4.Text = 'with step number';
            app.I2_in = uieditfield(app.uipanel10, 'numeric');
            app.I2_in.ValueChangedFcn = createCallbackFcn(app, @I2_in_ValueChanged, true);
            app.I2_in.Value = 0.1;
            app.I2_fin = uieditfield(app.uipanel10, 'numeric');
            app.I2_fin.ValueChangedFcn = createCallbackFcn(app, @I2_fin_ValueChanged, true);
            app.I2_fin.Value = 0.5;
            app.step_2 = uieditfield(app.uipanel10, 'numeric');
            app.step_2.ValueChangedFcn = createCallbackFcn(app, @step_2_ValueChanged, true);
            app.step_2.Value = 4;
            app.leqI_2leqLabel = uilabel(app.uipanel10);
            app.leqI_2leqLabel.Interpreter = 'latex';
            app.leqI_2leqLabel.Text = '$\leq I_2 \leq$';
            app.uipanel18 = uipanel(app.panelFrequencyModulationIndicator);
            app.uipanel18.Title = '... and plot';
            app.uipanel18.Tag = 'uipanel18';
            app.pushbutton_freq = uibutton(app.uipanel18, 'push');
            app.pushbutton_freq.ButtonPushedFcn = createCallbackFcn(app, @plot_Frequency, true);
            app.pushbutton_freq.Tag = 'pushbutton_freq';
            app.pushbutton_freq.Enable = 'off';
            app.pushbutton_freq.Text = 'Frequencies';
            app.pushbutton_FMI = uibutton(app.uipanel18, 'push');
            app.pushbutton_FMI.ButtonPushedFcn = createCallbackFcn(app, @plot_FMI, true);
            app.pushbutton_FMI.Tag = 'pushbutton_FMI';
            app.pushbutton_FMI.Enable = 'off';
            app.pushbutton_FMI.Text = 'F M I';
            app.pushbutton_level = uibutton(app.uipanel18, 'push');
            app.pushbutton_level.ButtonPushedFcn = createCallbackFcn(app, @plot_Frequency_level, true);
            app.pushbutton_level.Tag = 'pushbutton_level';
            app.pushbutton_level.Enable = 'off';
            app.pushbutton_level.Text = 'Frequencies (level lines)';
            app.I3_o = uieditfield(app.panelFrequencyModulationIndicator, 'numeric');
            app.I3_o.ValueChangedFcn = createCallbackFcn(app, @I3_o_ValueChanged, true);
            app.I3_o.Value = 1;
            app.I_3Label = uilabel(app.panelFrequencyModulationIndicator);
            app.I_3Label.Interpreter = 'latex';
            app.I_3Label.Text = '$I_3=%';
            % Show the figure after all components are created
            app.HAMILTONUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hamilton_source

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HAMILTONUIFigure)

            % Execute the startup function
            runStartupFcn(app, @HAMILTON_startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HAMILTONUIFigure)
        end
    end
end