classdef Kepler_source < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KEPLERUIFigure                 matlab.ui.Figure
        File                           matlab.ui.container.Menu
        Load                           matlab.ui.container.Menu
        Save                           matlab.ui.container.Menu
        closeall                       matlab.ui.container.Menu
        Save_exit                      matlab.ui.container.Menu
        Save_now                       matlab.ui.container.Menu
        Window                         matlab.ui.container.Menu
        Waitbars                       matlab.ui.container.Menu
        SlowWaitbars                   matlab.ui.container.Menu
        warningbox                     matlab.ui.container.Menu
        singularity                    matlab.ui.container.Menu
        watch                          matlab.ui.container.Menu
        perform                        matlab.ui.container.Menu
        LGG3_long_FMFT                 matlab.ui.container.Menu
        LSD_long_FMFT                  matlab.ui.container.Menu
        Figure                         matlab.ui.container.Menu
        close_all                      matlab.ui.container.Menu
        SingleFigures                  matlab.ui.container.Menu
        LargeMS                        matlab.ui.container.Menu
        no_degree                      matlab.ui.container.Menu
        Percentage_of_RowColumn        matlab.ui.container.Menu
        RowColumn_Completed            matlab.ui.container.Menu
        Total_bars                     matlab.ui.container.Menu
        Wait_bars                      matlab.ui.container.Menu
        Normalform                     matlab.ui.container.Menu
        SQZ                            matlab.ui.container.Menu
        setSQZ                         matlab.ui.container.Menu
        Cr3b                           matlab.ui.container.Menu
        setCr3b                        matlab.ui.container.Menu
        Satellite                      matlab.ui.container.Menu
        Help_gen                       matlab.ui.container.Menu
        impatient                      matlab.ui.container.Menu
        about                          matlab.ui.container.Menu
        uipanel_ODE                    matlab.ui.container.Panel
        popupmenuODE                   matlab.ui.control.DropDown
        edit_block                     matlab.ui.control.EditField
        text_block                     matlab.ui.control.Label
        popupmenuODE2                  matlab.ui.control.DropDown
        opt_ODE                        matlab.ui.container.Panel
        step_ODE                       matlab.ui.control.EditField
        OutputStepEditFieldLabel       matlab.ui.control.Label
        AbsTol                         matlab.ui.control.NumericEditField
        AbsoluteTolLabel               matlab.ui.control.Label
        RelTol                         matlab.ui.control.NumericEditField
        RelativeTolEditFieldLabel      matlab.ui.control.Label
        uipanel_GniCodes               matlab.ui.container.Panel
        text59                         matlab.ui.control.Label
        edit_StepSize2                 matlab.ui.control.EditField
        uipanelInit                    matlab.ui.container.Panel
        pushbutton_Up_Pauli            matlab.ui.control.Button
        pushbutton_UpdateCartesian     matlab.ui.control.Button
        pushbutton_UpdateKepler        matlab.ui.control.Button
        uipanel16                      matlab.ui.container.Panel
        edit_TrueAnomaly               matlab.ui.control.EditField
        edit_Inclination               matlab.ui.control.EditField
        edit_ArgPericenter             matlab.ui.control.EditField
        text36                         matlab.ui.control.Label
        text35                         matlab.ui.control.Label
        text34                         matlab.ui.control.Label
        edit_AscNode                   matlab.ui.control.EditField
        edit_Eccentricity              matlab.ui.control.EditField
        edit_smaxis                    matlab.ui.control.EditField
        text33                         matlab.ui.control.Label
        text32                         matlab.ui.control.Label
        text31                         matlab.ui.control.Label
        uipanel15                      matlab.ui.container.Panel
        p_3Label                       matlab.ui.control.Label
        p_2Label                       matlab.ui.control.Label
        p_1Label                       matlab.ui.control.Label
        q_3Label                       matlab.ui.control.Label
        q_2Label                       matlab.ui.control.Label
        q_1Label                       matlab.ui.control.Label
        p3_value                       matlab.ui.control.EditField
        p2_value                       matlab.ui.control.EditField
        p1_value                       matlab.ui.control.EditField
        q3_value                       matlab.ui.control.EditField
        q2_value                       matlab.ui.control.EditField
        q1_value                       matlab.ui.control.EditField
        uipanel18                      matlab.ui.container.Panel
        uipanel4                       matlab.ui.container.Panel
        text_tot_cycles                matlab.ui.control.Label
        total_cycles                   matlab.ui.control.EditField
        LongtimeFA                     matlab.ui.container.Panel
        ComputeLFA                     matlab.ui.control.EditField
        text154                        matlab.ui.control.Label
        uipanel_ClearMemory            matlab.ui.container.Panel
        edit_ClearMemory               matlab.ui.control.EditField
        text151                        matlab.ui.control.Label
        uipanel_from_to                matlab.ui.container.Panel
        edit_to                        matlab.ui.control.EditField
        edit_from                      matlab.ui.control.EditField
        text_from                      matlab.ui.control.Label
        uiPanelHp                      matlab.ui.container.Panel
        edit_epsilon5                  matlab.ui.control.EditField
        popupmenuHp5                   matlab.ui.control.DropDown
        text_epsilon                   matlab.ui.control.Label
        textHp                         matlab.ui.control.Label
        edit_epsilon4                  matlab.ui.control.EditField
        edit_epsilon3                  matlab.ui.control.EditField
        edit_epsilon2                  matlab.ui.control.EditField
        edit_epsilon1                  matlab.ui.control.EditField
        popupmenuHp4                   matlab.ui.control.DropDown
        popupmenuHp3                   matlab.ui.control.DropDown
        popupmenuHp2                   matlab.ui.control.DropDown
        popupmenuHp1                   matlab.ui.control.DropDown
        uipanel53                      matlab.ui.container.Panel
        popupmenu_head                 matlab.ui.control.DropDown
        uipanel_RegMethod              matlab.ui.container.Panel
        uipanel_Pert_NonPert           matlab.ui.container.ButtonGroup
        Image                          matlab.ui.control.Image
        check_pert                     matlab.ui.control.CheckBox
        check_nonpert                  matlab.ui.control.CheckBox
        pushbutton_tau_Step            matlab.ui.control.Button
        pushbutton_UserFunction        matlab.ui.control.Button
        pushbutton_Errors              matlab.ui.control.Button
        pushbutton_Time                matlab.ui.control.Button
        pushbutton_tKepler             matlab.ui.control.Button
        pushbutton_tauKepler           matlab.ui.control.Button
        pushbutton_Trajectories        matlab.ui.control.Button
        pushbutton_Integrate           matlab.ui.control.Button
        uipanel_StandMethod            matlab.ui.container.Panel
        pushbutton_Stand_UserFunction  matlab.ui.control.Button
        pushbutton_StandIntegrate      matlab.ui.control.Button
        pushbutton_t_Step              matlab.ui.control.Button
        pushbutton_Error               matlab.ui.control.Button
        pushbutton_KeplerParameters    matlab.ui.control.Button
        pushbutton_StandTrajectories   matlab.ui.control.Button
        uipanel_AA_FMFT                matlab.ui.container.Panel
        Panel                          matlab.ui.container.Panel
        pushbutton_AA_User             matlab.ui.control.Button
        pushbutton_AA_Delaunay         matlab.ui.control.Button
        pushbutton_AA_Delaunayprime    matlab.ui.control.Button
        pushbutton_AA_Pauli            matlab.ui.control.Button
        pushbutton_AA_Pauliprime       matlab.ui.control.Button
        uipanel_Pauli                  matlab.ui.container.Panel
        EnableUpdatebuttonCheckBox     matlab.ui.control.CheckBox
        d_3Label                       matlab.ui.control.Label
        d_2Label                       matlab.ui.control.Label
        d_1Label                       matlab.ui.control.Label
        s_3Label                       matlab.ui.control.Label
        s_2Label                       matlab.ui.control.Label
        s_1Label                       matlab.ui.control.Label
        edit_Energy                    matlab.ui.control.EditField
        text46                         matlab.ui.control.Label
        pushbutton_UpdateInit          matlab.ui.control.Button
        edit_D03                       matlab.ui.control.EditField
        edit_D02                       matlab.ui.control.EditField
        edit_D01                       matlab.ui.control.EditField
        edit_S03                       matlab.ui.control.EditField
        edit_S02                       matlab.ui.control.EditField
        edit_S01                       matlab.ui.control.EditField
        uipanel54                      matlab.ui.container.Panel
        pushbutton_Pauliprime          matlab.ui.control.Button
        pushbutton_Pauli               matlab.ui.control.Button
        uipanel52                      matlab.ui.container.Panel
        edit_Variance                  matlab.ui.control.EditField
        edit_max_freq                  matlab.ui.control.EditField
        edit_min_freq                  matlab.ui.control.EditField
        BetweenfreqandfreqVarianceLabel  matlab.ui.control.Label
        pushbutton_wavelet             matlab.ui.control.Button
        uipanel_FFT                    matlab.ui.container.Panel
        check_log                      matlab.ui.control.CheckBox
        pushbutton_FFT                 matlab.ui.control.Button
        uipanel_FMFT                   matlab.ui.container.Panel
        popupmenu_FMFT_flag            matlab.ui.control.DropDown
        edit_tofreq                    matlab.ui.control.EditField
        edit_fromfreq                  matlab.ui.control.EditField
        edit_nfreq                     matlab.ui.control.EditField
        ComputethefirstfrequenciesbetweenfreqandfreqwithmethodLabel  matlab.ui.control.Label
        pushbutton_FMFT                matlab.ui.control.Button
        uipanel_User                   matlab.ui.container.ButtonGroup
        text64                         matlab.ui.control.Label
        radiobutton_User               matlab.ui.control.RadioButton
        radiobutton_Pauliprime         matlab.ui.control.RadioButton
        radiobutton_Pauli              matlab.ui.control.RadioButton
        radiobutton_Delaunayprime      matlab.ui.control.RadioButton
        radiobutton_Delaunay           matlab.ui.control.RadioButton
        uipanel_Freq3D_LSD             matlab.ui.container.Panel
        phi_RLabel                     matlab.ui.control.Label
        uipanel68_4                    matlab.ui.container.Panel
        leq2D_3LleqLabel               matlab.ui.control.Label
        edit_3D_step_D                 matlab.ui.control.EditField
        text97_4                       matlab.ui.control.Label
        edit_3D_final_D                matlab.ui.control.EditField
        edit_3D_init_D                 matlab.ui.control.EditField
        uipanelFreq1_6                 matlab.ui.container.Panel
        nstep3LSD                      matlab.ui.control.EditField
        text156_6                      matlab.ui.control.Label
        text103_6                      matlab.ui.control.Label
        editLSD_max_Freq3              matlab.ui.control.EditField
        text102_6                      matlab.ui.control.Label
        text101_6                      matlab.ui.control.Label
        editLSD_min_Freq3              matlab.ui.control.EditField
        uipanelFreq1_5                 matlab.ui.container.Panel
        nstep2LSD                      matlab.ui.control.EditField
        text156_5                      matlab.ui.control.Label
        text103_5                      matlab.ui.control.Label
        editLSD_max_Freq2              matlab.ui.control.EditField
        text102_5                      matlab.ui.control.Label
        text101_5                      matlab.ui.control.Label
        editLSD_min_Freq2              matlab.ui.control.EditField
        uipanel_Pert_NonPert_LSD       matlab.ui.container.ButtonGroup
        ImageLSD                       matlab.ui.control.Image
        check_nonpert_LSD              matlab.ui.control.CheckBox
        check_pert_LSD                 matlab.ui.control.CheckBox
        uipanel73_2                    matlab.ui.container.Panel
        editSD_uni_13                  matlab.ui.control.EditField
        editSD_uni_22                  matlab.ui.control.EditField
        editSD_uni_33                  matlab.ui.control.EditField
        editSD_uni_11                  matlab.ui.control.EditField
        editSD_uni_23                  matlab.ui.control.EditField
        editSD_uni_32                  matlab.ui.control.EditField
        editSD_uni_12                  matlab.ui.control.EditField
        editSD_uni_21                  matlab.ui.control.EditField
        editSD_uni_31                  matlab.ui.control.EditField
        Label_2                        matlab.ui.control.Label
        pushbutton_clearLSD            matlab.ui.control.Button
        text129_2                      matlab.ui.control.Label
        edit_EnergyLSD                 matlab.ui.control.EditField
        uipanel68_3                    matlab.ui.container.Panel
        leq2S_3LleqLabel               matlab.ui.control.Label
        edit_3D_step_S                 matlab.ui.control.EditField
        text97_3                       matlab.ui.control.Label
        edit_3D_final_S                matlab.ui.control.EditField
        edit_3D_init_S                 matlab.ui.control.EditField
        uipanel56_2                    matlab.ui.container.Panel
        pushbutton_FreqlevelLSD        matlab.ui.control.Button
        pushbutton_FMILSD              matlab.ui.control.Button
        pushbutton_FreqLSD             matlab.ui.control.Button
        edit_phiR                      matlab.ui.control.EditField
        uipanelFreq1_4                 matlab.ui.container.Panel
        nstep1LSD                      matlab.ui.control.EditField
        text156_4                      matlab.ui.control.Label
        text103_4                      matlab.ui.control.Label
        editLSD_max_Freq1              matlab.ui.control.EditField
        text102_4                      matlab.ui.control.Label
        text101_4                      matlab.ui.control.Label
        editLSD_min_Freq1              matlab.ui.control.EditField
        pushbutton_calc_LSDprime       matlab.ui.control.Button
        pushbutton_StartSlave_LSD      matlab.ui.control.Button
        uipanel_PoincFreq_2D           matlab.ui.container.Panel
        uipanel_Pert_NonPert_2D        matlab.ui.container.ButtonGroup
        Image2D                        matlab.ui.control.Image
        check_2D_nonpert               matlab.ui.control.CheckBox
        check_2D_pert                  matlab.ui.control.CheckBox
        leqGLleqLabel                  matlab.ui.control.Label
        uipanel40_2                    matlab.ui.container.Panel
        text79_2                       matlab.ui.control.Label
        minGfreq_2D                    matlab.ui.control.EditField
        maxGfreq_2D                    matlab.ui.control.EditField
        text78_2                       matlab.ui.control.Label
        text77_2                       matlab.ui.control.Label
        panelTrueNorm                  matlab.ui.container.ButtonGroup
        G_3Label                       matlab.ui.control.Label
        edit_2D_G3                     matlab.ui.control.EditField
        NormG3                         matlab.ui.control.RadioButton
        TrueG3                         matlab.ui.control.RadioButton
        text128                        matlab.ui.control.Label
        edit_EnergyPoincare            matlab.ui.control.EditField
        uipanel46                      matlab.ui.container.ButtonGroup
        CheckBox3D                     matlab.ui.control.CheckBox
        CheckBoxPlane                  matlab.ui.control.CheckBox
        uipanel40                      matlab.ui.container.Panel
        text79                         matlab.ui.control.Label
        minLfreq_2D                    matlab.ui.control.EditField
        maxLfreq_2D                    matlab.ui.control.EditField
        text78                         matlab.ui.control.Label
        text77                         matlab.ui.control.Label
        text70                         matlab.ui.control.Label
        pushbutton_CalcPlot_Poincare   matlab.ui.control.Button
        edit_2D_omega                  matlab.ui.control.EditField
        edit_2D_step                   matlab.ui.control.EditField
        edit_2D_final                  matlab.ui.control.EditField
        edit_2D_init                   matlab.ui.control.EditField
        text68                         matlab.ui.control.Label
        uipanel65                      matlab.ui.container.Panel
        checkbox_2D_freq               matlab.ui.control.CheckBox
        pushbutton_clear2D             matlab.ui.control.Button
        checkbox_append                matlab.ui.control.CheckBox
        checkbox_2D_Poinc              matlab.ui.control.CheckBox
        uipanel_Freq3D_LGG3            matlab.ui.container.Panel
        uipanel68_2                    matlab.ui.container.Panel
        leqG_3LleqLabel                matlab.ui.control.Label
        edit_Step_G3prime              matlab.ui.control.EditField
        text97_2                       matlab.ui.control.Label
        edit_Final_G3prime             matlab.ui.control.EditField
        edit_Init_G3prime              matlab.ui.control.EditField
        uipanelFreq1_3                 matlab.ui.container.Panel
        nstep3LGG3                     matlab.ui.control.EditField
        text156_3                      matlab.ui.control.Label
        text103_3                      matlab.ui.control.Label
        editGG3_max_Freq3              matlab.ui.control.EditField
        text102_3                      matlab.ui.control.Label
        text101_3                      matlab.ui.control.Label
        editGG3_min_Freq3              matlab.ui.control.EditField
        uipanelFreq1_2                 matlab.ui.container.Panel
        nstep2LGG3                     matlab.ui.control.EditField
        text156_2                      matlab.ui.control.Label
        text103_2                      matlab.ui.control.Label
        editGG3_max_Freq2              matlab.ui.control.EditField
        text102_2                      matlab.ui.control.Label
        text101_2                      matlab.ui.control.Label
        editGG3_min_Freq2              matlab.ui.control.EditField
        uipanel_Pert_NonPert_LGG3      matlab.ui.container.ButtonGroup
        ImageLGG3                      matlab.ui.control.Image
        check_nonpert_LGG3             matlab.ui.control.CheckBox
        check_pert_LGG3                matlab.ui.control.CheckBox
        uipanel73                      matlab.ui.container.Panel
        editGG3_uni_13                 matlab.ui.control.EditField
        editGG3_uni_22                 matlab.ui.control.EditField
        editGG3_uni_33                 matlab.ui.control.EditField
        editGG3_uni_11                 matlab.ui.control.EditField
        editGG3_uni_23                 matlab.ui.control.EditField
        editGG3_uni_32                 matlab.ui.control.EditField
        editGG3_uni_12                 matlab.ui.control.EditField
        editGG3_uni_21                 matlab.ui.control.EditField
        editGG3_uni_31                 matlab.ui.control.EditField
        Label                          matlab.ui.control.Label
        pushbutton_clearLGG3           matlab.ui.control.Button
        text129                        matlab.ui.control.Label
        edit_EnergyLGG3                matlab.ui.control.EditField
        uipanel68                      matlab.ui.container.Panel
        leqGLleqLabel_2                matlab.ui.control.Label
        edit_Step_Gprime               matlab.ui.control.EditField
        text97                         matlab.ui.control.Label
        edit_Final_Gprime              matlab.ui.control.EditField
        edit_Init_Gprime               matlab.ui.control.EditField
        uipanel56                      matlab.ui.container.Panel
        pushbutton_FMILGG3             matlab.ui.control.Button
        pushbutton_FreqlevelLGG3       matlab.ui.control.Button
        pushbutton_FreqLGG3            matlab.ui.control.Button
        pushbutton_calc_LGG3prime      matlab.ui.control.Button
        text111                        matlab.ui.control.Label
        edit_LGG3_perprime             matlab.ui.control.EditField
        uipanelFreq1                   matlab.ui.container.Panel
        nstep1LGG3                     matlab.ui.control.EditField
        text156                        matlab.ui.control.Label
        text103                        matlab.ui.control.Label
        editGG3_max_Freq1              matlab.ui.control.EditField
        text102                        matlab.ui.control.Label
        text101                        matlab.ui.control.Label
        editGG3_min_Freq1              matlab.ui.control.EditField
        pushbutton_StartSlave_LGG3     matlab.ui.control.Button
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        KeplerDir
        PhSpGeoDir
        pythonDir
        PythonDir
    end
    
    methods (Access = public)                 

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function KEPLER_startup(app)
app.KeplerDir = cd;
cd ..
cd ..
cd ..
app.PhSpGeoDir = cd;
cd(app.KeplerDir);
%app.pythonDir = '/home/bruno/anaconda3/bin';
%app.PythonDir = [app.KeplerDir,'/Python'];

if isdeployed
    % Do nothing
else
    if exist('Report.log')
        delete Report.log
    end
end

if isdeployed
    setenv('PATH',['/home/bruno/anaconda3/bin',':',getenv('PATH')])
else
    path([app.PhSpGeoDir,'/AddedODEsolver/bin'], path);
    path([app.PhSpGeoDir,'/Util'], path);
    path([app.PhSpGeoDir,'/Util/Multicore'], path);
    path([app.PhSpGeoDir,'/Portraits'], path);
    path([app.PhSpGeoDir,'/AddedODEsolver/dop853'], path);
    path([app.PhSpGeoDir,'/AddedODEsolver/BulirschStoer'], path);
    path([app.PhSpGeoDir,'/AddedODEsolver/ABM8'], path);
    path([app.PhSpGeoDir,'/AddedODEsolver/ShampineGordon'], path);
    path([app.PhSpGeoDir,'/AddedODEsolver/Rk'], path);
    path([app.KeplerDir,'/StandardMethod/Plot'], path)
    path([app.KeplerDir,'/StandardMethod'], path)
    path([app.KeplerDir,'/PhaseSpaceMap'], path)
    path([app.KeplerDir,'/FFT'], path)
    path([app.KeplerDir,'/FMFT'], path)
    path([app.KeplerDir,'/Wavelet'], path)
    path([app.PhSpGeoDir,'/Util'], path)
    path([app.KeplerDir,'/Plot'], path)
    path([app.KeplerDir,'/Python'], path)
    path([app.KeplerDir,'/CoordinateChange'], path)
    path([app.KeplerDir,'/H_perturbation'], path)
    path([app.KeplerDir,'/Def'], path)
    path([app.KeplerDir,'/Do'], path)
    path([app.KeplerDir,'/Init'], path)
    path([app.KeplerDir,'/NormalForm'], path)
    path([app.KeplerDir,'/Help'], path);
    path([app.PhSpGeoDir,'/AddedODEsolver/bin'], path)
    path([app.PhSpGeoDir,'/Util/Multicore'], path)
    path(app.KeplerDir, path)
end

app.pushbutton_Integrate.Icon = 'Integrate.jpg';
app.Image.ImageSource = 'UserSaved/KS.jpg';
app.pushbutton_StandIntegrate.Icon = 'Integrate.jpg';
app.pushbutton_CalcPlot_Poincare.Icon = 'Integrate.jpg';
app.pushbutton_FFT.Icon = 'FFT.jpg';
app.pushbutton_FMFT.Icon = 'FMFT.jpg';
app.pushbutton_wavelet.Icon = 'onda.jpg';
app.pushbutton_CalcPlot_Poincare.Icon = 'Tori.jpg';
set(app.pushbutton_calc_LGG3prime,'Icon','PhSpGeo.jpg','IconAlignment','top');
set(app.pushbutton_StartSlave_LGG3,'Icon','Schiavo.jpg','IconAlignment','left');
set(app.pushbutton_calc_LSDprime,'Icon','PhSpGeo.jpg','IconAlignment','top');
set(app.pushbutton_StartSlave_LSD,'Icon','Schiavo.jpg','IconAlignment','left');
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

if ~isnan(findstr(app.KeplerDir, 'Slave'))
    cd(app.KeplerDir)            
    cd ..            
    if exist('Setting.mat')            
        load('Setting.mat')            
        if Head ~= 4            
            set(app.popupmenu_head, 'Value', num2str(3))            
            set(app.pushbutton_StartSlave_LGG3, 'Visible', 'on')
            set(app.pushbutton_calc_LGG3prime, 'Visible', 'off')
            set(app.uipanel_Freq3D_LGG3, 'Visible', 'on')            
        else            
            set(app.popupmenu_head, 'Value', num2str(4))            
            set(app.pushbutton_StartSlave_LSD, 'Visible', 'on')
            set(app.pushbutton_calc_LSDprime, 'Visible', 'off')
            set(app.uipanel_Freq3D_LSD, 'Visible', 'on')            
        end            
    else            
        set(app.popupmenu_head, 'Value', num2str(3))            
        set(app.pushbutton_StartSlave_LGG3, 'Visible', 'on')
        set(app.pushbutton_calc_LGG3prime, 'Visible', 'off')
        set(app.uipanel_Freq3D_LGG3, 'Visible', 'on')            
    end            
    cd(app.KeplerDir)            
            
    set(app.pushbutton_Integrate, 'Enable', 'off')            
    set(app.pushbutton_StandIntegrate, 'Enable', 'off')            
    set(app.pushbutton_CalcPlot_Poincare, 'Enable', 'off')            
    set(app.uipanel_PoincFreq_2D, 'Visible', 'off')            
    set(app.uipanelInit, 'Visible', 'off')            
    set(app.uipanel_AA_FMFT, 'Visible', 'off')            
    set(app.uipanel_RegMethod, 'Visible', 'off')            
    set(app.uipanel_StandMethod, 'Visible', 'off')            
    set(app.uipanel_from_to, 'Visible', 'off')
    set(app.uipanel_ClearMemory,'Visible', 'on')            
    set(app.check_nonpert,'Value', 1)            
%    set(app.radiobutton_2D_NonPert,'Value', 1)            
    set(app.check_nonpert_LGG3,'Value', 1)            
    set(app.check_pert_LSD,'Value', 1)            
    set(app.step_ODE,'Visible', 'on')            
    if str2num(get(app.edit_ClearMemory,'Value')) < str2num(get(app.ComputeLFA,'Value'))            
        set(app.ComputeLFA,'Value',get(app.edit_ClearMemory,'Value'))            
    end 
else
    cd ..
    if exist('multicorefiles','dir')
        rmdir('multicorefiles','s')
    end
    mkdir('multicorefiles')
    cd(app.KeplerDir)
end
            
list = strread(cd, '%s','delimiter',strrep(filesep,'\','\\'));
dim = size(list);
curdir = [list{dim(1,1), dim(1,2)},' - ',list{dim(1,1) - 1, dim(1,2)}];
set(app.KEPLERUIFigure, 'Name', curdir)
%%%%%%%%%%%%%%%% Load what was saved on exit %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
cd(app.KeplerDir)
cd ..
if exist('Setting.mat')
    load('Setting.mat')
    SetInit
end
cd(app.KeplerDir)
        end

        % Menu selected function: Load
        function load_init(app, event)
            cd(app.KeplerDir);
            [filename, pathname] = uigetfile( ...
                 {'*.mat', 'All MAT-Files (*.mat)'; ...
                     '*.*','All Files (*.*)'}, ...
                'Load Initial Data','Init/');
            if isequal([filename,pathname],[0,0])
                return
            else
                [pathstr, name, ext] = fileparts(filename);
                cd(pathname)
                load([name,'.mat'])
                cd(app.KeplerDir);
            end
            
            set(app.popupmenuHp1,'Value', Hp1)
            set(app.popupmenuHp2,'Value', Hp2)
            set(app.popupmenuHp3,'Value', Hp3)
            set(app.popupmenuHp4,'Value', Hp4)
            set(app.popupmenuHp5,'Value', Hp5)
            set(app.edit_epsilon1,'Value', epsilon1)
            set(app.edit_epsilon2,'Value', epsilon2)
            set(app.edit_epsilon3,'Value', epsilon3)
            set(app.edit_epsilon4,'Value', epsilon4)
            set(app.edit_epsilon5,'Value', epsilon5)
            set(app.q1_value,'Value', q1)
            set(app.q2_value,'Value', q2)
            set(app.q3_value,'Value', q3)
            set(app.p1_value,'Value', p1)
            set(app.p2_value,'Value', p2)
            set(app.p3_value,'Value', p3)
            set(app.edit_smaxis,'Value', sm)
            set(app.edit_Eccentricity,'Value', ecc)
            set(app.edit_AscNode,'Value', Omega)
            set(app.edit_ArgPericenter,'Value', omega)
            set(app.edit_Inclination,'Value', incl)
            set(app.edit_TrueAnomaly,'Value', f)
            set(app.edit_S01,'Value', SO1)
            set(app.edit_S02,'Value', SO2)
            set(app.edit_S03,'Value', SO3)
            set(app.edit_D01,'Value', DO1)
            set(app.edit_D02,'Value', DO2)
            set(app.edit_D03,'Value', DO3)
            set(app.uipanel_Pauli,'Visible', Visible)
            set(app.edit_Energy,'Value', TotE)
            set(app.edit_EnergyPoincare,'Value', TotE)
            set(app.edit_EnergyLGG3,'Value', TotE)
            set(app.edit_EnergyLSD,'Value', TotE)
            set(app.pushbutton_Integrate, 'Enable', 'on')
            set(app.pushbutton_StandIntegrate, 'Enable', 'on')
            set(app.EnableUpdatebuttonCheckBox,'Value',0)
            set(app.pushbutton_UpdateInit, 'Enable', 'off')
            
            Disable_General
            
            set(app.radiobutton_Delaunay,'Value',1)
            set(app.radiobutton_Pauli,'Value',0)
            set(app.radiobutton_User,'Value',0)
            if strcmp(get(app.uipanel_Pauli,'Visible') , 'on') == 1
                set(app.radiobutton_Delaunayprime, 'Enable', 'on', 'Value',0)
                set(app.radiobutton_Pauliprime, 'Enable', 'on', 'Value',0)
            else
                set(app.radiobutton_Delaunayprime, 'Enable', 'off')
                set(app.radiobutton_Pauliprime, 'Enable', 'off')
            end
            
            set(app.pushbutton_Up_Pauli, 'Enable', 'off')            
        end

        % Menu selected function: Save
        function save_init(app, event)
            cd(app.KeplerDir);
            [filename, pathname] = uiputfile( ...
                 {'*.mat', 'MAT-Files (*.mat)'; ...
                    '*.*','All Files (*.*)'}, ...
                'Save Initial Data','Init/');
            if isequal([filename,pathname],[0,0])
                return
            else
                val1 = get(app.popupmenuHp1,'Value');
                val2 = get(app.popupmenuHp2,'Value');
                val3 = get(app.popupmenuHp3,'Value');
                val4 = get(app.popupmenuHp4,'Value');
                val4b = get(app.popupmenuHp5,'Value');
                val5 = get(app.edit_epsilon1,'Value');
                val6 = get(app.edit_epsilon2,'Value');
                val7 = get(app.edit_epsilon3,'Value');
                val8 = get(app.edit_epsilon4,'Value');
                val8b = get(app.edit_epsilon5,'Value');
                val9 = get(app.q1_value,'Value');
                val10 = get(app.q2_value,'Value');
                val11 = get(app.q3_value,'Value');
                val12 = get(app.p1_value,'Value');
                val13 = get(app.p2_value,'Value');
                val14 = get(app.p3_value,'Value');
                val15 = get(app.edit_smaxis,'Value');
                val16 = get(app.edit_Eccentricity,'Value');
                val17 = get(app.edit_AscNode,'Value');
                val18 = get(app.edit_ArgPericenter,'Value');
                val19 = get(app.edit_Inclination,'Value');
                val20 = get(app.edit_TrueAnomaly,'Value');
                val21 = get(app.edit_S01,'Value');
                val22 = get(app.edit_S02,'Value');
                val23 = get(app.edit_S03,'Value');
                val24 = get(app.edit_D01,'Value');
                val25 = get(app.edit_D02,'Value');
                val26 = get(app.edit_D03,'Value');
                val27 = get(app.uipanel_Pauli,'Visible');
%                val28 = get(app.EnableUpdatebuttonCheckBox,'Value');
                val31 = get(app.edit_Energy,'Value');
            
                InitStruct = struct('Hp1',val1, 'Hp2',val2, 'Hp3',val3, 'Hp4',val4, 'Hp5',val4b,...
                    'epsilon1',val5, 'epsilon2',val6, 'epsilon3',val7, 'epsilon4',val8, 'epsilon5',val8b,...
                    'q1',val9, 'q2',val10, 'q3',val11,...
                    'p1',val12, 'p2',val13, 'p3',val14,...
                    'sm',val15, 'ecc',val16, 'Omega',val17,...
                    'omega',val18, 'incl',val19, 'f',val20,...
                    'SO1',val21, 'SO2',val22, 'SO3',val23,...
                    'DO1',val24, 'DO2',val25, 'DO3',val26,...
                    'Visible',val27,'TotE',val31);
            
                save([pathname,filename], '-struct', 'InitStruct')
            end            
        end

        % Menu selected function: closeall
        function close_KEPLER(app, event)
            close all hidden
        end

        % Menu selected function: Save_now
        function save_now(app, event)
            InitStructure
            cd(app.KeplerDir)
            cd ..
            save('Setting.mat', '-struct', 'InitStr')
            cd(app.KeplerDir)            
        end

        % Menu selected function: Save_exit
        function save_exit_menu(app, event)
            if strcmp(get(gcbo, 'Checked'),'on')
                set(gcbo, 'Checked','off');
            else
                set(gcbo, 'Checked','on');
            end            
        end

        % Menu selected function: close_all
        function close_figures(app, event)
            close all            
        end

        % Menu selected function: SingleFigures
        function single_figures(app, event)
            if strcmp(get(gcbo, 'Checked'),'on')
                set(gcbo, 'Checked','off');
            else
                set(gcbo, 'Checked','on');
            end
        end

        % Menu selected function: Waitbars
        function Waitbars_show(app, event)
            if strcmp(get(gcbo, 'Checked'),'on')
                set(gcbo, 'Checked','off');
            else
                set(gcbo, 'Checked','on');
                set(app.SlowWaitbars, 'Checked','off');
            end            
        end

        % Menu selected function: SlowWaitbars
        function SlowWaitbars_show(app, event)
            if strcmp(get(gcbo, 'Checked'),'on')
                set(gcbo, 'Checked','off');
            else
                set(gcbo, 'Checked','on');
                set(app.Waitbars, 'Checked','off');
            end            
        end

        % Menu selected function: warningbox
        function warnigbox_show(app, event)
            if strcmp(get(gcbo, 'Checked'),'on')
                set(gcbo, 'Checked','off');
            else
                set(gcbo, 'Checked','on');
            end            
        end

        % Menu selected function: singularity
        function singularity_show(app, event)
            if strcmp(get(gcbo, 'Checked'),'on')
                set(gcbo, 'Checked','off');
            else
                set(gcbo, 'Checked','on');
            end            
        end

        % Menu selected function: watch
        function watch_show(app, event)
            if strcmp(get(gcbo, 'Checked'),'on')
                set(gcbo, 'Checked','off');
            else
                set(gcbo, 'Checked','on');
            end            
        end

        % Menu selected function: perform
        function perform_show(app, event)
            if strcmp(get(gcbo, 'Checked'),'on')
                set(gcbo, 'Checked','off');
            else
                set(gcbo, 'Checked','on');
            end            
        end

        % Menu selected function: LGG3_long_FMFT
        function check_LGG3_long_FMFT(app, event)
            if strcmp(get(gcbo, 'Checked'),'on')
                set(gcbo, 'Checked','off');
                set(app.LSD_long_FMFT, 'Enable','on');
                set(app.LongtimeFA, 'Enable','off');
                set(app.uipanel_ClearMemory, 'Enable','off');                
%{                
                set(app.pushbutton_StandIntegrate,'Enable', 'on')
                set(app.LongtimeFA, 'Visible','off');
                if (strcmp(get(app.uipanel_Freq3D_LSD,'Visible') , 'on') == 1) |...
                      (strcmp(get(app.uipanel_Freq3D_LGG3,'Visible') , 'on') == 1) |...
                      (strcmp(get(app.uipanel_PoincFreq_2D,'Visible') , 'on') == 1)
                  % do nothing
                else
                    set(app.uipanel_from_to, 'Visible','on');
                end
%}
            else
                set(gcbo, 'Checked','on');
                set(app.LSD_long_FMFT, 'Checked','off');
                set(app.LSD_long_FMFT, 'Enable','off');
                set(app.LongtimeFA, 'Enable','on');
                set(app.uipanel_ClearMemory, 'Enable','on');

%{
                set(app.LSD_long_FMFT, 'Checked','off');
                set(app.pushbutton_StandIntegrate,'Enable', 'off')
                set(app.edit_Step_Gprime,'Value', '0')
                set(app.edit_Step_G3prime,'Value', '0')
                set(app.edit_Final_Gprime,'Enable', 'off')
                set(app.edit_Final_G3prime,'Enable', 'off')
                if (strcmp(get(app.uipanel_Freq3D_LSD,'Visible') , 'on') == 1) |...
                      (strcmp(get(app.uipanel_Freq3D_LGG3,'Visible') , 'on') == 1)
                    set(app.LongtimeFA, 'Visible','on');
                elseif (strcmp(get(app.uipanel_PoincFreq_2D,'Visible') , 'on') == 1)
                    set(app.LongtimeFA, 'Visible','off');
                else
                    set(app.LongtimeFA, 'Visible','on');
                    set(app.uipanel_from_to, 'Visible','off');
                end
                if str2num(get(app.edit_ClearMemory,'Value')) < str2num(get(app.ComputeLFA,'Value'))
                    set(app.ComputeLFA,'Value',get(app.edit_ClearMemory,'Value'))
                end
%}
            end            
        end

        % Menu selected function: LSD_long_FMFT
        function check_LSD_long_FMFT(app, event)
            if strcmp(get(gcbo, 'Checked'),'on')
                set(gcbo, 'Checked','off');
                set(app.LGG3_long_FMFT, 'Enable','on');
                set(app.LongtimeFA, 'Enable','off');
                set(app.uipanel_ClearMemory, 'Enable','off');   
                %{                
                set(app.pushbutton_StandIntegrate,'Enable', 'on')
%                set(app.LongtimeFA, 'Visible','off');
                 if (strcmp(get(app.uipanel_Freq3D_LSD,'Visible') , 'on') == 1) |...
                      (strcmp(get(app.uipanel_Freq3D_LGG3,'Visible') , 'on') == 1)
                  % do nothing
                else
                    set(app.uipanel_from_to, 'Visible','on');
                end
                %}
            else
                set(gcbo, 'Checked','on');
                set(app.LGG3_long_FMFT, 'Checked','off');
                set(app.LGG3_long_FMFT, 'Enable','off');
                set(app.LongtimeFA, 'Enable','on');
                set(app.uipanel_ClearMemory, 'Enable','on');                
%{
                set(gcbo, 'Checked','on');
                set(app.LGG3_long_FMFT, 'Checked','off');
                set(app.pushbutton_StandIntegrate,'Enable', 'off')
                set(app.edit_3D_step_S,'Value', '0')
                set(app.edit_3D_step_D,'Value', '0')
                set(app.edit_3D_final_S,'Enable', 'off')
                set(app.edit_3D_final_D,'Enable', 'off')
                if (strcmp(get(app.uipanel_Freq3D_LSD,'Visible') , 'on') == 1) |...
                      (strcmp(get(app.uipanel_Freq3D_LGG3,'Visible') , 'on') == 1)
                    set(app.LongtimeFA, 'Visible','on');
                 else
                    set(app.LongtimeFA, 'Visible','on');
                    set(app.uipanel_from_to, 'Visible','off');
                end
                if str2num(get(app.edit_ClearMemory,'Value')) < str2num(get(app.ComputeLFA,'Value'))
                    set(app.ComputeLFA,'Value',get(app.edit_ClearMemory,'Value'))
                end
%}
            end            
        end

        % Menu selected function: LargeMS
        function largemarker_show(app, event)
            if strcmp(get(gcbo, 'Checked'),'on')
                set(gcbo, 'Checked','off');
            else
                set(gcbo, 'Checked','on');
            end            
        end

        % Menu selected function: no_degree
        function nodegree_show(app, event)
            if strcmp(get(gcbo, 'Checked'),'on')
                set(gcbo, 'Checked','off');
            else
                set(gcbo, 'Checked','on');
            end            
        end

        % Menu selected function: Percentage_of_RowColumn
        function percentage_show(app, event)
            if strcmp(get(gcbo, 'Checked'),'on')
                set(gcbo, 'Checked','off');
            else
                set(gcbo, 'Checked','on');
            end                       
        end

        % Menu selected function: RowColumn_Completed
        function completed_show(app, event)
            if strcmp(get(gcbo, 'Checked'),'on')
                set(gcbo, 'Checked','off');
            else
                set(gcbo, 'Checked','on');
            end                       
        end

        % Menu selected function: Total_bars
        function total_bar_show(app, event)
            if strcmp(get(gcbo, 'Checked'),'on')
                set(gcbo, 'Checked','off');
            else
                set(gcbo, 'Checked','on');
            end                      
        end

        % Menu selected function: Wait_bars
        function total_waitbar_show(app, event)
            if strcmp(get(gcbo, 'Checked'),'on')
                set(gcbo, 'Checked','off');
            else
                set(gcbo, 'Checked','on');
            end                                 
        end

        % Menu selected function: SQZ
        function SQZ_open(app, event)
            SQZ
        end

        % Menu selected function: setSQZ
        function setSQZ_Menu(app, event)
            set(app.edit_Energy,'Value','-0.5')
            set(app.edit_EnergyPoincare,'Value','-0.5')
            set(app.edit_EnergyLGG3,'Value','-0.5')
            set(app.edit_EnergyLSD,'Value','-0.5')

            load('NormalForm/SQZsetting.mat')
            set(app.popupmenuHp1,'Value','3');
            set(app.edit_epsilon1,'Value',Zeeman3_str);
            set(app.popupmenuHp2,'Value','6');
            QZ3string = num2str(0.5*(str2num(Zeeman3_str))^2);
            set(app.edit_epsilon2,'Value',QZ3string);
            set(app.popupmenuHp3,'Value','7');
            set(app.edit_epsilon3,'Value',Stark1_str);
            set(app.popupmenuHp4,'Value','9');
            set(app.edit_epsilon4,'Value',Stark3_str);
            set(app.popupmenuHp5,'Value','20');
            set(app.edit_epsilon5,'Value','0');            
            set(app.edit_S01,'Value',S1_str);
            set(app.edit_S02,'Value',S2_str);
            set(app.edit_S03,'Value',S3_str);
            set(app.edit_D01,'Value',D1_str);
            set(app.edit_D02,'Value',D2_str);
            set(app.edit_D03,'Value',D3_str);
        end

        % Menu selected function: Cr3b
        function Cr3b_open(app, event)
            Cr3b
        end

        % Menu selected function: setCr3b
        function setCr3b_Menu(app, event)
            popupmessage('Check_distance.txt')
            load('NormalForm/Cr3bsetting.mat')
            set(app.popupmenuHp1,'Value','11');
            set(app.edit_epsilon1,'Value',Euler1_str);
            set(app.popupmenuHp2,'Value','3');
            set(app.edit_epsilon2,'Value',Zeeman3_str);
            set(app.popupmenuHp3,'Value','17');
            set(app.edit_epsilon3,'Value',p2_str);
            set(app.popupmenuHp4,'Value','20');
            set(app.edit_epsilon4,'Value','0');
            set(app.popupmenuHp5,'Value','20');
            set(app.edit_epsilon5,'Value','0');            
            set(app.edit_S01,'Value',S1_str);
            set(app.edit_S02,'Value',S2_str);
            set(app.edit_S03,'Value',S3_str);
            set(app.edit_D01,'Value',D1_str);
            set(app.edit_D02,'Value',D2_str);
            set(app.edit_D03,'Value',D3_str);            
        end

        % Menu selected function: about
        function About_Menu(app, event)
            About_KEPLER
        end

        % Menu selected function: Satellite
        function Sat_open(app, event)
            Sat
        end

        % Value changed function: popupmenu_head
        function head_change(app, event)
            set(app.pushbutton_clear2D,'Enable','on')
            set(app.pushbutton_clearLGG3,'Enable','on')
            set(app.pushbutton_clearLSD,'Enable','on')

            if strcmp(get(app.edit_2D_step,'Value'),'0') & get(app.checkbox_2D_freq, 'Value') == true;
                set(app.pushbutton_CalcPlot_Poincare,'Icon','Orbita.jpg','IconAlignment','left');
            else
                set(app.pushbutton_CalcPlot_Poincare,'Icon','Tori.jpg','IconAlignment','left');
            end            

            if strcmp(get(app.edit_Step_Gprime,'Value'),'0') & strcmp(get(app.edit_Step_G3prime,'Value'),'0');
                set(app.pushbutton_calc_LGG3prime,'Icon','Orbita.jpg','IconAlignment','top');
                set(app.pushbutton_calc_LGG3prime,'Text','Calculate trajectory','FontSize',11);
            else
                set(app.pushbutton_calc_LGG3prime,'Icon','PhSpGeo.jpg','IconAlignment','top');
                set(app.pushbutton_calc_LGG3prime,'Text','Calculate...','FontSize',14);
            end

            if strcmp(get(app.edit_3D_step_S,'Value'),'0') & strcmp(get(app.edit_3D_step_D,'Value'),'0');
                set(app.pushbutton_calc_LSDprime,'Icon','Orbita.jpg','IconAlignment','top');
                set(app.pushbutton_calc_LSDprime,'Text','Calculate trajectory','FontSize',11);
            else
                set(app.pushbutton_calc_LSDprime,'Icon','PhSpGeo.jpg','IconAlignment','top');
                set(app.pushbutton_calc_LSDprime,'Text','Calculate...','FontSize',14);
            end
            
            if get(app.popupmenu_head,'Value') == '1';
                set(app.uipanel_PoincFreq_2D, 'Visible', 'off')
                set(app.uipanel_Freq3D_LGG3, 'Visible', 'off')
                set(app.uipanel_Freq3D_LSD, 'Visible', 'off')
                set(app.uipanelInit, 'Visible', 'on')
                set(app.uipanel_AA_FMFT, 'Visible', 'on')
                set(app.uipanel_RegMethod, 'Visible', 'on')
                set(app.uipanel_StandMethod, 'Visible', 'on')
%{ 
               set(app.LGG3_long_FMFT, 'Enable','off')
                set(app.LGG3_long_FMFT, 'Checked','off')
                set(app.LSD_long_FMFT, 'Enable','off')
                set(app.LSD_long_FMFT,'Checked', 'off')
                set(app.LongtimeFA,'Enable', 'off')
                set(app.uipanel_from_to, 'Enable', 'on')
                set(app.uipanel_ClearMemory,'Enable', 'on')
                set(app.SecondOorderCheckBox,'Value', false)
%}
                
            elseif get(app.popupmenu_head,'Value') == '2';
                set(app.uipanel_PoincFreq_2D, 'Visible', 'on')
                set(app.uipanel_Freq3D_LGG3, 'Visible', 'off')
                set(app.uipanel_Freq3D_LSD, 'Visible', 'off')
                set(app.uipanelInit, 'Visible', 'off')
                set(app.uipanel_AA_FMFT, 'Visible', 'off')
                set(app.uipanel_RegMethod, 'Visible', 'off')
                set(app.uipanel_StandMethod, 'Visible', 'off')
  %              set(app.uipanel_from_to, 'Enable', 'off')
  %              set(app.popupmenuODE2,'Visible', 'off')
                set(app.popupmenuODE,'Visible', 'on')
  %              set(app.uipanel_GniCodes,'Visible', 'off')
                set(app.step_ODE,'Visible', 'on')
%{
                set(app.LongtimeFA,'Enable', 'off')
                set(app.uipanel_ClearMemory,'Enable', 'off')
                set(app.LGG3_long_FMFT, 'Enable','off')
                set(app.LGG3_long_FMFT, 'Checked','off')
                set(app.LSD_long_FMFT, 'Enable','off')
                set(app.LSD_long_FMFT,'Checked', 'off')
%}
            elseif get(app.popupmenu_head,'Value') == '3';
                set(app.uipanel_PoincFreq_2D, 'Visible', 'off')
                set(app.uipanel_Freq3D_LGG3, 'Visible', 'on')
                set(app.uipanel_Freq3D_LSD, 'Visible', 'off')
                set(app.uipanelInit, 'Visible', 'off')
                set(app.uipanel_AA_FMFT, 'Visible', 'off')
                set(app.uipanel_RegMethod, 'Visible', 'off')
                set(app.uipanel_StandMethod, 'Visible', 'off')
%                set(app.uipanel_from_to, 'Enable', 'off')
%                set(app.LongtimeFA,'Enable', 'on')
%                set(app.ComputeLFA,'Enable', 'on')
%                set(app.uipanel_ClearMemory,'Enable', 'on')
                if ~isnan(findstr(cd, 'Slave'))
            %       do nothing
                else
                    if strcmp(get(app.uipanel_Pauli, 'Visible'),'on')
                        set(app.pushbutton_calc_LGG3prime, 'Enable', 'on')
                    else
                        warndlg(['To enable the pushbutton ''Calculate...'', you must before ',...
                          'calculate the Pauli vectors of the relative equilibrium point'])
                        set(app.pushbutton_calc_LGG3prime, 'Enable', 'off')
                    end
                end
%                set(app.popupmenuODE2,'Visible', 'off')
                set(app.popupmenuODE,'Visible', 'on')
%                set(app.uipanel_GniCodes,'Visible', 'off')
                set(app.step_ODE,'Visible', 'on')
                if str2num(get(app.edit_ClearMemory,'Value')) < str2num(get(app.ComputeLFA,'Value'))
                    set(app.ComputeLFA,'Value',get(app.edit_ClearMemory,'Value'))
                end
%                set(app.LGG3_long_FMFT,'Enable', 'on')
%                set(app.LSD_long_FMFT,'Enable', 'off')
%                set(app.LSD_long_FMFT,'Checked', 'off')
            elseif get(app.popupmenu_head,'Value') == '4';
                set(app.uipanel_PoincFreq_2D, 'Visible', 'off')
                set(app.uipanel_Freq3D_LGG3, 'Visible', 'off')
                set(app.uipanel_Freq3D_LSD, 'Visible', 'on')
                set(app.uipanelInit, 'Visible', 'off')
                set(app.uipanel_AA_FMFT, 'Visible', 'off')
                set(app.uipanel_RegMethod, 'Visible', 'off')
                set(app.uipanel_StandMethod, 'Visible', 'off')
%                set(app.uipanel_from_to, 'Enable', 'off')
%                set(app.LongtimeFA,'Enable', 'on')
%                set(app.ComputeLFA,'Enable', 'on')
%                set(app.uipanel_ClearMemory,'Enable', 'on')
                if ~isnan(findstr(cd, 'Slave'))
            %       do nothing
                    set(app.pushbutton_calc_LSDprime,'Visible','off')
                else
                    if strcmp(get(app.uipanel_Pauli, 'Visible'),'on')
                        set(app.pushbutton_calc_LSDprime, 'Enable', 'on')
                    else
                        warndlg(['To enable the pushbutton ''Calculate...'', you must before ',...
                          'calculate the Pauli vectors of the relative equilibrium point'])
                        set(app.pushbutton_calc_LSDprime, 'Enable', 'off')
                    end
                end
                set(app.popupmenuODE2,'Visible', 'off')
                set(app.popupmenuODE,'Visible', 'on')
                set(app.uipanel_GniCodes,'Visible', 'off')
                set(app.step_ODE,'Visible', 'on')
                if str2num(get(app.edit_ClearMemory,'Value')) < str2num(get(app.ComputeLFA,'Value'))
                    set(app.ComputeLFA,'Value',get(app.edit_ClearMemory,'Value'))
                end
                if ~isnan(findstr(cd, 'Slave'))
                    set(app.pushbutton_StartSlave_LSD, 'Visible', 'on')
                end
%                set(app.LGG3_long_FMFT,'Checked', 'off')
%                set(app.LGG3_long_FMFT,'Enable', 'off')
%                set(app.LSD_long_FMFT,'Enable', 'on')
            end
        end

        % Value changed function: popupmenuHp1
        function Hp1_change(app, event)
            Disable_General
        end

        % Value changed function: popupmenuHp2
        function Hp2_change(app, event)
            Disable_General         
        end

        % Value changed function: popupmenuHp3
        function Hp3_change(app, event)
            Disable_General          
        end

        % Value changed function: popupmenuHp4
        function Hp4_change(app, event)
            Disable_General            
        end

        % Value changed function: popupmenuHp5
        function Hp5_change(app, event)
            Disable_General             
        end

        % Value changed function: edit_epsilon1
        function epsilon1_change(app, event)
            Disable_General
        end

        % Value changed function: edit_epsilon2
        function epsilon2_change(app, event)
            Disable_General
        end

        % Value changed function: edit_epsilon3
        function epsilon3_change(app, event)
            Disable_General
        end

        % Value changed function: edit_epsilon4
        function epsilon4_change(app, event)
            Disable_General
        end

        % Value changed function: edit_epsilon5
        function epsilon5_change(app, event)
            Disable_General
        end

        % Value changed function: q1_value
        function q1_change(app, event)
            Disable_General
            set(app.pushbutton_Integrate, 'Enable', 'off')
            set(app.pushbutton_StandIntegrate, 'Enable', 'off')
            set(app.pushbutton_UpdateKepler, 'Enable', 'on')          
        end

        % Value changed function: q2_value
        function q2_change(app, event)
            Disable_General
            set(app.pushbutton_Integrate, 'Enable', 'off')
            set(app.pushbutton_StandIntegrate, 'Enable', 'off')            
            set(app.pushbutton_UpdateKepler, 'Enable', 'on') 
        end

        % Value changed function: q3_value
        function q3_chnge(app, event)
            Disable_General
            set(app.pushbutton_Integrate, 'Enable', 'off')
            set(app.pushbutton_StandIntegrate, 'Enable', 'off')            
            set(app.pushbutton_UpdateKepler, 'Enable', 'on')     
        end

        % Value changed function: p1_value
        function p1_change(app, event)
            Disable_General
            set(app.pushbutton_Integrate, 'Enable', 'off')
            set(app.pushbutton_StandIntegrate, 'Enable', 'off')            
            set(app.pushbutton_UpdateKepler, 'Enable', 'on') 
        end

        % Value changed function: p2_value
        function p2_change(app, event)
            Disable_General
            set(app.pushbutton_Integrate, 'Enable', 'off')
            set(app.pushbutton_StandIntegrate, 'Enable', 'off')            
            set(app.pushbutton_UpdateKepler, 'Enable', 'on')             
        end

        % Value changed function: p3_value
        function p3_change(app, event)
            Disable_General
            set(app.pushbutton_Integrate, 'Enable', 'off')
            set(app.pushbutton_StandIntegrate, 'Enable', 'off')            
            set(app.pushbutton_UpdateKepler, 'Enable', 'on')             
        end

        % Value changed function: edit_smaxis
        function Sma_change(app, event)
            if str2num(get(app.edit_smaxis,'Value')) <= 0
                warndlg('The value of the semimajor axis must be strictly positive')
                set(app.edit_smaxis,'Value','1')
%                update_qp(app, event)
%                return                
            end
            Disable_General
            set(app.pushbutton_Integrate, 'Enable', 'off')
            set(app.pushbutton_StandIntegrate, 'Enable', 'off')            
            set(app.pushbutton_UpdateCartesian, 'Enable', 'on')             
        end

        % Value changed function: edit_Eccentricity
        function Ecc_change(app, event)
            Disable_General
            EccVal = str2num(get(app.edit_Eccentricity,'Value'));
            if (EccVal < 0 | EccVal >= 1)
                warndlg('The eccentricity must be null or positive and less than one')
                set(app.edit_Eccentricity,'Value','0')
%                update_qp(app, event)
%                return
            end
            set(app.pushbutton_Integrate, 'Enable', 'off')
            set(app.pushbutton_StandIntegrate, 'Enable', 'off')            
            set(app.pushbutton_UpdateCartesian, 'Enable', 'on')            
        end

        % Value changed function: edit_AscNode
        function AscNode_change(app, event)
            Disable_General
            set(app.pushbutton_Integrate, 'Enable', 'off')
            set(app.pushbutton_StandIntegrate, 'Enable', 'off')            
            set(app.pushbutton_UpdateCartesian, 'Enable', 'on')            
        end

        % Value changed function: edit_ArgPericenter
        function ArgPeri_change(app, event)
            Disable_General
            set(app.pushbutton_Integrate, 'Enable', 'off')
            set(app.pushbutton_StandIntegrate, 'Enable', 'off')            
            set(app.pushbutton_UpdateCartesian, 'Enable', 'on')           
        end

        % Value changed function: edit_Inclination
        function Incl_change(app, event)
            Disable_General
            set(app.pushbutton_Integrate, 'Enable', 'off')
            set(app.pushbutton_StandIntegrate, 'Enable', 'off')            
            set(app.pushbutton_UpdateCartesian, 'Enable', 'on')            
        end

        % Value changed function: edit_TrueAnomaly
        function TrueAn_change(app, event)
            Disable_General
            set(app.pushbutton_Integrate, 'Enable', 'off')
            set(app.pushbutton_StandIntegrate, 'Enable', 'off')            
            set(app.pushbutton_UpdateCartesian, 'Enable', 'on')            
        end

        % Value changed function: popupmenuODE
        function ODE_change(app, event)
            Disable_General
            if strcmp(get(app.popupmenuODE,'Value'),'RungeKutta4') |...
                    strcmp(get(app.popupmenuODE,'Value'),'RungeKutta5')
                set(app.RelTol, 'Enable', 'off')
                set(app.AbsTol, 'Enable', 'off')
                set(app.edit_block, 'Enable', 'off')
                if isempty(get(app.step_ODE,'Value'));
                    warndlg('The Output Step for Runge-Kutta must be strictly positive. Default value = 0.01.')
                    set(app.step_ODE,'Value','0.01');
                end
            else
                set(app.RelTol, 'Enable', 'on')
                set(app.AbsTol, 'Enable', 'on')
                set(app.text_block, 'Visible', 'on')
                set(app.edit_block, 'Visible', 'on')
                set(app.edit_block, 'Enable', 'on')
            end
            if strcmp(get(app.popupmenuODE,'Value'),'ShampineGordon')
                set(app.AbsTol,'Value', 1e-07);
            else
                set(app.AbsTol,'Value', 1e-14);
            end
        end

        % Value changed function: RelTol
        function RelTol_change(app, event)
            Disable_General
        end

        % Value changed function: AbsTol
        function AbsTol_change(app, event)
            Disable_General
        end

        % Value changed function: step_ODE
        function step_ODE_change(app, event)
            Disable_General
            if isempty(get(app.step_ODE,'Value'));
                set(app.text_block,'Visible','off');
                set(app.edit_block,'Visible','off');
                if strcmp(get(app.popupmenuODE,'Value'),'RungeKutta4') | strcmp(get(app.popupmenuODE,'Value'),'RungeKutta5')                
                    warndlg('The Output Step for Runge-Kutta must be strictly positive. Default value = 0.01.')
                    set(app.step_ODE,'Value','0.01');
                end
            else
                set(app.text_block,'Visible','on');
                set(app.edit_block,'Visible','on');
            end
        end

        % Value changed function: edit_block
        function block_change(app, event)
            Disable_General
        end

        % Value changed function: popupmenuODE2
        function ODE2_change(app, event)
            Disable_General
        end

        % Value changed function: edit_StepSize2
        function Step_ODE2_change(app, event)
            Disable_General
        end

        % Value changed function: total_cycles
        function total_cycle_change(app, event)
            val = get(app.total_cycles,'Value');
            if str2num(get(app.edit_from,'Value')) > str2num(val)...
                    | str2num(get(app.edit_to,'Value')) ~= str2num(val)
                set(app.edit_from,'Value', '0');
                set(app.edit_to,'Value', val);
            end
            Disable_General
            clear global Ang_tot Act_tot
            set(app.pushbutton_clear2D, 'Enable', 'off')    
        end

        % Value changed function: edit_from
        function edit_from_change(app, event)
            val = get(app.total_cycles,'Value');
            if str2num(get(app.edit_from,'Value')) > str2num(val)...
                  | str2num(get(app.edit_from,'Value')) > str2num(get(app.edit_to,'Value'))
               set(app.edit_from,'Value', '0');
            end
            clear global Ang_tot Act_tot
            set(app.pushbutton_clearLGG3,'Enable','off') 
        end

        % Value changed function: edit_to
        function edit_to_change(app, event)
            val = get(app.total_cycles,'Value');
            if str2num(val) < str2num(get(app.edit_to,'Value'))...
                    | str2num(get(app.edit_to,'Value')) < str2num(get(app.edit_from,'Value'))                 
                set(app.edit_to,'Value', val);                 
            end
            clear global Ang_tot Act_tot
            set(app.pushbutton_clearLGG3,'Enable','off') 
        end

        % Button pushed function: pushbutton_UpdateKepler
        function update_Kepler(app, event)
            q_vec_o(1) = str2num(get(app.q1_value,'Value'));
            q_vec_o(2) = str2num(get(app.q2_value,'Value'));
            q_vec_o(3) = str2num(get(app.q3_value,'Value'));
            p_vec_o(1) = str2num(get(app.p1_value,'Value'));
            p_vec_o(2) = str2num(get(app.p2_value,'Value'));
            p_vec_o(3) = str2num(get(app.p3_value,'Value'));
            
            Cartesian2Keplerian
            
            set(app.edit_smaxis,'Value', num2str(sm_ax_o,'%5.15f'))
            set(app.edit_Eccentricity,'Value', num2str(Ecc_o,'%5.15f'))
            set(app.edit_AscNode,'Value', num2str(Omega_o,'%5.15f'))
            set(app.edit_ArgPericenter,'Value', num2str(omega_o,'%5.15f'))
            set(app.edit_Inclination,'Value', num2str(incl_o,'%5.15f'))
            if exist('f_o')
                set(app.edit_TrueAnomaly,'Value', num2str(f_o,'%5.15f'))
            end            
            set(app.pushbutton_UpdateKepler,'Enable','off')
            set(app.pushbutton_Integrate,'Enable','on')
            set(app.pushbutton_StandIntegrate,'Enable','on')
            if (get(app.pushbutton_UpdateInit,'Enable') == 1) &...
                strcmp(get(app.uipanel_Pauli, 'Visible'),'on')
                set(app.pushbutton_Up_Pauli, 'Enable', 'on')
            end            
        end

        % Button pushed function: pushbutton_UpdateCartesian
        function update_qp(app, event)
            sm_ax_o = str2num(get(app.edit_smaxis,'Value'));
            Ecc_o = str2num(get(app.edit_Eccentricity,'Value'));
            Omega_o = str2num(get(app.edit_AscNode,'Value'));
            omega_o = str2num(get(app.edit_ArgPericenter,'Value'));
            incl_o = str2num(get(app.edit_Inclination,'Value'));
            f_o = str2num(get(app.edit_TrueAnomaly,'Value'));
            
            Keplerian2Cartesian
            
            set(app.q1_value,'Value', num2str(q_vec_o(1),'%5.15f'))
            set(app.q2_value,'Value', num2str(q_vec_o(2),'%5.15f'))
            set(app.q3_value,'Value', num2str(q_vec_o(3),'%5.15f'))
            set(app.p1_value,'Value', num2str(p_vec_o(1),'%5.15f'))
            set(app.p2_value,'Value', num2str(p_vec_o(2),'%5.15f'))
            set(app.p3_value,'Value', num2str(p_vec_o(3),'%5.15f'))
            
            set(app.pushbutton_UpdateCartesian,'Enable','off')
            set(app.pushbutton_Integrate,'Enable','on')
            set(app.pushbutton_StandIntegrate,'Enable','on')
            if (get(app.pushbutton_UpdateInit,'Enable') == 1) &...
                strcmp(get(app.uipanel_Pauli, 'Visible'),'on')
                set(app.pushbutton_Up_Pauli, 'Enable', 'on')
            end            
        end

        % Button pushed function: pushbutton_Up_Pauli
        function update_Pauli(app, event)
            clear global lambda
            global epsilon1 epsilon2 epsilon3 epsilon4 epsilon5 Hp1 Hp2 Hp3 Hp4 Hp5
            degrees = [0.5,0.5,0.5,2,2,2,1,1,1,2,-1,-1,-1,3,-2,-3,-0.5,2,-1,-1];            
            item = str2num(get(app.popupmenuHp1,'Value'));
            Items = get(app.popupmenuHp1,'Items');            
            Hp1 = Items{item};
            deg1 = degrees(item);

            item = str2num(get(app.popupmenuHp2,'Value'));
            Items = get(app.popupmenuHp2,'Items');            
            Hp2 = Items{item};
            deg2 = degrees(item);

            item = str2num(get(app.popupmenuHp3,'Value'));
            Items = get(app.popupmenuHp3,'Items');            
            Hp3 = Items{item};
            deg3 = degrees(item);

            item = str2num(get(app.popupmenuHp4,'Value'));
            Items = get(app.popupmenuHp4,'Items');            
            Hp4 = Items{item};
            deg4 = degrees(item);

            item = str2num(get(app.popupmenuHp5,'Value'));
            Items = get(app.popupmenuHp5,'Items');            
            Hp5 = Items{item};
            deg5 = degrees(item);
            
            epsilon1 = str2num(get(app.edit_epsilon1,'Value'));
            epsilon2 = str2num(get(app.edit_epsilon2,'Value'));
            epsilon3 = str2num(get(app.edit_epsilon3,'Value'));
            epsilon4 = str2num(get(app.edit_epsilon4,'Value'));
            epsilon5 = str2num(get(app.edit_epsilon5,'Value'));   
            
            q_vec_o(1) = str2num(get(app.q1_value,'Value'));
            q_vec_o(2) = str2num(get(app.q2_value,'Value'));
            q_vec_o(3) = str2num(get(app.q3_value,'Value'));
            p_vec_o(1) = str2num(get(app.p1_value,'Value'));
            p_vec_o(2) = str2num(get(app.p2_value,'Value'));
            p_vec_o(3) = str2num(get(app.p3_value,'Value'));
            E_total = 1/2*norma(p_vec_o).^2 - 1./norma(q_vec_o)...
                 + Hp(q_vec_o, p_vec_o, epsilon1, epsilon2, epsilon3, epsilon4, epsilon5, Hp1, Hp2, Hp3, Hp4, Hp5);
            
            Cartesian2SD
            set(app.edit_S01,'Value', num2str(S1,'%1.15f'))
            set(app.edit_S02,'Value', num2str(S2,'%1.15f'))
            set(app.edit_S03,'Value', num2str(S3,'%1.15f'))
            set(app.edit_D01,'Value', num2str(D1,'%1.15f'))
            set(app.edit_D02,'Value', num2str(D2,'%1.15f'))
            set(app.edit_D03,'Value', num2str(D3,'%1.15f'))
            set(app.edit_Energy,'Value', num2str(E_total,'%1.15f'))
            set(app.edit_EnergyPoincare,'Value', num2str(E_total,'%1.15f'))
            set(app.edit_EnergyLGG3,'Value', num2str(E_total,'%1.15f'))
            set(app.edit_EnergyLSD,'Value', num2str(E_total,'%1.15f'))
            
            set(app.pushbutton_Up_Pauli, 'Enable', 'off')
            Disable_General
        end

        % Value changed function: edit_S01
        function check_s1(app, event)
            value = app.edit_S01.Value;
            if isnan(str2double(value))
                   warndlg('Input must be a number','Wrong entry')
                   return
            end
            NormalizeS
        end

        % Value changed function: edit_S02
        function check_s2(app, event)
            value = app.edit_S02.Value;
            if isnan(str2double(value))
                   warndlg('Input must be a number','Wrong entry')
                   return
            end
            NormalizeS
        end

        % Value changed function: edit_S03
        function check_s3(app, event)
            value = app.edit_S03.Value;
            if isnan(str2double(value))
                   warndlg('Input must be a number','Wrong entry')
                   return
            end
            NormalizeS
        end

        % Value changed function: edit_D01
        function check_d1(app, event)
            value = app.edit_D01.Value;
            if isnan(str2double(value))
                   warndlg('Input must be a number','Wrong entry')
                   return
            end
            NormalizeD
        end

        % Value changed function: edit_D02
        function check_d2(app, event)
            value = app.edit_D02.Value;
            if isnan(str2double(value))
                   warndlg('Input must be a number','Wrong entry')
                   return
            end
            NormalizeD
        end

        % Value changed function: edit_D03
        function check_d3(app, event)
            value = app.edit_D03.Value;
            if isnan(str2double(value))
                   warndlg('Input must be a number','Wrong entry')
                   return
            end
            NormalizeD
        end

        % Button pushed function: pushbutton_Integrate
        function exec_Integrate(app, event)
            clear global q_vec p_vec sm_ax q_vec_o p_vec_o tau_total tau_i tau_f
            clear global epsilon1 epsilon2 epsilon3 epsilon4 epsilon5 deg1 deg2 deg3 deg4 deg5 Hp1 Hp2 Hp3 Hp4 Hp5
            clear global lambda step_i step_f x_vec y_vec tau tau_c W Ko
            clear global dist1 dist2 dist3
            
            global q_vec p_vec sm_ax q_vec_o p_vec_o tau_total tau_i tau_f
            global epsilon1 epsilon2 epsilon3 epsilon4 epsilon5 deg1 deg2 deg3 deg4 deg5 Hp1 Hp2 Hp3 Hp4 Hp5
            global lambda step_i step_f x_vec y_vec tau tau_c W Ko
            global perfid
            
            Disable_General
            cd(app.KeplerDir)
            degrees = [0.5,0.5,0.5,2,2,2,1,1,1,2,-1,-1,-1,-3,-2,-3,-0.5,2,-1,-1];
            
            item = str2num(get(app.popupmenuHp1,'Value'));
            Items = get(app.popupmenuHp1,'Items');            
            Hp1 = Items{item};
            deg1 = degrees(item);

            item = str2num(get(app.popupmenuHp2,'Value'));
            Items = get(app.popupmenuHp2,'Items');            
            Hp2 = Items{item};
            deg2 = degrees(item);

            item = str2num(get(app.popupmenuHp3,'Value'));
            Items = get(app.popupmenuHp3,'Items');            
            Hp3 = Items{item};
            deg3 = degrees(item);

            item = str2num(get(app.popupmenuHp4,'Value'));
            Items = get(app.popupmenuHp4,'Items');            
            Hp4 = Items{item};
            deg4 = degrees(item);

            item = str2num(get(app.popupmenuHp5,'Value'));
            Items = get(app.popupmenuHp5,'Items');            
            Hp5 = Items{item};
            deg5 = degrees(item);
            
            epsilon1 = str2num(get(app.edit_epsilon1,'Value'));
            epsilon2 = str2num(get(app.edit_epsilon2,'Value'));
            epsilon3 = str2num(get(app.edit_epsilon3,'Value'));
            epsilon4 = str2num(get(app.edit_epsilon4,'Value'));
            epsilon5 = str2num(get(app.edit_epsilon5,'Value'));
            
            q_vec_o(1) = str2num(get(app.q1_value,'Value'));
            q_vec_o(2) = str2num(get(app.q2_value,'Value'));
            q_vec_o(3) = str2num(get(app.q3_value,'Value'));
            p_vec_o(1) = str2num(get(app.p1_value,'Value'));
            p_vec_o(2) = str2num(get(app.p2_value,'Value'));
            p_vec_o(3) = str2num(get(app.p3_value,'Value'));
            
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));
            
            ODE = get(app.popupmenuODE,'Value');
            
            Integrate
            
            clear DiffEquation            
        end

        % Value changed function: check_pert
        function check_pert_change(app, event)
            set(app.check_pert,'Value', true)
            set(app.check_nonpert,'Value', false)
            Disable_General
        end

        % Value changed function: check_nonpert
        function check_nonpert_change(app, event)
            set(app.check_nonpert,'Value', true)
            set(app.check_pert,'Value', false)
            Disable_General
        end

        % Button pushed function: pushbutton_Trajectories
        function plot_Trajectories(app, event)
            global q_vec p_vec tau_c W lambda
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));
            delta_tau = tau_f - tau_i;
           
            if delta_tau > 100
                button = questdlg(sprintf(['In 3D, we do not recommend viewing more than (about) 100 cycles:\n',...
                    'the visualization may be lengthy and the figure overloaded.\n',...
                    '               Do you continue anyway?']),...
                    'Too much cycles to display', 'No');
                if strcmp(button,'Yes')
                    if get(app.check_pert,'Value') == 1
                        Calculate_Trajectories
                    elseif get(app.check_nonpert,'Value') == 1
                        Calculate_TrajectoriesNonPert
                    end
                    Trajectories
                end
            else
                if get(app.check_pert,'Value') == true
                    Calculate_Trajectories
                elseif get(app.check_nonpert,'Value') == true
                    Calculate_TrajectoriesNonPert
                end
                Trajectories
            end
        end

        % Button pushed function: pushbutton_tauKepler
        function plot_tauKepler(app, event)
            global lambda q_vec p_vec tau W tau_c
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));

            if get(app.check_pert,'Value') == 1
                Calculate_Trajectories
            elseif get(app.check_nonpert,'Value') == 1
                Calculate_TrajectoriesNonPert
            end

            tau_Kepler_Parameters            
        end

        % Button pushed function: pushbutton_tKepler
        function plot_tKepler(app, event)
            global lambda q_vec p_vec tau W tau_c
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));

            if get(app.check_pert,'Value') == 1
                Calculate_Trajectories
            elseif get(app.check_nonpert,'Value') == 1
                Calculate_TrajectoriesNonPert
            end

            t_Kepler_Parameters            
        end

        % Button pushed function: pushbutton_Time
        function plot_Time(app, event)
            global lambda q_vec W tau tau_i tau_f tau_total tau_c
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));

            if get(app.check_pert,'Value') == 1
                Calculate_Trajectories
            elseif get(app.check_nonpert,'Value') == 1
                Calculate_TrajectoriesNonPert
            end

            Time            
        end

        % Button pushed function: pushbutton_Errors
        function plot_Errors(app, event)
            global q_vec p_vec step_i step_f x_vec y_vec W tau tau_c
            global Ko q_vec_o p_vec_o lambda
            global q_vec p_vec tau_c W lambda
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));

            if get(app.check_pert,'Value') == 1
                Calculate_Trajectories
            elseif get(app.check_nonpert,'Value') == 1
                Calculate_TrajectoriesNonPert
            end

            Errors            
        end

        % Button pushed function: pushbutton_tau_Step
        function plot_tau_Step(app, event)
            global q_vec p_vec tau_c tau W lambda

            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));

            if get(app.check_pert,'Value') == 1
                Calculate_Trajectories
            elseif get(app.check_nonpert,'Value') == 1
                Calculate_TrajectoriesNonPert
            end

            figure
            plot(tau)
            title('False time vs Step number','FontSize',12)
            xlabel('Step number', 'FontSize',14), ylabel('\tau', 'FontSize',14)     
        end

        % Button pushed function: pushbutton_UserFunction
        function plot_User(app, event)
            global lambda q_vec p_vec tau W tau_c
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));

            if get(app.check_pert,'Value') == 1
                Calculate_Trajectories
            elseif get(app.check_nonpert,'Value') == 1
                Calculate_TrajectoriesNonPert
            end            
            
            q1 = q_vec(:,1);
            q2 = q_vec(:,2);
            q3 = q_vec(:,3);
            p1 = p_vec(:,1);
            p2 = p_vec(:,2);
            p3 = p_vec(:,3);
            
            cd(app.KeplerDir);
            [filename, pathname] = uigetfile( ...
                {'*.m', 'All M-Files (*.m)'; ...
                    '*.*','All Files (*.*)'}, ...
                'Choose function(s) to plot','UserFunctions/');
            if isequal([filename,pathname],[0,0])
                return
            else
                [pathstr, name, ext] = fileparts(filename);
                cd(pathname)
                eval(name)
                cd(app.KeplerDir)
            end
            
            d = size(UserFunction, 2);
            for k = 1:d
                figure
                plot(tau, UserFunction(:,k))
                str = num2str(k);
                title(['User function ', str, ' \itvs \rmFalse time'],'FontSize',12)
                xlabel('\tau', 'FontSize',14), ylabel('Function', 'FontSize',14)
            end            
        end

        % Button pushed function: pushbutton_StandIntegrate
        function exec_StandIntegrate(app, event)
            ODE = get(app.popupmenuODE,'Value');
            if (strcmp(ODE,'BulirschStoer') | strcmp(ODE,'ABM8') | strcmp(ODE,'ShampineGordon'))
                warndlg('This ODE solver is not implemented for the Standard Integration method')
                return
            end

            clear global  epsilon1 epsilon2 epsilon3 epsilon4 epsilon5 Hp1 Hp2 Hp3 Hp4 Hp5 t_c W
            clear global q_vec p_vec q_vec_o p_vec_o tau_total tau_i tau_f t
            clear global lambda
            global  epsilon1 epsilon2 epsilon3 epsilon4 epsilon5 Hp1 Hp2 Hp3 Hp4 Hp5 t_c W
            global q_vec p_vec q_vec_o p_vec_o tau_total tau_i tau_f t
            global perfid

            Disable_General
            cd(app.KeplerDir)
            degrees = [0.5,0.5,0.5,2,2,2,1,1,1,2,-1,-1,-1,3,-2,-3,-0.5,2,-1,-1];
            
            item = str2num(get(app.popupmenuHp1,'Value'));
            Items = get(app.popupmenuHp1,'Items');            
            Hp1 = Items{item};
            deg1 = degrees(item);

            item = str2num(get(app.popupmenuHp2,'Value'));
            Items = get(app.popupmenuHp2,'Items');            
            Hp2 = Items{item};
            deg2 = degrees(item);

            item = str2num(get(app.popupmenuHp3,'Value'));
            Items = get(app.popupmenuHp3,'Items');            
            Hp3 = Items{item};
            deg3 = degrees(item);

            item = str2num(get(app.popupmenuHp4,'Value'));
            Items = get(app.popupmenuHp4,'Items');            
            Hp4 = Items{item};
            deg4 = degrees(item);

            item = str2num(get(app.popupmenuHp5,'Value'));
            Items = get(app.popupmenuHp5,'Items');            
            Hp5 = Items{item};
            deg5 = degrees(item);
            
            epsilon1 = str2num(get(app.edit_epsilon1,'Value'));
            epsilon2 = str2num(get(app.edit_epsilon2,'Value'));
            epsilon3 = str2num(get(app.edit_epsilon3,'Value'));
            epsilon4 = str2num(get(app.edit_epsilon4,'Value'));
            epsilon5 = str2num(get(app.edit_epsilon5,'Value'));

            q_vec_o(1) = str2num(get(app.q1_value,'Value'));
            q_vec_o(2) = str2num(get(app.q2_value,'Value'));
            q_vec_o(3) = str2num(get(app.q3_value,'Value'));
            p_vec_o(1) = str2num(get(app.p1_value,'Value'));
            p_vec_o(2) = str2num(get(app.p2_value,'Value'));
            p_vec_o(3) = str2num(get(app.p3_value,'Value'));
            
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));
            
            sm_ax_o = str2num(get(app.edit_smaxis,'Value'));
            
            Stand_Integrate
      
            set(app.pushbutton_Integrate, 'Enable', 'on')
            set(app.pushbutton_StandIntegrate, 'Enable', 'on')
            set(app.pushbutton_StandTrajectories, 'Enable', 'on')
            set(app.pushbutton_KeplerParameters, 'Enable', 'on')
            set(app.pushbutton_Error, 'Enable', 'on')
            set(app.pushbutton_t_Step, 'Enable', 'on')
            set(app.pushbutton_Stand_UserFunction, 'Enable', 'on')
        end

        % Button pushed function: pushbutton_StandTrajectories
        function plot_StandTrajectories(app, event)
            global q_vec p_vec tau_c W t_c
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));
            delta_tau = tau_f - tau_i;
            if delta_tau > 100
                button = questdlg(sprintf(['In 3D, we do not recommend viewing more than (about) 100 cycles:\n',...
                    'the visualization way be lengthy and the figure overloaded.\n',...
                    '               Do you continue anyway?']),...
                    'Too much cycles to display', 'No');
                if strcmp(button,'Yes')
                    Stand_Calculate_Trajectories
                    Stand_Trajectories
                end
            else
                Stand_Calculate_Trajectories
                Stand_Trajectories
            end            
        end

        % Button pushed function: pushbutton_KeplerParameters
        function plot_KeplerParameters(app, event)
            global q_vec p_vec W t_c
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));
            
            Stand_Calculate_Trajectories
            Stand_Kepler_Parameters            
        end

        % Button pushed function: pushbutton_Error
        function plot_Error(app, event)
            clear global lambda
            global q_vec p_vec q_vec_o p_vec_o t W t_c
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));
            Stand_Calculate_Trajectories
            ErrorHstand            
        end

        % Button pushed function: pushbutton_t_Step
        function plot_t_step(app, event)
            global q_vec p_vec tau_c W t_c sm_ax_o
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));
            
            Stand_Calculate_Trajectories
            figure
            plot(t)
            title('Time vs Step number','FontSize',12)
            xlabel('Step number', 'FontSize',14), ylabel('\itt', 'FontSize',14)            
        end

        % Button pushed function: pushbutton_Stand_UserFunction
        function plot_StandardUser(app, event)
            global q_vec p_vec t W t_c
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));
            Stand_Calculate_Trajectories
            q1 = q_vec(:,1);
            q2 = q_vec(:,2);
            q3 = q_vec(:,3);
            p1 = p_vec(:,1);
            p2 = p_vec(:,2);
            p3 = p_vec(:,3);
            
            cd(app.KeplerDir);
            [filename, pathname] = uigetfile( ...
                {'*.m', 'All M-Files (*.m)'; ...
                    '*.*','All Files (*.*)'}, ...
                'Choose function(s) to plot','UserFunctions/');
            if isequal([filename,pathname],[0,0])
                return
            else
                [pathstr, name, ext] = fileparts(filename);
                cd(pathname)
                eval(name)
                cd(app.KeplerDir)
            end
            
            d = size(UserFunction, 2);
            for k = 1:d
                figure
                plot(t, UserFunction(:,k))
                str = num2str(k);
                title(['User function ', str, ' \itvs \rmtime'],'FontSize',12)
                xlabel('\itt', 'FontSize',14), ylabel('Function', 'FontSize',14)
            end            
        end

        % Button pushed function: pushbutton_UpdateInit
        function update_init(app, event)
            clear global lambda            
            global epsilon1 epsilon2 epsilon3 epsilon4 epsilon5 Hp1 Hp2 Hp3 Hp4 Hp5
            
            degrees = [0.5,0.5,0.5,2,2,2,1,1,1,2,-1,-1,-1,3,-2,-3,-0.5,2,-1,-1];            
            item = str2num(get(app.popupmenuHp1,'Value'));
            Items = get(app.popupmenuHp1,'Items');            
            Hp1 = Items{item};
            deg1 = degrees(item);

            item = str2num(get(app.popupmenuHp2,'Value'));
            Items = get(app.popupmenuHp2,'Items');            
            Hp2 = Items{item};
            deg2 = degrees(item);

            item = str2num(get(app.popupmenuHp3,'Value'));
            Items = get(app.popupmenuHp3,'Items');            
            Hp3 = Items{item};
            deg3 = degrees(item);

            item = str2num(get(app.popupmenuHp4,'Value'));
            Items = get(app.popupmenuHp4,'Items');            
            Hp4 = Items{item};
            deg4 = degrees(item);

            item = str2num(get(app.popupmenuHp5,'Value'));
            Items = get(app.popupmenuHp5,'Items');            
            Hp5 = Items{item};
            deg5 = degrees(item);
            
            epsilon1 = str2num(get(app.edit_epsilon1,'Value'));
            epsilon2 = str2num(get(app.edit_epsilon2,'Value'));
            epsilon3 = str2num(get(app.edit_epsilon3,'Value'));
            epsilon4 = str2num(get(app.edit_epsilon4,'Value'));
            epsilon5 = str2num(get(app.edit_epsilon5,'Value'));
            
            S_vec_o(1) = str2num(get(app.edit_S01,'Value'));
            S_vec_o(2) = str2num(get(app.edit_S02,'Value'));
            S_vec_o(3) = str2num(get(app.edit_S03,'Value'));
            D_vec_o(1) = str2num(get(app.edit_D01,'Value'));
            D_vec_o(2) = str2num(get(app.edit_D02,'Value'));
            D_vec_o(3) = str2num(get(app.edit_D03,'Value'));
            f_o = str2num(get(app.edit_TrueAnomaly,'Value'));
            
            [q_vec_o, p_vec_o] = SD2Cartesian(S_vec_o/2, D_vec_o/2, f_o);
            
            %-------------------------------------------------------------------------------
            E_tot = str2num(get(app.edit_Energy,'Value'));
            lambda_tot = 1/sqrt(-2*E_tot);
            lambd = lambda_tot + 1;
            lambda = 1.0;
            
            kk = 0;
            while abs(lambd - lambda_tot) > 1e-02
                if kk > 50
                    break
                end
                lambd = 1/sqrt(-2*(1/2*p_vec_o*p_vec_o' - 1/sqrt(q_vec_o*q_vec_o')...
                    + Hp(q_vec_o, p_vec_o, epsilon1, epsilon2, epsilon3, epsilon4, epsilon5, Hp1, Hp2, Hp3, Hp4, Hp5)));
            
                if ~isreal(lambd)
                    break
                end
            
                q_vec_o = lambda_tot^2*q_vec_o/lambd^2;
                p_vec_o = p_vec_o*lambd/lambda_tot;
                kk = kk + 1;
            end
            
            if (~isreal(lambd) | isnan(lambd))
                 [q_vec_o, p_vec_o] = SD2Cartesian(S_vec_o/2, D_vec_o/2, f_o);
            end
            
            f = @(z)1/sqrt(-2*(1/2*z^2*p_vec_o*p_vec_o' - 1/sqrt(q_vec_o*q_vec_o')*z^2 ...
                   + Hp(q_vec_o/z^2, p_vec_o*z, epsilon1, epsilon2, epsilon3, epsilon4, epsilon5, Hp1, Hp2, Hp3, Hp4, Hp5))) - lambda_tot;
            opts = optimset('Display','off');
            
            try
                a = fzero(f,1,opts);
            catch
                warndlg(sprintf(['With the selected value of Total Energy I am not able to find the initial conditions. Check that the energy is negative.']))
                return
            end
            
            if isnan(a)
                warndlg(sprintf(['With the selected value of Total Energy I am not able to find the initial conditions. Check that the energy is negative']))
                return
            end
            q_vec_o = q_vec_o/a^2;
            p_vec_o = p_vec_o*a;
            lambda = lambda_tot;
            %---------------------------------------------------------------------
            
            set(app.q1_value,'Value', num2str(q_vec_o(1),'%5.15f'))
            set(app.q2_value,'Value', num2str(q_vec_o(2),'%5.15f'))
            set(app.q3_value,'Value', num2str(q_vec_o(3),'%5.15f'))
            set(app.p1_value,'Value', num2str(p_vec_o(1),'%5.15f'))
            set(app.p2_value,'Value', num2str(p_vec_o(2),'%5.15f'))
            set(app.p3_value,'Value', num2str(p_vec_o(3),'%5.15f'))
            
            Cartesian2Keplerian
            
            set(app.edit_smaxis,'Value', num2str(sm_ax_o,'%5.15f'))
            set(app.edit_Eccentricity,'Value', num2str(Ecc_o,'%5.15f'))
            set(app.edit_AscNode,'Value', num2str(Omega_o,'%5.15f'))
            set(app.edit_ArgPericenter,'Value', num2str(omega_o,'%5.15f'))
            set(app.edit_Inclination,'Value', num2str(incl_o,'%5.15f'))
%            set(app.edit_TrueAnomaly,'Value', num2str(f_o,'%5.15f'))
            
            Disable_General            
        end

        % Value changed function: EnableUpdatebuttonCheckBox
        function enable_Update(app, event)
            value = app.EnableUpdatebuttonCheckBox.Value;
            if value
                app.pushbutton_UpdateInit.Enable = 'on';
%                app.pushbutton_Up_Pauli.Enable = 'on';
            else
                app.pushbutton_UpdateInit.Enable = 'off';
                app.pushbutton_Up_Pauli.Enable = 'off';
            end            
        end

        % Button pushed function: pushbutton_Pauli
        function plot_Pauli_vector(app, event)
            global lambda q_vec p_vec W tau tau_i tau_f tau_total tau_c S_vec_o D_vec_o
            global epsilon1 epsilon2 epsilon3 epsilon4 epsilon5 Hp1 Hp2 Hp3 Hp4 Hp5
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));
            
            if get(app.EnableUpdatebuttonCheckBox,'Value') == 1
                set(app.pushbutton_UpdateInit, 'Enable', 'on')
            end
            set(app.pushbutton_Pauliprime, 'Enable', 'on')
            set(app.pushbutton_AA_Delaunayprime, 'Enable', 'on')
            set(app.pushbutton_AA_Pauliprime, 'Enable', 'on')
            if strcmp(get(app.uipanel_Pauli, 'Visible'),'off')
                set(app.EnableUpdatebuttonCheckBox,'Value',1)
                set(app.pushbutton_UpdateInit, 'Enable', 'on')
                set(app.uipanel_Pauli, 'Visible', 'on')
                set(app.radiobutton_Delaunayprime, 'Enable', 'on')
                set(app.radiobutton_Pauliprime, 'Enable', 'on')
            
            end
            
            if get(app.check_pert,'Value') == 1
                Calculate_Trajectories
            elseif get(app.check_nonpert,'Value') == 1
                Calculate_TrajectoriesNonPert
            end

            tau_SD
            
            if get(app.EnableUpdatebuttonCheckBox,'Value') == 1
                set(app.edit_S01,'Value', num2str(S_vec_o(1)))
                set(app.edit_S02,'Value', num2str(S_vec_o(2)))
                set(app.edit_S03,'Value', num2str(S_vec_o(3)))
                set(app.edit_D01,'Value', num2str(D_vec_o(1)))
                set(app.edit_D02,'Value', num2str(D_vec_o(2)))
                set(app.edit_D03,'Value', num2str(D_vec_o(3)))
                clear global lambda
                Energy = 1/2*norma(p_vec(1,:))^2 - 1./norma(q_vec(1,:)) + Hp(q_vec(1,:), p_vec(1,:),...
                               epsilon1, epsilon2, epsilon3, epsilon4, epsilon5, Hp1, Hp2, Hp3, Hp4, Hp5);
                global lambda
                lambda = 1/sqrt(-2*Energy);
                TotE = num2str(Energy(1));
                set(app.edit_Energy,'Value', TotE)
                set(app.edit_EnergyPoincare,'Value', TotE)
                set(app.edit_EnergyLGG3,'Value', TotE)
                set(app.edit_EnergyLSD,'Value', TotE)
            end            
        end

        % Button pushed function: pushbutton_Pauliprime
        function plot_Pauli_prime(app, event)
            global lambda q_vec p_vec S_vec_o D_vec_o tau Sprime_vec Dprime_vec
            global W tau_c
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));
            
            S_vec_o(1) = str2num(get(app.edit_S01,'Value'));
            S_vec_o(2) = str2num(get(app.edit_S02,'Value'));
            S_vec_o(3) = str2num(get(app.edit_S03,'Value'));
            D_vec_o(1) = str2num(get(app.edit_D01,'Value'));
            D_vec_o(2) = str2num(get(app.edit_D02,'Value'));
            D_vec_o(3) = str2num(get(app.edit_D03,'Value'));
            
            if get(app.check_pert,'Value') == 1
                Calculate_Trajectories
            elseif get(app.check_nonpert,'Value') == 1
                Calculate_TrajectoriesNonPert
            end
            
            tau_SDprime
            
            set(app.pushbutton_FFT, 'Enable', 'on')
            set(app.pushbutton_FMFT, 'Enable', 'on')
            set(app.pushbutton_wavelet, 'Enable', 'on')            
        end

        % Button pushed function: pushbutton_AA_Delaunay
        function plot_AA_Delaynay(app, event)
            global lambda q_vec p_vec W tau_i tau_f tau_total tau_c
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));
            
            if strcmp(get(app.pushbutton_Trajectories, 'Enable'),'on')
                if get(app.check_pert,'Value') == 1
                    Calculate_Trajectories
                elseif get(app.check_nonpert,'Value') == 1
                    Calculate_TrajectoriesNonPert
                end
            end
            AA_Delaunay            
        end

        % Button pushed function: pushbutton_AA_Delaunayprime
        function plot_AA_Delaunay_prime(app, event)
            global lambda q_vec p_vec W tau tau_i tau_f tau_total tau_c S_vec_o D_vec_o
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));
            
            if get(app.check_pert,'Value') == 1
                Calculate_Trajectories
            elseif get(app.check_nonpert,'Value') == 1
                Calculate_TrajectoriesNonPert
            end
            
            %%%%%%%%%%%%%%%%%%%%%
            qp2SD
            SD2SpDp
            %%%%%%%%%%%%%%%%%%%%%
            qp2uv
            %%%%%%%%%%%%%%%%%%%%%
            SoDo2uRvR
            %%%%%%%%%%%%%%%%%%%%%
            AA_Delaunayprime            
        end

        % Button pushed function: pushbutton_AA_Pauli
        function plot_AA_Pauli(app, event)
            global lambda q_vec p_vec W tau tau_i tau_f tau_total tau_c S_vec_o D_vec_o
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));
            
            if get(app.check_pert,'Value') == 1
                Calculate_Trajectories
            elseif get(app.check_nonpert,'Value') == 1
                Calculate_TrajectoriesNonPert
            end
            AA_Pauli            
        end

        % Button pushed function: pushbutton_AA_Pauliprime
        function plot_AA_Pauliprime(app, event)
            global lambda q_vec p_vec W tau tau_i tau_f tau_total tau_c S_vec_o D_vec_o
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));
            
            if get(app.check_pert,'Value') == 1
                Calculate_Trajectories
            elseif get(app.check_nonpert,'Value') == 1
                Calculate_TrajectoriesNonPert
            end
            
            %%%%%%%%%%%%%%%%%%%%%
            qp2SD
            SD2SpDp
            %%%%%%%%%%%%%%%%%%%%%
            qp2uv
            %%%%%%%%%%%%%%%%%%%%%
            SoDo2uRvR
            %%%%%%%%%%%%%%%%%%%%%
            AA_Pauliprime            
        end

        % Button pushed function: pushbutton_AA_User
        function plot_AAuser(app, event)
            global lambda q_vec p_vec W tau tau_i tau_f tau_total tau_c S_vec_o D_vec_o
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));
            
            if get(app.check_pert,'Value') == 1
                Calculate_Trajectories
            elseif get(app.check_nonpert,'Value') == 1
                Calculate_TrajectoriesNonPert
            end
            
            cd(app.KeplerDir);
            [filename, pathname] = uigetfile( ...
                {'*.m', 'All M-Files (*.m)'; ...
                    '*.*','All Files (*.*)'}, ...
                'Choose Action-Angle file','UserFunctions/UserActionAngle/');
            if isequal([filename,pathname],[0,0])
                return
            else
                [pathstr, name, ext] = fileparts(filename);
                cd(pathname)
                eval(name)
                cd(app.KeplerDir)
            end
            
            figure
            polar(pi/180*Angl, Action,'.')
            title({'Action \itvs \rmangle'})
            figure
            if strcmp(get(app.LargeMS, 'Checked'),'on')
                MarkSize = 6;
            else
                MarkSize = 4;
            end
            plot(Angl, Action,'.','MarkerSize',MarkSize)
            if strcmp(get(app.no_degree, 'Checked'),'off')
                Degree_x
            end
            title({'Action \itvs \rmangle (deg)'})            
        end

        % Button pushed function: pushbutton_FFT
        function exec_FFT(app, event)

            global lambda q_vec p_vec tau Sprime_vec Dprime_vec
            global W tau_c
            OutStep = str2num(get(app.step_ODE, 'Value'));
            freq_max = 2*pi/OutStep;
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));

            if get(app.check_pert,'Value') == 1
                Calculate_Trajectories
            elseif get(app.check_nonpert,'Value') == 1
                Calculate_TrajectoriesNonPert
            end
            
            if get(app.radiobutton_Delaunay,'Value') == 1
                DelaunayforFFT
            end
            
            if get(app.radiobutton_Delaunayprime,'Value') == 1
                DelaunayprimeforFFT
            end
            
            if get(app.radiobutton_Pauli,'Value') == 1
                PauliforFFT
            end
            
            if get(app.radiobutton_Pauliprime,'Value') == 1
                PauliprimeforFFT
            end
            
            if get(app.radiobutton_User,'Value') == 1
                UserforFFT
            end          
        end

        % Button pushed function: pushbutton_FMFT
        function exec_FMFT(app, event)
            global lambda q_vec p_vec tau Sprime_vec Dprime_vec S_vec_o D_vec_o

            cd(app.KeplerDir)
            cd ./FMFT
            delete *.txt
            cd(app.KeplerDir)
            %-------------------------------------------------------------------------
            global W tau_c
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));
            
            if get(app.check_pert,'Value') == 1
                Calculate_Trajectories
            elseif get(app.check_nonpert,'Value') == 1
                Calculate_TrajectoriesNonPert
            end
            %--------------------------------------------------------------------------
            
                OutStep = str2num(get(app.step_ODE, 'Value'));
                
                    data_sep = num2str(2*pi*100000*OutStep);
                    if strcmp(get(app.warningbox, 'Checked'),'on')
                        if isdeployed
                             hhh = helpdlg(sprintf(['Please wait, KEPLER is calculating. \n',...
                                'When this box will automatically shut down, you will find, in the FMFT\n'...
                                'folder, some text file(s) containing the frequencies on KAM tori.']),...
                                'Frequency Analysis');
                        else
                            hhh = helpdlg(sprintf(['Please wait, KEPLER is calculating. \n',...
                                'When this box will automatically shut down, you will find, in the\n'...
                                'MATLAB editor, some text file(s) containing the frequencies on KAM tori.']),...
                                'Frequency Analysis');
                        end
                    end


            min_f = num2str(1000*str2num(get(app.edit_fromfreq,'Value')),'%6.12f');
            max_f = num2str(1000*str2num(get(app.edit_tofreq,'Value')),'%6.12f');
            flag_FMFT = get(app.popupmenu_FMFT_flag,'Value');
            nfreq = get(app.edit_nfreq,'Value');
            d = size(q_vec(:,1),1);
            ndata = num2str(2^(floor(log2(d))));
            
            if get(app.radiobutton_Delaunay,'Value') == 1
                DelaunayforFMFT
                cd([app.KeplerDir, '/FMFT'])
                cmd_Value = ['!',app.PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...
                      data_sep,' ',ndata,' ','<inputL.txt',' ','>FMFT_of_L.txt'];
                eval(cmd_Value)
                delete inputL.txt
                cmd_Value = ['!',app.PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...
                      data_sep,' ',ndata,' ','<inputG.txt',' ','>FMFT_of_G.txt'];
                eval(cmd_Value)
                delete inputG.txt
                cmd_Value = ['!',app.PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...
                      data_sep,' ',ndata,' ','<inputG3.txt',' ','>FMFT_of_G3.txt'];
                eval(cmd_Value)
                delete inputG3.txt
            
                [Freq  Amp Phase] = textread('FMFT_of_L.txt', '%f %f %f', 'headerlines', 4);
                figure
                bar(Freq/1000,(log10(Amp) + ceil(abs(min(log10(Amp))))),0.2)
                title('FMFT for \itL')
                xlabel('Frequency', 'FontSize',10)
            
                [Freq  Amp Phase] = textread('FMFT_of_G.txt', '%f %f %f', 'headerlines', 4);
                figure
                bar(Freq/1000,(log10(Amp) + ceil(abs(min(log10(Amp))))),0.2)
                title('FMFT for \itG')
                xlabel('Frequency', 'FontSize',10)
            
                [Freq  Amp Phase] = textread('FMFT_of_G3.txt', '%f %f %f', 'headerlines', 4);
                figure
                bar(Freq/1000,(log10(Amp) + ceil(abs(min(log10(Amp))))),0.2)
                title('FMFT for \itG_3')
                xlabel('Frequency', 'FontSize',10)
            
                fid = fopen(['FMFT_of_L.txt'], 'a');
                fprintf(fid,'    \n');
                fprintf(fid,'**************************************************************\n');
                fprintf(fid, ['Frequency analysis for L\n']);
                fprintf(fid,'**************************************************************\n');
                fclose(fid);
            
                fid = fopen(['FMFT_of_G.txt'], 'a');
                fprintf(fid,'    \n');
                fprintf(fid,'**************************************************************\n');
                fprintf(fid, ['Frequency analysis for G\n']);
                fprintf(fid,'**************************************************************\n');
                fclose(fid);
            
                fid = fopen(['FMFT_of_G3.txt'], 'a');
                fprintf(fid,'    \n');
                fprintf(fid,'**************************************************************\n');
                fprintf(fid, ['Frequency analysis for G3\n']);
                fprintf(fid,'**************************************************************\n');
                fclose(fid);
            
                if isdeployed
                    % do nothing
                else
                    edit FMFT_of_L.txt FMFT_of_G.txt FMFT_of_G3.txt
                end
                            
                close(hhh)
            end
            
            if get(app.radiobutton_Delaunayprime,'Value') == 1
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                qp2SD
                SD2SpDp
                %%%%%%%%%%%%%%%%%%%%%
                qp2uv
                %%%%%%%%%%%%%%%%%%%%%
                SoDo2uRvR
                %%%%%%%%%%%%%%%%%%%%%
                DelaunayprimeforFMFT_noRes
                %%%%%%%%%%%%%%%%%%%%%
                cd([app.KeplerDir, '/FMFT'])
                cmd_Value = ['!',app.PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...
                      data_sep,' ',ndata,' ','<inputL.txt',' ','>FMFT_of_Lprime.txt'];
                eval(cmd_Value)
                delete inputL.txt
                cmd_Value = ['!',app.PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...
                      data_sep,' ',ndata,' ','<inputG.txt',' ','>FMFT_of_Gprime.txt'];
                eval(cmd_Value)
                delete inputG.txt
                cmd_Value = ['!',app.PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...
                      data_sep,' ',ndata,' ','<inputG3.txt',' ','>FMFT_of_G3prime.txt'];
                eval(cmd_Value)
                delete inputG3.txt
            
                [Freq  Amp Phase] = textread('FMFT_of_Lprime.txt', '%f %f %f', 'headerlines', 4);
                figure
                bar(Freq/1000,(log10(Amp) + ceil(abs(min(log10(Amp))))),0.2)
                title('FMFT for "rotated" \itL')
                xlabel('Frequency', 'FontSize',10)
            
                [Freq  Amp Phase] = textread('FMFT_of_Gprime.txt', '%f %f %f', 'headerlines', 4);
                figure
                bar(Freq/1000,(log10(Amp) + ceil(abs(min(log10(Amp))))),0.2)
                title('FMFT for "rotated" \itG')
                xlabel('Frequency', 'FontSize',10)
            
                [Freq  Amp Phase] = textread('FMFT_of_G3prime.txt', '%f %f %f', 'headerlines', 4);
                figure
                bar(Freq/1000,(log10(Amp) + ceil(abs(min(log10(Amp))))),0.2)
                title('FMFT for "rotated" \itG_3')
                xlabel('Frequency', 'FontSize',10)
            
                fid = fopen(['FMFT_of_Lprime.txt'], 'a');
                fprintf(fid,'    \n');
                fprintf(fid,'**************************************************************\n');
                fprintf(fid, ['Frequency analysis for "rotated" L\n']);
                fprintf(fid,'**************************************************************\n');
                fclose(fid);
            
                fid = fopen(['FMFT_of_Gprime.txt'], 'a');
                fprintf(fid,'    \n');
                fprintf(fid,'**************************************************************\n');
                fprintf(fid, ['Frequency analysis for "rotated" G\n']);
                fprintf(fid,'**************************************************************\n');
                fclose(fid);
            
                fid = fopen(['FMFT_of_G3prime.txt'], 'a');
                fprintf(fid,'    \n');
                fprintf(fid,'**************************************************************\n');
                fprintf(fid, ['Frequency analysis for "rotated" G3\n']);
                fprintf(fid,'**************************************************************\n');
                fclose(fid);
            
                if isdeployed
                    % do nothing
                else
                    edit FMFT_of_Lprime.txt FMFT_of_Gprime.txt FMFT_of_G3prime.txt
                end
            
                close(hhh)
            end
            
            if get(app.radiobutton_Pauli,'Value') == 1
                PauliforFMFT
                cd([app.KeplerDir, '/FMFT'])
                cmd_Value = ['!',app.PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...
                      data_sep,' ',ndata,' ','<inputL.txt',' ','>FMFT_of_LPauli.txt'];
                eval(cmd_Value)
                delete inputL.txt
                cmd_Value = ['!',app.PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...
                      data_sep,' ',ndata,' ','<inputS.txt',' ','>FMFT_of_S.txt'];
                eval(cmd_Value)
                delete inputS.txt
                cmd_Value = ['!',app.PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...
                      data_sep,' ',ndata,' ','<inputD.txt',' ','>FMFT_of_D.txt'];
                eval(cmd_Value)
                delete inputD.txt
            
                [Freq  Amp Phase] = textread('FMFT_of_LPauli.txt', '%f %f %f', 'headerlines', 4);
                figure
                bar(Freq/1000,(log10(Amp) + ceil(abs(min(log10(Amp))))),0.2)
                title('FMFT for \itL_P')
                xlabel('Frequency', 'FontSize',10)
            
                [Freq  Amp Phase] = textread('FMFT_of_S.txt', '%f %f %f', 'headerlines', 4);
                figure
                bar(Freq/1000,(log10(Amp) + ceil(abs(min(log10(Amp))))),0.2)
                title('FMFT for \itS')
                xlabel('Frequency', 'FontSize',10)
            
                [Freq  Amp Phase] = textread('FMFT_of_D.txt', '%f %f %f', 'headerlines', 4);
                figure
                bar(Freq/1000,(log10(Amp) + ceil(abs(min(log10(Amp))))),0.2)
                title('FMFT for \itD')
                xlabel('Frequency', 'FontSize',10)
            
                fid = fopen(['FMFT_of_LPauli.txt'], 'a');
                fprintf(fid,'    \n');
                fprintf(fid,'**************************************************************\n');
                fprintf(fid, ['Frequency analysis for LPauli\n']);
                fprintf(fid,'**************************************************************\n');
                fclose(fid);
            
                fid = fopen(['FMFT_of_S.txt'], 'a');
                fprintf(fid,'    \n');
                fprintf(fid,'**************************************************************\n');
                fprintf(fid, ['Frequency analysis for S\n']);
                fprintf(fid,'**************************************************************\n');
                fclose(fid);
            
                fid = fopen(['FMFT_of_D.txt'], 'a');
                fprintf(fid,'    \n');
                fprintf(fid,'**************************************************************\n');
                fprintf(fid, ['Frequency analysis for D\n']);
                fprintf(fid,'**************************************************************\n');
                fclose(fid);
            
                if isdeployed
                    % do nothing
                else
                    edit FMFT_of_LPauli.txt FMFT_of_S.txt FMFT_of_D.txt
                end
            
                close(hhh)
            end
            
            if get(app.radiobutton_Pauliprime,'Value') == 1
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                qp2SD
                SD2SpDp
                %%%%%%%%%%%%%%%%%%%%%
                qp2uv
                %%%%%%%%%%%%%%%%%%%%%
                SoDo2uRvR
                %%%%%%%%%%%%%%%%%%%%%
                PauliprimeforFMFT_noRes
                %%%%%%%%%%%%%%%%%%%%%
                cd([app.KeplerDir, '/FMFT'])
                cmd_Value = ['!',app.PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...
                      data_sep,' ',ndata,' ','<inputL.txt',' ','>FMFT_of_LPauliprime.txt'];
                eval(cmd_Value)
                delete inputL.txt
                cmd_Value = ['!',app.PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...
                      data_sep,' ',ndata,' ','<inputS.txt',' ','>FMFT_of_Sprime.txt'];
                eval(cmd_Value)
                delete inputS.txt
                cmd_Value = ['!',app.PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...
                      data_sep,' ',ndata,' ','<inputD.txt',' ','>FMFT_of_Dprime.txt'];
                eval(cmd_Value)
                delete inputD.txt
            
                [Freq  Amp Phase] = textread('FMFT_of_LPauliprime.txt', '%f %f %f', 'headerlines', 4);
                figure
                bar(Freq/1000,(log10(Amp) + ceil(abs(min(log10(Amp))))),0.2)
                title('FMFT for "rotated" \itL_P')
                xlabel('Frequency', 'FontSize',10)
            
                [Freq  Amp Phase] = textread('FMFT_of_Sprime.txt', '%f %f %f', 'headerlines', 4);
                figure
                bar(Freq/1000,(log10(Amp) + ceil(abs(min(log10(Amp))))),0.2)
                title('FMFT for "rotated" \itS')
                xlabel('Frequency', 'FontSize',10)
            
                [Freq  Amp Phase] = textread('FMFT_of_Dprime.txt', '%f %f %f', 'headerlines', 4);
                figure
                bar(Freq/1000,(log10(Amp) + ceil(abs(min(log10(Amp))))),0.2)
                title('FMFT for "rotated" \itD')
                xlabel('Frequency', 'FontSize',10)
            
                fid = fopen(['FMFT_of_LPauliprime.txt'], 'a');
                fprintf(fid,'    \n');
                fprintf(fid,'**************************************************************\n');
                fprintf(fid, ['Frequency analysis for "rotated" LPauli\n']);
                fprintf(fid,'**************************************************************\n');
                fclose(fid);
            
                fid = fopen(['FMFT_of_Sprime.txt'], 'a');
                fprintf(fid,'    \n');
                fprintf(fid,'**************************************************************\n');
                fprintf(fid, ['Frequency analysis for "rotated" S\n']);
                fprintf(fid,'**************************************************************\n');
                fclose(fid);
            
                fid = fopen(['FMFT_of_Dprime.txt'], 'a');
                fprintf(fid,'    \n');
                fprintf(fid,'**************************************************************\n');
                fprintf(fid, ['Frequency analysis for "rotated" D\n']);
                fprintf(fid,'**************************************************************\n');
                fclose(fid);
            
                if isdeployed
                    % do nothing
                else
                    edit FMFT_of_LPauliprime.txt FMFT_of_Sprime.txt FMFT_of_Dprime.txt
                end
            
                close(hhh)
            end
            
            
            if get(app.radiobutton_User,'Value') == 1
                UserforFMFT
                cd([app.KeplerDir, '/FMFT'])
                if exist('FMFT/inputUser.txt')
                    cmd_Value = ['!',app.PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...
                          data_sep,' ',ndata,' ','<inputUser.txt',' ','>FMFT_of_User.txt'];
                    eval(cmd_Value)
                    delete inputUser.txt
            
                    [Freq  Amp Phase] = textread('FMFT_of_User.txt', '%f %f %f', 'headerlines', 4);
                    figure
                    bar(Freq/1000,(log10(Amp) + ceil(abs(min(log10(Amp))))),0.2)
                    title('FMFT for User')
                    xlabel('Frequency', 'FontSize',10)
            
                    fid = fopen(['FMFT_of_User.txt'], 'a');
                    fprintf(fid,'    \n');
                    fprintf(fid,'**************************************************************\n');
                    fprintf(fid, ['Frequency analysis for User\n']);
                    fprintf(fid,'**************************************************************\n');
                    fclose(fid);
            
                    if isdeployed
                        % do nothing
                    else
                        edit FMFT_of_User.txt
                    end
                end
                close(hhh)
            end

            cd ..
        end

        % Button pushed function: pushbutton_wavelet
        function exec_Wavelet(app, event)
            global lambda q_vec p_vec tau Sprime_vec Dprime_vec S_vec_o D_vec_o
 %           global min_freq max_freq eta sigma2 fourier_factor
            
            min_freq = str2num(get(app.edit_min_freq,'Value'));
            max_freq = str2num(get(app.edit_max_freq,'Value'));
            eta = 1;
            sigma2 = (2*pi*str2num(get(app.edit_Variance,'Value')))^2;
            fourier_factor = 2/(eta + sqrt(2/sigma2 + eta^2));
            OutStep = str2num(get(app.step_ODE, 'Value'));
            h = helpdlg(sprintf(['Please wait, KEPLER is calculating.']),'Wavelet transform');            
        
            global W tau_c
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = str2num(get(app.edit_from,'Value'));
            tau_f = str2num(get(app.edit_to,'Value'));
            if get(app.check_pert,'Value') == 1
                Calculate_Trajectories
            elseif get(app.check_nonpert,'Value') == 1
                Calculate_TrajectoriesNonPert
            end
            
            if get(app.radiobutton_Delaunay,'Value') == 1
                DelaunayforWT
            end
            
            if get(app.radiobutton_Delaunayprime,'Value') == 1
                DelaunayprimeforWT
            end
            
            if get(app.radiobutton_Pauli,'Value') == 1
                PauliforWT
            end
            
            if get(app.radiobutton_Pauliprime,'Value') == 1
                PauliprimeforWT
            end
            
            if get(app.radiobutton_User,'Value') == 1
                UserforWT
            end
            
            if ~exist('signal')
                close(h)
                return
            end
            
            [wt, Frequency] = wavelet_transf(signal, OutStep,min_freq, max_freq, eta, sigma2, fourier_factor);
            spectrum = (abs(wt)).^2;
            if spectrum < 1000*eps
                spectrum = 1000*eps;
            end
                        
            figure
            colormap(gray)
            contour(tau, Frequency, spectrum)
            xlabel('Revolution number', 'FontSize',12), ylabel('Frequency \times 1000', 'FontSize',12)

            close(h)
        end

        % Value changed function: CheckBoxPlane
        function CheckBoxPlane_change(app, event)
            value = app.CheckBoxPlane.Value;
            if value
                app.CheckBoxPlane.Value = true;
                app.CheckBox3D.Value = false;
                app.panelTrueNorm.Enable = 'off';
            else
                app.CheckBoxPlane.Value = false;
                app.CheckBox3D.Value = true;
                app.panelTrueNorm.Enable = 'on';
            end             
        end

        % Value changed function: CheckBox3D
        function CheckBox3D_change(app, event)
            value = app.CheckBox3D.Value;
            if value
                app.CheckBoxPlane.Value = false;
                app.CheckBox3D.Value = true;
                app.panelTrueNorm.Enable = 'on';
            else
                app.CheckBoxPlane.Value = true;
                app.CheckBox3D.Value = false;
                app.panelTrueNorm.Enable = 'off';
            end                      
        end

        % Value changed function: checkbox_2D_freq
        function checkbox_2D_freq_change(app, event)
            if get(app.checkbox_2D_freq, 'Value') == 1  & strcmp(get(app.edit_2D_step,'Value'),'0');
                set(app.pushbutton_CalcPlot_Poincare,'Icon','Orbita.jpg','IconAlignment','left');
            else
                set(app.pushbutton_CalcPlot_Poincare,'Icon','Tori.jpg','IconAlignment','left');
            end
            
            if get(app.checkbox_2D_freq, 'Value') == 1
                set(app.maxLfreq_2D, 'Enable', 'on')
                set(app.minLfreq_2D, 'Enable', 'on')
                set(app.maxGfreq_2D, 'Enable', 'on')
                set(app.minGfreq_2D, 'Enable', 'on')
            else
                set(app.maxLfreq_2D, 'Enable', 'off')
                set(app.minLfreq_2D, 'Enable', 'off')
                set(app.maxGfreq_2D, 'Enable', 'off')
                set(app.minGfreq_2D, 'Enable', 'off')
            end       
        end

        % Value changed function: edit_2D_step
        function step2D_change(app, event)
            if strcmp(get(app.edit_2D_step,'Value'),'0');
                set(app.edit_2D_final,'Enable','off');
                set(app.leqGLleqLabel,'Text','$=G/L$');
            else
                set(app.edit_2D_final,'Enable','on');
                set(app.leqGLleqLabel,'Text','$\leq G/L  \leq$')
            end
            if strcmp(get(app.edit_2D_step,'Value'),'0') & get(app.checkbox_2D_freq, 'Value') == true;
                set(app.pushbutton_CalcPlot_Poincare,'Icon','Orbita.jpg','IconAlignment','left');
            else
                set(app.pushbutton_CalcPlot_Poincare,'Icon','Tori.jpg','IconAlignment','left');
            end             
        end

        % Value changed function: edit_2D_G3
        function G3_change(app, event)
            clear global Ang_tot Act_tot
            set(app.pushbutton_clear2D, 'Enable', 'off')    
            value = str2num(app.edit_2D_G3.Value);
            if value > str2num(app.edit_2D_init.Value)
                warndlg('The G3 value cannot exceed the min value of G.')
                app.edit_2D_G3.Value = app.edit_2D_init.Value;
                app.NormG3.Value = true;
                return
            end                
        end

        % Button pushed function: pushbutton_clear2D
        function clear_append_2D(app, event)
            clear global Ang_tot Act_tot
            set(app.pushbutton_clear2D, 'Enable', 'off')           
        end

        % Button pushed function: pushbutton_CalcPlot_Poincare
        function exec_CalcPlot_Poincare(app, event)
            clear global q_vec p_vec q_vec_o p_vec_o tau_total tau_i tau_f
            clear global epsilon1 epsilon2 epsilon3 epsilon4 epsilon5 deg1 deg2 deg3 deg4 deg5 Hp1 Hp2 Hp3 Hp4 Hp5
            clear global lambda step_i step_f x_vec y_vec tau tau_c W Ko
            
            global q_vec p_vec q_vec_o p_vec_o tau_total tau_i tau_f
            global epsilon1 epsilon2 epsilon3 epsilon4 epsilon5 deg1 deg2 deg3 deg4 deg5 Hp1 Hp2 Hp3 Hp4 Hp5
            global lambda step_i step_f x_vec y_vec tau tau_c W Ko
            global perfid
            
            cd(app.KeplerDir)
            ODE = get(app.popupmenuODE,'Value');
            if strcmp(ODE,'RungeKutta4') | strcmp(ODE,'RungeKutta5')
                warndlg('The Runge-Kutta methods are only for demonstration purposes and are not implemented in this window.')
                return
            end
            
            Disable_General
            
            degrees = [0.5,0.5,0.5,2,2,2,1,1,1,2,-1,-1,-1,3,-2,-3,-0.5,2,-1,-1];
            
            item = str2num(get(app.popupmenuHp1,'Value'));
            Items = get(app.popupmenuHp1,'Items');            
            Hp1 = Items{item};
            deg1 = degrees(item);

            item = str2num(get(app.popupmenuHp2,'Value'));
            Items = get(app.popupmenuHp2,'Items');            
            Hp2 = Items{item};
            deg2 = degrees(item);

            item = str2num(get(app.popupmenuHp3,'Value'));
            Items = get(app.popupmenuHp3,'Items');            
            Hp3 = Items{item};
            deg3 = degrees(item);

            item = str2num(get(app.popupmenuHp4,'Value'));
            Items = get(app.popupmenuHp4,'Items');            
            Hp4 = Items{item};
            deg4 = degrees(item);

            item = str2num(get(app.popupmenuHp5,'Value'));
            Items = get(app.popupmenuHp5,'Items');            
            Hp5 = Items{item};
            deg5 = degrees(item);
            
            epsilon1 = str2num(get(app.edit_epsilon1,'Value'));
            epsilon2 = str2num(get(app.edit_epsilon2,'Value'));
            epsilon3 = str2num(get(app.edit_epsilon3,'Value'));
            epsilon4 = str2num(get(app.edit_epsilon4,'Value'));
            epsilon5 = str2num(get(app.edit_epsilon5,'Value'));

            sm_ax_o =  1;
            Omega_o =  0;
            omega_o =  str2num(get(app.edit_2D_omega,'Value'));
            f_o =      0;
            G3_o =     str2num(get(app.edit_2D_G3,'Value'));
            G_ofinal =  str2num(get(app.edit_2D_final,'Value'));
            G_oinit = str2num(get(app.edit_2D_init,'Value'));
            steps_2D = str2num(get(app.edit_2D_step,'Value'));
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = 0;
            tau_f = tau_total;
            
            if (G3_o >= G_ofinal) & (get(app.CheckBox3D,'Value') == 1) & (get(app.NormG3,'Value') == 1)
                warndlg(['The normalized ''G3'' value must be smaller ',...
                      'than the normalized ''Min angular momentum'' value'])
                return
            end
            
            E_tot = str2num(get(app.edit_EnergyPoincare,'Value'));
            lambda_tot = 1/sqrt(-2*E_tot);
            lambda = lambda_tot;
            
            if (G3_o >= G_ofinal*lambda) & (get(app.CheckBox3D,'Enable') == 1) & (get(app.TrueG3,'Value') == 1)
                warndlg(['The true ''G3'' value must be smaller ',...
                      'than the true ''Min angular momentum'' value'])
                return
            end
            
            if get(app.checkbox_2D_Poinc,'Value') == 1
                PoincareMapG
            end
            
            if get(app.checkbox_2D_freq,'Value') == 1
                OutStep = str2num(get(app.step_ODE, 'Value'));
                if isempty(OutStep);
                    warndlg(['For a correct Windowed FFT the integration MUST be performed ',...
                       'with a constant Output Step (default value: 0.01). Please, redo ''Calculate and plot''.'])
                    set(app.step_ODE,'Value', '0.01');
                    return
                end
               
                FreqMapLG
                if exist('FMFT/inputL.txt')
                    delete FMFT/inputL.txt
                end
                if exist('FMFT/inputG.txt')
                    delete FMFT/inputG.txt
                end
                if exist('FMFT/inputG3.txt')
                    delete FMFT/inputG3.txt
                end
            end
            
            clear DiffEquation            
        end

        % Value changed function: check_2D_pert
        function check_2D_pert_change(app, event)
            set(app.check_2D_pert,'Value', true)
            set(app.check_2D_nonpert,'Value', false)
            Disable_General
        end

        % Value changed function: check_2D_nonpert
        function check_2D_nonpert_change(app, event)
            set(app.check_2D_nonpert,'Value', true)
            set(app.check_2D_pert,'Value', false)
            Disable_General                        
        end

        % Value changed function: edit_Step_Gprime
        function Step_Gprime_change(app, event)
            if strcmp(get(app.edit_Step_Gprime,'Value'),'0');
                set(app.edit_Final_Gprime,'Enable','off');
                set(app.leqGLleqLabel_2,'Text','$=G/L$');
            else
                set(app.edit_Final_Gprime,'Enable','on');
                set(app.leqGLleqLabel_2,'Text','$\leq G/L  \leq$')
            end
            if strcmp(get(app.edit_Step_Gprime,'Value'),'0') & strcmp(get(app.edit_Step_G3prime,'Value'),'0');
                set(app.pushbutton_calc_LGG3prime,'Icon','Orbita.jpg','IconAlignment','top');
                set(app.pushbutton_calc_LGG3prime,'Text','Calculate trajectory','FontSize',11);
            else
                set(app.pushbutton_calc_LGG3prime,'Icon','PhSpGeo.jpg','IconAlignment','top');
                set(app.pushbutton_calc_LGG3prime,'Text','Calculate...','FontSize',14)
            end
        end

        % Value changed function: edit_Step_G3prime
        function Step_G3prime_change(app, event)
            if strcmp(get(app.edit_Step_G3prime,'Value'),'0');
                set(app.edit_Final_G3prime,'Enable','off');
                set(app.leqG_3LleqLabel,'Text','$=G_3/L$');
            else
                set(app.edit_Final_G3prime,'Enable','on');
                set(app.leqG_3LleqLabel,'Text','$\leq G_3/L  \leq$')
            end
            if strcmp(get(app.edit_Step_Gprime,'Value'),'0') & strcmp(get(app.edit_Step_G3prime,'Value'),'0');
                set(app.pushbutton_calc_LGG3prime,'Icon','Orbita.jpg','IconAlignment','top');
                set(app.pushbutton_calc_LGG3prime,'Text','Calculate trajectory','FontSize',11);
            else
                set(app.pushbutton_calc_LGG3prime,'Icon','PhSpGeo.jpg','IconAlignment','top');
                set(app.pushbutton_calc_LGG3prime,'Text','Calculate...','FontSize',14)
            end            
        end

        % Button pushed function: pushbutton_calc_LGG3prime
        function exec_CalcLGG3(app, event)
            clear global q_vec p_vec q_vec_o p_vec_o tau_total tau_i tau_f
            clear global epsilon1 epsilon2 epsilon3 epsilon4 epsilon5 deg1 deg2 deg3 deg4 deg5 Hp1 Hp2 Hp3 Hp4 Hp5
            clear global lambda step_i step_f x_vec y_vec tau tau_c W Ko
            clear global freq_1 freq_2 freq_3 FMI_1 FMI_2 FMI_3 G_ G_3
            
            global q_vec p_vec q_vec_o p_vec_o tau_total tau_i tau_f
            global epsilon1 epsilon2 epsilon3 epsilon4 epsilon5 deg1 deg2 deg3 deg4 deg5 Hp1 Hp2 Hp3 Hp4 Hp5
            global lambda step_i step_f x_vec y_vec tau tau_c W Ko
            global perfid
            global freq_1 freq_2 freq_3 FMI_1 FMI_2 FMI_3 G_ G_3
            global row_tot col_tot G_Value G3_Value
            
            ODE = get(app.popupmenuODE,'Value');
            if strcmp(ODE,'RungeKutta4') | strcmp(ODE,'RungeKutta5')
                warndlg('The Runge-Kutta methods are only for demonstration purposes and are not implemented in this window.')
                return
            end            
            Disable_General
            set(app.pushbutton_calc_LGG3prime, 'Enable', 'off')            
            cd(app.KeplerDir)            
            row_tot = str2num(get(app.edit_Step_Gprime,'Value')) + 1;
            col_tot = str2num(get(app.edit_Step_G3prime,'Value')) + 1;
            OutStep = str2num(get(app.step_ODE, 'Value'));
            if (((row_tot == 1) & (col_tot == 1)) | OutStep)
                % do nothing
            else
                warndlg(['For a correct Windowed FFT the integration MUST be performed ',...
                      'with a constant Output Step (default value: 0.01). Please, redo ''Calculate...''.'])
                set(app.step_ODE,'Value', '0.01');
                set(app.pushbutton_calc_LGG3prime, 'Enable', 'on')
                set(app.text_block, 'Visible',true)
                set(app.edit_block, 'Visible',true)
                return
            end
          
            degrees = [0.5,0.5,0.5,2,2,2,1,1,1,2,-1,-1,-1,3,-2,-3,-0.5,2,-1,-1];
            
            item = str2num(get(app.popupmenuHp1,'Value'));
            Items = get(app.popupmenuHp1,'Items');            
            Hp1 = Items{item};
            deg1 = degrees(item);

            item = str2num(get(app.popupmenuHp2,'Value'));
            Items = get(app.popupmenuHp2,'Items');            
            Hp2 = Items{item};
            deg2 = degrees(item);

            item = str2num(get(app.popupmenuHp3,'Value'));
            Items = get(app.popupmenuHp3,'Items');            
            Hp3 = Items{item};
            deg3 = degrees(item);

            item = str2num(get(app.popupmenuHp4,'Value'));
            Items = get(app.popupmenuHp4,'Items');            
            Hp4 = Items{item};
            deg4 = degrees(item);

            item = str2num(get(app.popupmenuHp5,'Value'));
            Items = get(app.popupmenuHp5,'Items');            
            Hp5 = Items{item};
            deg5 = degrees(item);
            
            epsilon1 = str2num(get(app.edit_epsilon1,'Value'));
            epsilon2 = str2num(get(app.edit_epsilon2,'Value'));
            epsilon3 = str2num(get(app.edit_epsilon3,'Value'));
            epsilon4 = str2num(get(app.edit_epsilon4,'Value'));
            epsilon5 = str2num(get(app.edit_epsilon5,'Value'));
            
            f_o =   0;
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = 0;
            tau_f = tau_total;
            
            E_tot = str2num(get(app.edit_EnergyLGG3,'Value'));
            lambda_tot = 1/sqrt(-2*E_tot);
            lambda = lambda_tot;
            
            %set(app.count,'Value', '0')
            RotatedFreqMapLGG3
            set(app.pushbutton_calc_LGG3prime, 'Enable', 'on')
            if (row_tot ~= 1) | (col_tot ~= 1)
                set(app.pushbutton_FreqLGG3, 'Enable', 'on')
                set(app.pushbutton_FMILGG3, 'Enable', 'on')
            end
            if (row_tot ~= 1) & (col_tot ~= 1)
                set(app.pushbutton_FreqlevelLGG3, 'Enable', 'on')
            end
            
            delete FMFT/*.txt
            clear DiffEquation            
        end

        % Value changed function: check_pert_LGG3
        function check_pert_LGG3_change(app, event)
            set(app.check_pert_LGG3,'Value', true)
            set(app.check_nonpert_LGG3,'Value', false)
            Disable_General            
        end

        % Value changed function: check_nonpert_LGG3
        function check_nonpert_LGG3_change(app, event)
            set(app.check_nonpert_LGG3,'Value', true)
            set(app.check_pert_LGG3,'Value', false)
            Disable_General              
        end

        % Button pushed function: pushbutton_clearLGG3
        function clear_append_LGG3(app, event)
            clear global Ang_tot Act_tot
            set(app.pushbutton_clearLGG3,'Enable','off')
        end

        % Button pushed function: pushbutton_StartSlave_LGG3
        function exec_StartSlaveLGG3(app, event)
            list = strread(cd, '%s','delimiter',strrep(filesep,'\','\\'));             %#ok<*DSTRRD> 
            dim = size(list);
            Slave = list{dim(1,1), dim(1,2)};
            set(app.pushbutton_StartSlave_LGG3, 'Enable', 'off', 'Text', [Slave,' is running'], ...
                  'FontSize', 16, 'Icon', 'Runner.jpg','IconAlignment','top',...
                  'HorizontalAlignment','center','Position', [8 183 150 103])
            
            clear global q_vec p_vec q_vec_o p_vec_o tau_total tau_i tau_f
            clear global epsilon1 epsilon2 epsilon3 epsilon4 epsilon5 deg1 deg2 deg3 deg4 deg5 Hp1 Hp2 Hp3 Hp4 Hp5
            clear global lambda step_i step_f x_vec y_vec tau tau_c W Ko
            clear global freq_1 freq_2 freq_3 FMI_1 FMI_2 FMI_3 G_ G_3
            
            global q_vec p_vec q_vec_o p_vec_o tau_total tau_i tau_f
            global epsilon1 epsilon2 epsilon3 epsilon4 epsilon5 deg1 deg2 deg3 deg4 deg5 Hp1 Hp2 Hp3 Hp4 Hp5
            global lambda step_i step_f x_vec y_vec tau tau_c W Ko
            global perfid
            global freq_1 freq_2 freq_3 FMI_1 FMI_2 FMI_3 G_ G_3
            global row_tot col_tot G_Value G3_Value
            
            Disable_General
            cd(app.KeplerDir)
            row_tot = str2num(get(app.edit_Step_Gprime,'Value')) + 1;
            col_tot = str2num(get(app.edit_Step_G3prime,'Value')) + 1;
            OutStep = str2num(get(app.step_ODE, 'Value'));
            if (((row_tot == 1) & (col_tot == 1)) | OutStep)
                %do nothing
            else
                warndlg(['For a correct Windowed FFT the integration MUST be performed ',...
                      'with a constant Output Step (default value: 0.01). Please, redo ''Calculate and plot''.'])
                set(app.step_ODE,'Value', '0.01');
                return
            end           
            
            degrees = [0.5,0.5,0.5,2,2,2,1,1,1,2,-1,-1,-1,3,-2,-3,-0.5,2,-1,-1];
            
            item = str2num(get(app.popupmenuHp1,'Value'));
            Items = get(app.popupmenuHp1,'Items');            
            Hp1 = Items{item};
            deg1 = degrees(item);

            item = str2num(get(app.popupmenuHp2,'Value'));
            Items = get(app.popupmenuHp2,'Items');            
            Hp2 = Items{item};
            deg2 = degrees(item);

            item = str2num(get(app.popupmenuHp3,'Value'));
            Items = get(app.popupmenuHp3,'Items');            
            Hp3 = Items{item};
            deg3 = degrees(item);

            item = str2num(get(app.popupmenuHp4,'Value'));
            Items = get(app.popupmenuHp4,'Items');            
            Hp4 = Items{item};
            deg4 = degrees(item);

            item = str2num(get(app.popupmenuHp5,'Value'));
            Items = get(app.popupmenuHp5,'Items');            
            Hp5 = Items{item};
            deg5 = degrees(item);
            
            epsilon1 = str2num(get(app.edit_epsilon1,'Value'));
            epsilon2 = str2num(get(app.edit_epsilon2,'Value'));
            epsilon3 = str2num(get(app.edit_epsilon3,'Value'));
            epsilon4 = str2num(get(app.edit_epsilon4,'Value'));
            epsilon5 = str2num(get(app.edit_epsilon5,'Value'));

            f_o =   0;
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = 0;
            tau_f = tau_total;
            
            ODE = get(app.popupmenuODE,'Value');
            
            E_tot = str2num(get(app.edit_EnergyLGG3,'Value'));
            lambda_tot = 1/sqrt(-2*E_tot);
            lambda = lambda_tot;
            
            if isdeployed
                fid = fopen( 'Python/parameters.txt', 'wt');
                fprintf(fid, ['[', num2str(lambda, 16),', ', num2str(epsilon1),', ', num2str(epsilon2),', ', ...
                                   num2str(epsilon3),', ', num2str(epsilon4),', ', num2str(epsilon5),', ', ...
                                   num2str(deg1),', ', num2str(deg2),', ', num2str(deg3),', ', ...
                                   num2str(deg4),', ', num2str(deg5),', ', ...
                                   '"', Hp1,'"',', ', '"', Hp2,'"',', ', '"', Hp3,'"',', ', ...
                                   '"', Hp4,'"',', ', '"', Hp5,'"',']']);                               
                fclose(fid);
                if get(app.check_pert_LGG3,'Value') == 1
                   !python ./Python/Symbolic.py
%                    eval(['!',app.pythonDir,'/python ',app.PythonDir,'/Symbolic.py'])
                elseif get(app.check_nonpert_LGG3,'Value') == 1
                   !python ./Python/SymbolicNonPert.py
%                    eval(['!',app.pythonDir,'/python ',app.PythonDir,'/SymbolicNonPert.py'])
                end
            else
                if get(app.check_pert_LGG3,'Value') == 1
                    Symbolic
                    Write2file
                    waitfor(exist('DiffEquation'))
                elseif get(app.check_nonpert_LGG3,'Value') == 1
                    SymbolicNonPert
                    Write2fileNonPert
                    waitfor(exist('DiffEquation'))
                end
            end
            
            startmulticoreslave('../multicorefiles')            
        end

        % Button pushed function: pushbutton_FMILGG3
        function plot_FMILGG3(app, event)
            global G_ G_3 FMI_1 FMI_2 FMI_3 row_tot col_tot G_Value G3_Value
            if (row_tot == 1) & (col_tot ~= 1)
                DiffSecG_LGG3
            elseif (col_tot == 1) & (row_tot ~= 1)
                DiffSecG3_LGG3
            else (row_tot ~= 1) & (col_tot ~= 1);
                Diff_LGG3
            end            
        end

        % Button pushed function: pushbutton_FreqLGG3
        function plot_FreqLGG3(app, event)
            global freq_1 freq_2 freq_3 G_ G_3 row_tot col_tot G_Value G3_Value
            if (row_tot == 1) & (col_tot ~= 1)
                FreqSecG_LGG3
            elseif (col_tot == 1) & (row_tot ~= 1)
                FreqSecG3_LGG3
            else (row_tot ~= 1) & (col_tot ~= 1);
                Freq_LGG3
            end
        end

        % Button pushed function: pushbutton_FreqlevelLGG3
        function plot_FreqLevelLGG3(app, event)
            global freq_1 freq_2 freq_3 G_ G_3 row_tot col_tot G_Value G3_Value
            Freq_levelLGG3
        end

        % Value changed function: edit_3D_step_S
        function StepS_change(app, event)
            if strcmp(get(app.edit_3D_step_S,'Value'),'0');
                set(app.edit_3D_final_S,'Enable','off');
                set(app.leq2S_3LleqLabel,'Text','$=2S_3/L$');
            else
                set(app.edit_3D_final_S,'Enable','on');
                set(app.leq2S_3LleqLabel,'Text','$\leq 2S_3/L \leq$');
            end
            if strcmp(get(app.edit_3D_step_S,'Value'),'0') & strcmp(get(app.edit_3D_step_D,'Value'),'0');
                set(app.pushbutton_calc_LSDprime,'Icon','Orbita.jpg','IconAlignment','top');
                set(app.pushbutton_calc_LSDprime,'Text','Calculate trajectory','FontSize',11);
            else
                set(app.pushbutton_calc_LSDprime,'Icon','PhSpGeo.jpg','IconAlignment','top');
                set(app.pushbutton_calc_LSDprime,'Text','Calculate ...','FontSize',14);
            end            
        end

        % Value changed function: edit_3D_step_D
        function StepD_change(app, event)
            if strcmp(get(app.edit_3D_step_D,'Value'),'0');
                set(app.edit_3D_final_D,'Enable','off');
                set(app.leq2D_3LleqLabel,'Text','$=2D_3/L$');
            else
                set(app.edit_3D_final_D,'Enable','on');
                set(app.leq2D_3LleqLabel,'Text','$\leq 2D_3/L \leq$');
            end
            if strcmp(get(app.edit_3D_step_S,'Value'),'0') & strcmp(get(app.edit_3D_step_D,'Value'),'0');
                set(app.pushbutton_calc_LSDprime,'Icon','Orbita.jpg','IconAlignment','top');
                set(app.pushbutton_calc_LSDprime,'Text','Calculate trajectory','FontSize',11);
            else
                set(app.pushbutton_calc_LSDprime,'Icon','PhSpGeo.jpg','IconAlignment','top');
                set(app.pushbutton_calc_LSDprime,'Text','Calculate ...','FontSize',14);
            end             
        end

        % Button pushed function: pushbutton_calc_LSDprime
        function exec_calcLSD(app, event)
            clear global q_vec p_vec q_vec_o p_vec_o tau_total tau_i tau_f
            clear global epsilon1 epsilon2 epsilon3 epsilon4 epsilon5 deg1 deg2 deg3 deg4 deg5 Hp1 Hp2 Hp3 Hp4 Hp5
            clear global lambda step_i step_f x_vec y_vec tau tau_c W Ko
            clear global freq_1 freq_2 freq_3 FMI_1 FMI_2 FMI_3 S3prime D3prime
            
            global q_vec p_vec q_vec_o p_vec_o tau_total tau_i tau_f
            global epsilon1 epsilon2 epsilon3 epsilon4 epsilon5 deg1 deg2 deg3 deg4 deg5 Hp1 Hp2 Hp3 Hp4 Hp5
            global lambda step_i step_f x_vec y_vec tau tau_c W Ko
            global perfid
            global freq_1 freq_2 freq_3 FMI_1 FMI_2 FMI_3 S3prime D3prime
            global row_tot col_tot S3_str D3_str
            
            ODE = get(app.popupmenuODE,'Value');
            if strcmp(ODE,'RungeKutta4') | strcmp(ODE,'RungeKutta5')
                warndlg('The Runge-Kutta methods are only for demonstration purposes and are not implemented in this window.')
                return
            end            
            Disable_General
            set(app.pushbutton_calc_LSDprime, 'Enable', 'off')
            cd(app.KeplerDir)
            row_tot = str2num(get(app.edit_3D_step_S,'Value')) + 1;
            col_tot = str2num(get(app.edit_3D_step_D,'Value')) + 1;
            OutStep = str2num(get(app.step_ODE, 'Value'));
            if (((row_tot == 1) & (col_tot == 1)) | OutStep)
                % do nothing
            else
                warndlg(['For a correct Windowed FFT the integration MUST be performed ',...
                      'with a constant Output Step (default value: 0.01). Please, redo ''Calculate...''.'])
                set(app.step_ODE,'Value', '0.01');
                set(app.pushbutton_calc_LSDprime, 'Enable', 'on')
                set(app.text_block, 'Visible',true)
                set(app.edit_block, 'Visible',true)                
                return
            end
            
            degrees = [0.5,0.5,0.5,2,2,2,1,1,1,2,-1,-1,-1,3,-2,-3,-0.5,2,-1,-1];
            
            item = str2num(get(app.popupmenuHp1,'Value'));
            Items = get(app.popupmenuHp1,'Items');            
            Hp1 = Items{item};
            deg1 = degrees(item);

            item = str2num(get(app.popupmenuHp2,'Value'));
            Items = get(app.popupmenuHp2,'Items');            
            Hp2 = Items{item};
            deg2 = degrees(item);

            item = str2num(get(app.popupmenuHp3,'Value'));
            Items = get(app.popupmenuHp3,'Items');            
            Hp3 = Items{item};
            deg3 = degrees(item);

            item = str2num(get(app.popupmenuHp4,'Value'));
            Items = get(app.popupmenuHp4,'Items');            
            Hp4 = Items{item};
            deg4 = degrees(item);

            item = str2num(get(app.popupmenuHp5,'Value'));
            Items = get(app.popupmenuHp5,'Items');            
            Hp5 = Items{item};
            deg5 = degrees(item);
            
            epsilon1 = str2num(get(app.edit_epsilon1,'Value'));
            epsilon2 = str2num(get(app.edit_epsilon2,'Value'));
            epsilon3 = str2num(get(app.edit_epsilon3,'Value'));
            epsilon4 = str2num(get(app.edit_epsilon4,'Value'));
            epsilon5 = str2num(get(app.edit_epsilon5,'Value'));

            f_o =   0;
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = 0;
            tau_f = tau_total;
            
            E_tot = str2num(get(app.edit_EnergyLSD,'Value'));
            lambda_tot = 1/sqrt(-2*E_tot);
            lambda = lambda_tot;
            
            RotatedFreqMapLSD
            set(app.pushbutton_calc_LSDprime, 'Enable', 'on')
            if (row_tot ~= 1) | (col_tot ~= 1)
                set(app.pushbutton_FreqLSD, 'Enable', 'on')
                set(app.pushbutton_FMILSD, 'Enable', 'on')
            end
            if (row_tot ~= 1) & (col_tot ~= 1)
                set(app.pushbutton_FreqlevelLSD, 'Enable', 'on')
            end
            
            delete FMFT/*.txt
            clear DiffEquation            
        end

        % Value changed function: check_pert_LSD
        function check_pert_LSD_change(app, event)
            set(app.check_pert_LSD,'Value', true)
            set(app.check_nonpert_LSD,'Value', false)
            Disable_General             
        end

        % Value changed function: check_nonpert_LSD
        function check_nonpert_LSD_change(app, event)
            set(app.check_nonpert_LSD,'Value', true)
            set(app.check_pert_LSD,'Value', false)
            Disable_General             
        end

        % Button pushed function: pushbutton_clearLSD
        function clear_append_LSD(app, event)
            clear global Ang_tot Act_tot
            set(app.pushbutton_clearLSD,'Enable','off')
        end

        % Button pushed function: pushbutton_StartSlave_LSD
        function exec_StartSlaveLSD(app, event)
            list = strread(cd, '%s','delimiter',strrep(filesep,'\','\\'));             %#ok<*DSTRRD> 
            dim = size(list);
            Slave = list{dim(1,1), dim(1,2)};
            set(app.pushbutton_StartSlave_LSD, 'Enable', 'off', 'Text', [Slave,' is running'], ...
                  'FontSize', 16, 'Icon', 'Runner.jpg','IconAlignment','top',...
                  'HorizontalAlignment','center','Position', [8 183 150 103])

            clear global q_vec p_vec q_vec_o p_vec_o tau_total tau_i tau_f
            clear global epsilon1 epsilon2 epsilon3 epsilon4 epsilon5 deg1 deg2 deg3 deg4 deg5 Hp1 Hp2 Hp3 Hp4 Hp5
            clear global lambda step_i step_f x_vec y_vec tau tau_c W Ko
            clear global freq_1 freq_2 freq_3 FMI_1 FMI_2 FMI_3 S3prime D3prime
            
            global q_vec p_vec q_vec_o p_vec_o tau_total tau_i tau_f
            global epsilon1 epsilon2 epsilon3 epsilon4 epsilon5 deg1 deg2 deg3 deg4 deg5 Hp1 Hp2 Hp3 Hp4 Hp5
            global lambda step_i step_f x_vec y_vec tau tau_c W Ko
            global perfid KeplerDir
            global freq_1 freq_2 freq_3 FMI_1 FMI_2 FMI_3 S3prime D3prime
            global row_tot col_tot S3_str D3_str
            
            Disable_General
            cd(app.KeplerDir)
            row_tot = str2num(get(app.edit_3D_step_S,'Value')) + 1;
            col_tot = str2num(get(app.edit_3D_step_D,'Value')) + 1;
            OutStep = str2num(get(app.step_ODE, 'Value'));
            if ((row_tot == 1) & (col_tot == 1)) | OutStep
            else
                warndlg(['For a correct Windowed FFT the integration MUST be performed ',...
                  'with a constant Output Step (default value: 0.01). Please, redo ''Calculate and plot''.'])
                set(app.edit_OutputStep,'Value', num2str(0.01));
                return
            end
            
            degrees = [0.5,0.5,0.5,2,2,2,1,1,1,2,-1,-1,-1,3,-2,-3,-0.5,2,-1,-1];
            
            item = str2num(get(app.popupmenuHp1,'Value'));
            Items = get(app.popupmenuHp1,'Items');            
            Hp1 = Items{item};
            deg1 = degrees(item);

            item = str2num(get(app.popupmenuHp2,'Value'));
            Items = get(app.popupmenuHp2,'Items');            
            Hp2 = Items{item};
            deg2 = degrees(item);

            item = str2num(get(app.popupmenuHp3,'Value'));
            Items = get(app.popupmenuHp3,'Items');            
            Hp3 = Items{item};
            deg3 = degrees(item);

            item = str2num(get(app.popupmenuHp4,'Value'));
            Items = get(app.popupmenuHp4,'Items');            
            Hp4 = Items{item};
            deg4 = degrees(item);

            item = str2num(get(app.popupmenuHp5,'Value'));
            Items = get(app.popupmenuHp5,'Items');            
            Hp5 = Items{item};
            deg5 = degrees(item);
            
            epsilon1 = str2num(get(app.edit_epsilon1,'Value'));
            epsilon2 = str2num(get(app.edit_epsilon2,'Value'));
            epsilon3 = str2num(get(app.edit_epsilon3,'Value'));
            epsilon4 = str2num(get(app.edit_epsilon4,'Value'));
            epsilon5 = str2num(get(app.edit_epsilon5,'Value'));
            
            f_o =   0;
            tau_total = str2num(get(app.total_cycles,'Value'));
            tau_i = 0;
            tau_f = tau_total;
            
            ODE = get(app.popupmenuODE,'Value');
            
            E_tot = str2num(get(app.edit_EnergyLSD,'Value'));
            lambda_tot = 1/sqrt(-2*E_tot);
            lambda = lambda_tot;
            
            if isdeployed
                fid = fopen( 'Python/parameters.txt', 'wt');
                fprintf(fid, ['[', num2str(lambda, 16),', ', num2str(epsilon1),', ', num2str(epsilon2),', ', ...
                                   num2str(epsilon3),', ', num2str(epsilon4),', ', num2str(epsilon5),', ', ...
                                   num2str(deg1),', ', num2str(deg2),', ', num2str(deg3),', ', ...
                                   num2str(deg4),', ', num2str(deg5),', ', ...
                                   '"', Hp1,'"',', ', '"', Hp2,'"',', ', '"', Hp3,'"',', ', ...
                                   '"', Hp4,'"',', ', '"', Hp5,'"',']']);                               
                fclose(fid);
                if get(app.check_pert_LSD,'Value') == 1
                   !python ./Python/Symbolic.py
%                    eval(['!',app.pythonDir,'/python ',app.PythonDir,'/Symbolic.py'])
                elseif get(app.check_nonpert_LSD,'Value') == 1
                   !python ./Python/SymbolicNonPert.py
%                    eval(['!',app.pythonDir,'/python ',app.PythonDir,'/SymbolicNonPert.py'])
                end
            else
                if get(app.check_pert_LSD,'Value') == 1
                    Symbolic
                    Write2file
                    waitfor(exist('DiffEquation'))
                elseif get(app.check_nonpert_LSD,'Value') == 1
                    SymbolicNonPert
                    Write2fileNonPert
                    waitfor(exist('DiffEquation'))
                end
            end
            
            startmulticoreslave('../multicorefiles')            
        end

        % Button pushed function: pushbutton_FMILSD
        function plot_FMILSD(app, event)
            global S3prime D3prime FMI_1 FMI_2 FMI_3 row_tot col_tot S3_str D3_str
            if (row_tot == 1) & (col_tot ~= 1)
                DiffSecS_LSD
            elseif (col_tot == 1) & (row_tot ~= 1)
                DiffSecD_LSD
            else (row_tot ~= 1) & (col_tot ~= 1);
                Diff_LSD
            end            
        end

        % Button pushed function: pushbutton_FreqLSD
        function plot_FreqLSD(app, event)
            global freq_1 freq_2 freq_3 S3prime D3prime row_tot col_tot S3_str D3_str
            if (row_tot == 1) & (col_tot ~= 1)
                FreqSecS_LSD
            elseif (col_tot == 1) & (row_tot ~= 1)
                FreqSecD_LSD
            else (row_tot ~= 1) & (col_tot ~= 1);
                Freq_LSD
            end            
        end

        % Button pushed function: pushbutton_FreqlevelLSD
        function plot_FreqLevelLSD(app, event)
            global freq_1 freq_2 freq_3 S3prime D3prime row_tot col_tot S3_str D3_str
            Freq_levelLSD            
        end

        % Close request function: KEPLERUIFigure
        function KEPLER_closing(app, event)
            if isdeployed
                % Do nothing
            else
                if exist('Report.log')
                    delete Report.log
                end
            end

            if ~isnan(findstr(app.KeplerDir, 'Slave'))
                %do nothing
            else
                if strcmp(get(app.Save_exit, 'Checked'),'on')    
                    InitStructure
                    cd(app.KeplerDir);
                    cd ..        
                    save('Setting.mat', '-struct', 'InitStr')
                    cd(app.KeplerDir);
                end
            end            
            delete(app)            
        end

        % Value changed function: edit_Energy
        function All_EnergyValueChanged(app, event)
            value = app.edit_Energy.Value;
            set(app.edit_EnergyPoincare,'Value', value)
            set(app.edit_EnergyLGG3,'Value', value)
            set(app.edit_EnergyLSD,'Value', value)            
        end

        % Menu selected function: impatient
        function impatientMenu(app, event)
            popupmessage('Help.txt','Help for the impatient')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.KEPLERUIFigure = uifigure('Visible', 'off');
            app.KEPLERUIFigure.Name = 'MATLAB App';
            app.KEPLERUIFigure.Icon = 'Kepler.jpg';
            app.KEPLERUIFigure.CloseRequestFcn = createCallbackFcn(app, @KEPLER_closing, true);
            app.File = uimenu(app.KEPLERUIFigure);
            app.File.Text = 'File';
            app.File.Tag = 'File';
            app.Load = uimenu(app.File);
            app.Load.MenuSelectedFcn = createCallbackFcn(app, @load_init, true);
            app.Load.Text = 'Load new initial data';
            app.Load.Tag = 'Load';
            app.Save = uimenu(app.File);
            app.Save.MenuSelectedFcn = createCallbackFcn(app, @save_init, true);
            app.Save.Text = 'Save current initial data';
            app.Save.Tag = 'Save';
            app.closeall = uimenu(app.File);
            app.closeall.MenuSelectedFcn = createCallbackFcn(app, @close_KEPLER, true);
            app.closeall.Text = 'Close KEPLER';
            app.closeall.Tag = 'closeall';
            app.Save_exit = uimenu(app.File);
            app.Save_exit.MenuSelectedFcn = createCallbackFcn(app, @save_exit_menu, true);
            app.Save_exit.Separator = 'on';
            app.Save_exit.Checked = 'on';
            app.Save_exit.Text = 'Save setting on exit';
            app.Save_exit.Tag = 'Save_exit';
            app.Save_now = uimenu(app.File);
            app.Save_now.MenuSelectedFcn = createCallbackFcn(app, @save_now, true);
            app.Save_now.Text = 'Save setting now';
            app.Save_now.Tag = 'Save_now';
            app.Window = uimenu(app.KEPLERUIFigure);
            app.Window.Text = '  Window';
            app.Window.Tag = 'Window';
            app.Waitbars = uimenu(app.Window);
            app.Waitbars.MenuSelectedFcn = createCallbackFcn(app, @Waitbars_show, true);
            app.Waitbars.Checked = 'on';
            app.Waitbars.Text = 'Show waitbar';
            app.Waitbars.Tag = 'waitbars';
            app.SlowWaitbars = uimenu(app.Window);
            app.SlowWaitbars.MenuSelectedFcn = createCallbackFcn(app, @SlowWaitbars_show, true);
            app.SlowWaitbars.Text = 'Show demodulation waitbar';
            app.SlowWaitbars.Tag = 'SlowWaitbars';
            app.warningbox = uimenu(app.Window);
            app.warningbox.MenuSelectedFcn = createCallbackFcn(app, @warnigbox_show, true);
            app.warningbox.Checked = 'on';
            app.warningbox.Text = 'Show warning boxes';
            app.warningbox.Tag = 'warningbox';
            app.singularity = uimenu(app.Window);
            app.singularity.MenuSelectedFcn = createCallbackFcn(app, @singularity_show, true);
            app.singularity.Separator = 'on';
            app.singularity.Checked = 'on';
            app.singularity.Text = 'Watch falling into singularities';
            app.singularity.Tag = 'singularity';
            app.watch = uimenu(app.Window);
            app.watch.MenuSelectedFcn = createCallbackFcn(app, @watch_show, true);
            app.watch.Text = 'Watch output reliability';
            app.watch.Tag = 'watch';
            app.perform = uimenu(app.Window);
            app.perform.MenuSelectedFcn = createCallbackFcn(app, @perform_show, true);
            app.perform.Separator = 'on';
            app.perform.Checked = 'on';
            app.perform.Text = 'Performance registry';
            app.perform.Tag = 'perform';
            app.LGG3_long_FMFT = uimenu(app.Window);
            app.LGG3_long_FMFT.MenuSelectedFcn = createCallbackFcn(app, @check_LGG3_long_FMFT, true);
            app.LGG3_long_FMFT.Separator = 'on';
            app.LGG3_long_FMFT.Text = 'Demodulation for rotated Delaunay variables';
            app.LGG3_long_FMFT.Tag = 'LGG3_long_FMFT';
            app.LSD_long_FMFT = uimenu(app.Window);
            app.LSD_long_FMFT.MenuSelectedFcn = createCallbackFcn(app, @check_LSD_long_FMFT, true);
            app.LSD_long_FMFT.Text = 'Demodulation for rotated Pauli variables';
            app.LSD_long_FMFT.Tag = 'LSD_long_FMFT';
            app.Figure = uimenu(app.KEPLERUIFigure);
            app.Figure.Text = '  Figure';
            app.Figure.Tag = 'Figure';
            app.close_all = uimenu(app.Figure);
            app.close_all.MenuSelectedFcn = createCallbackFcn(app, @close_figures, true);
            app.close_all.Text = 'Close all figures';
            app.SingleFigures = uimenu(app.Figure);
            app.SingleFigures.MenuSelectedFcn = createCallbackFcn(app, @single_figures, true);
            app.SingleFigures.Text = 'Show also single figures';
            app.SingleFigures.Tag = 'SingleFigures';
            app.LargeMS = uimenu(app.Figure);
            app.LargeMS.MenuSelectedFcn = createCallbackFcn(app, @largemarker_show, true);
            app.LargeMS.Separator = 'on';
            app.LargeMS.Text = 'Large marker size';
            app.LargeMS.Tag = 'LargeMS';
            app.no_degree = uimenu(app.Figure);
            app.no_degree.MenuSelectedFcn = createCallbackFcn(app, @nodegree_show, true);
            app.no_degree.Checked = 'on';
            app.no_degree.Text = 'No degree symbol';
            app.no_degree.Tag = 'no_degree';
            app.Percentage_of_RowColumn = uimenu(app.Figure);
            app.Percentage_of_RowColumn.MenuSelectedFcn = createCallbackFcn(app, @percentage_show, true);
            app.Percentage_of_RowColumn.Separator = 'on';
            app.Percentage_of_RowColumn.Checked = 'on';
            app.Percentage_of_RowColumn.Text = 'Percentage of Row/Column';
            app.RowColumn_Completed = uimenu(app.Figure);
            app.RowColumn_Completed.MenuSelectedFcn = createCallbackFcn(app, @completed_show, true);
            app.RowColumn_Completed.Checked = 'on';
            app.RowColumn_Completed.Text = 'Rows/Columns Completed';
            app.Total_bars = uimenu(app.Figure);
            app.Total_bars.MenuSelectedFcn = createCallbackFcn(app, @total_bar_show, true);
            app.Total_bars.Checked = 'on';
            app.Total_bars.Text = 'Total Progress';
            app.Wait_bars = uimenu(app.Figure);
            app.Wait_bars.MenuSelectedFcn = createCallbackFcn(app, @total_waitbar_show, true);
            app.Wait_bars.Checked = 'on';
            app.Wait_bars.Text = 'Total Progress Waitbars';
            app.Normalform = uimenu(app.KEPLERUIFigure);
            app.Normalform.Text = '  Normal form';
            app.Normalform.Tag = 'Normalform';
            app.SQZ = uimenu(app.Normalform);
            app.SQZ.MenuSelectedFcn = createCallbackFcn(app, @SQZ_open, true);
            app.SQZ.Text = 'Stark Quadratic Zeeman';
            app.SQZ.Tag = 'SQZ';
            app.setSQZ = uimenu(app.Normalform);
            app.setSQZ.MenuSelectedFcn = createCallbackFcn(app, @setSQZ_Menu, true);
            app.setSQZ.Text = 'Import data from SQZ window';
            app.Cr3b = uimenu(app.Normalform);
            app.Cr3b.MenuSelectedFcn = createCallbackFcn(app, @Cr3b_open, true);
            app.Cr3b.Separator = 'on';
            app.Cr3b.Text = 'Circular restricted 3-body';
            app.Cr3b.Tag = 'Cr3b';
            app.setCr3b = uimenu(app.Normalform);
            app.setCr3b.MenuSelectedFcn = createCallbackFcn(app, @setCr3b_Menu, true);
            app.setCr3b.Text = 'Import data from Cr3b window';
            app.Satellite = uimenu(app.Normalform);
            app.Satellite.MenuSelectedFcn = createCallbackFcn(app, @Sat_open, true);
            app.Satellite.Separator = 'on';
            app.Satellite.Text = 'Satellite';
            app.Satellite.Tag = 'Sat';
            app.Help_gen = uimenu(app.KEPLERUIFigure);
            app.Help_gen.Text = '  Help';
            app.Help_gen.Tag = 'Help_gen';
            app.impatient = uimenu(app.Help_gen);
            app.impatient.MenuSelectedFcn = createCallbackFcn(app, @impatientMenu, true);
            app.impatient.Text = 'Help for the impatient';
            app.impatient.Tag = 'impatient';
            app.about = uimenu(app.Help_gen);
            app.about.MenuSelectedFcn = createCallbackFcn(app, @About_Menu, true);
            app.about.Text = 'About KEPLER';
            app.about.Tag = 'about';
            app.uipanel_Freq3D_LGG3 = uipanel(app.KEPLERUIFigure);
            app.uipanel_Freq3D_LGG3.Title = ' Frequency analysis and FMI for Rotated and Normalized  Delaunay variables ';
            app.uipanel_Freq3D_LGG3.Visible = 'off';
            app.uipanel_Freq3D_LGG3.Tag = 'uipanel_Freq3D_LGG3';
            app.pushbutton_StartSlave_LGG3 = uibutton(app.uipanel_Freq3D_LGG3, 'push');
            app.pushbutton_StartSlave_LGG3.ButtonPushedFcn = createCallbackFcn(app, @exec_StartSlaveLGG3, true);
            app.pushbutton_StartSlave_LGG3.Visible = 'off';
            app.pushbutton_StartSlave_LGG3.Text = {'   Start'; '   Slave'};
            app.uipanelFreq1 = uipanel(app.uipanel_Freq3D_LGG3);
            app.uipanelFreq1.Tag = 'uipanelFreq1';
            app.editGG3_min_Freq1 = uieditfield(app.uipanelFreq1, 'text');
            app.editGG3_min_Freq1.Tag = 'editGG3_min_Freq1';
            app.editGG3_min_Freq1.Value = '0.01';
            app.text101 = uilabel(app.uipanelFreq1);
            app.text101.Tag = 'text101';
            app.text101.Text = 'Frequency 1';
            app.text102 = uilabel(app.uipanelFreq1);
            app.text102.Tag = 'text102';
            app.text102.Text = {'Compute the first'; 'frequency between'};
            app.editGG3_max_Freq1 = uieditfield(app.uipanelFreq1, 'text');
            app.editGG3_max_Freq1.Tag = 'editGG3_max_Freq1';
            app.editGG3_max_Freq1.Value = '2.0';
            app.text103 = uilabel(app.uipanelFreq1);
            app.text103.Tag = 'text103';
            app.text103.Text = 'and';
            app.text156 = uilabel(app.uipanelFreq1);
            app.text156.Tag = 'text156';
            app.text156.Text = 'Step number for FMI 1';
            app.nstep1LGG3 = uieditfield(app.uipanelFreq1, 'text');
            app.nstep1LGG3.Tag = 'nstep1LGG3';
            app.nstep1LGG3.Value = '6';
            app.edit_LGG3_perprime = uieditfield(app.uipanel_Freq3D_LGG3, 'text');
            app.edit_LGG3_perprime.Tag = 'edit_LGG3_perprime';
            app.edit_LGG3_perprime.Value = '90';
            app.text111 = uilabel(app.uipanel_Freq3D_LGG3);
            app.text111.Tag = 'text111';
            app.text111.Text = 'Argument of pericenter (°)';
            app.pushbutton_calc_LGG3prime = uibutton(app.uipanel_Freq3D_LGG3, 'push');
            app.pushbutton_calc_LGG3prime.ButtonPushedFcn = createCallbackFcn(app, @exec_CalcLGG3, true);
            app.pushbutton_calc_LGG3prime.Tag = 'pushbutton_CalcPlot_LGG3prime';
            app.pushbutton_calc_LGG3prime.Text = 'Calculate ...';
            app.uipanel56 = uipanel(app.uipanel_Freq3D_LGG3);
            app.uipanel56.Title = '... and plot';
            app.uipanel56.Tag = 'uipanel56';
            app.pushbutton_FreqLGG3 = uibutton(app.uipanel56, 'push');
            app.pushbutton_FreqLGG3.ButtonPushedFcn = createCallbackFcn(app, @plot_FreqLGG3, true);
            app.pushbutton_FreqLGG3.Tag = 'pushbutton_FreqLGG3';
            app.pushbutton_FreqLGG3.Enable = 'off';
            app.pushbutton_FreqLGG3.Text = 'Frequencies';
            app.pushbutton_FreqlevelLGG3 = uibutton(app.uipanel56, 'push');
            app.pushbutton_FreqlevelLGG3.ButtonPushedFcn = createCallbackFcn(app, @plot_FreqLevelLGG3, true);
            app.pushbutton_FreqlevelLGG3.Tag = 'pushbutton_FreqlevelLGG3';
            app.pushbutton_FreqlevelLGG3.Enable = 'off';
            app.pushbutton_FreqlevelLGG3.Text = 'Frequencies (level lines)';
            app.pushbutton_FMILGG3 = uibutton(app.uipanel56, 'push');
            app.pushbutton_FMILGG3.ButtonPushedFcn = createCallbackFcn(app, @plot_FMILGG3, true);
            app.pushbutton_FMILGG3.Tag = 'pushbutton_FMILGG3';
            app.pushbutton_FMILGG3.Enable = 'off';
            app.pushbutton_FMILGG3.Text = 'F M I';
            app.uipanel68 = uipanel(app.uipanel_Freq3D_LGG3);
            app.uipanel68.Tag = 'uipanel68';
            app.edit_Init_Gprime = uieditfield(app.uipanel68, 'text');
            app.edit_Init_Gprime.Tag = 'edit_Init_Gprime';
            app.edit_Init_Gprime.Value = '0.80';
            app.edit_Final_Gprime = uieditfield(app.uipanel68, 'text');
            app.edit_Final_Gprime.Tag = 'edit_Final_Gprime';
            app.edit_Final_Gprime.Value = '0.99';
            app.text97 = uilabel(app.uipanel68);
            app.text97.Tag = 'text97';
            app.text97.Text = 'with step number ';
            app.edit_Step_Gprime = uieditfield(app.uipanel68, 'text');
            app.edit_Step_Gprime.ValueChangedFcn = createCallbackFcn(app, @Step_Gprime_change, true);
            app.edit_Step_Gprime.Tag = 'edit_Step_Gprime';
            app.edit_Step_Gprime.Value = '4';
            app.leqGLleqLabel_2 = uilabel(app.uipanel68);
            app.leqGLleqLabel_2.Interpreter = 'latex';
            app.leqGLleqLabel_2.Text = '$\leq G/L  \leq$';
            app.edit_EnergyLGG3 = uieditfield(app.uipanel_Freq3D_LGG3, 'text');
            app.edit_EnergyLGG3.Tag = 'edit_EnergyLGG3';
            app.edit_EnergyLGG3.Editable = 'off';
            app.edit_EnergyLGG3.Value = '-0.5';
            app.text129 = uilabel(app.uipanel_Freq3D_LGG3);
            app.text129.Tag = 'text129';
            app.text129.Text = 'Total Energy';
            app.pushbutton_clearLGG3 = uibutton(app.uipanel_Freq3D_LGG3, 'push');
            app.pushbutton_clearLGG3.ButtonPushedFcn = createCallbackFcn(app, @clear_append_LGG3, true);
            app.pushbutton_clearLGG3.Tag = 'pushbutton_ClearAppend';
            app.pushbutton_clearLGG3.Text = 'Clear Append';
            app.uipanel73 = uipanel(app.uipanel_Freq3D_LGG3);
            app.uipanel73.Title = 'Unimodular transformation';
            app.uipanel73.Tag = 'uipanel73';
            app.Label = uilabel(app.uipanel73);
            app.Label.Interpreter = 'latex';
            app.Label.Text = '$\left[\begin{array}{cc}\varphi_1\\ \varphi_2\\ \varphi_3\end{array}\right]=\left[\begin{array}{cc}\qquad\\ \qquad\\ \qquad\quad\end{array}\right]\left[\begin{array}{cc}l\\ \omega\\ \Omega\end{array}\right]$';
            app.editGG3_uni_31 = uieditfield(app.uipanel73, 'text');
            app.editGG3_uni_31.Tag = 'editGG3_uni_31';
            app.editGG3_uni_31.Value = '0';
            app.editGG3_uni_21 = uieditfield(app.uipanel73, 'text');
            app.editGG3_uni_21.Tag = 'editGG3_uni_21';
            app.editGG3_uni_21.Value = '0';
            app.editGG3_uni_12 = uieditfield(app.uipanel73, 'text');
            app.editGG3_uni_12.Tag = 'editGG3_uni_12';
            app.editGG3_uni_12.Value = '0';
            app.editGG3_uni_32 = uieditfield(app.uipanel73, 'text');
            app.editGG3_uni_32.Tag = 'editGG3_uni_32';
            app.editGG3_uni_32.Value = '0';
            app.editGG3_uni_23 = uieditfield(app.uipanel73, 'text');
            app.editGG3_uni_23.Tag = 'editGG3_uni_23';
            app.editGG3_uni_23.Value = '0';
            app.editGG3_uni_11 = uieditfield(app.uipanel73, 'text');
            app.editGG3_uni_11.Tag = 'editGG3_uni_11';
            app.editGG3_uni_11.Value = '1';
            app.editGG3_uni_33 = uieditfield(app.uipanel73, 'text');
            app.editGG3_uni_33.Tag = 'editGG3_uni_33';
            app.editGG3_uni_33.Value = '1';
            app.editGG3_uni_22 = uieditfield(app.uipanel73, 'text');
            app.editGG3_uni_22.Tag = 'editGG3_uni_22';
            app.editGG3_uni_22.Value = '1';
            app.editGG3_uni_13 = uieditfield(app.uipanel73, 'text');
            app.editGG3_uni_13.Tag = 'editGG3_uni_13';
            app.editGG3_uni_13.Value = '0';
            app.uipanel_Pert_NonPert_LGG3 = uibuttongroup(app.uipanel_Freq3D_LGG3);
            app.uipanel_Pert_NonPert_LGG3.Tag = 'uipanel_Pert_NonPert';
            app.check_pert_LGG3 = uicheckbox(app.uipanel_Pert_NonPert_LGG3);
            app.check_pert_LGG3.ValueChangedFcn = createCallbackFcn(app, @check_pert_LGG3_change, true);
            app.check_pert_LGG3.Text = {'   Perturbative method'; 'with Moser regularization'};
            app.check_pert_LGG3.Value = true;
            app.check_nonpert_LGG3 = uicheckbox(app.uipanel_Pert_NonPert_LGG3);
            app.check_nonpert_LGG3.ValueChangedFcn = createCallbackFcn(app, @check_nonpert_LGG3_change, true);
            app.check_nonpert_LGG3.Text = {'Non perturbative method'; '  with        regularization'};
            app.ImageLGG3 = uiimage(app.uipanel_Pert_NonPert_LGG3);
            app.ImageLGG3.ImageSource = fullfile(pathToMLAPP, 'UserSaved', 'KS.jpg');
            app.uipanelFreq1_2 = uipanel(app.uipanel_Freq3D_LGG3);
            app.uipanelFreq1_2.Tag = 'uipanelFreq1';
            app.editGG3_min_Freq2 = uieditfield(app.uipanelFreq1_2, 'text');
            app.editGG3_min_Freq2.Tag = 'editGG3_min_Freq1';
            app.editGG3_min_Freq2.Value = '-0.7';
            app.text101_2 = uilabel(app.uipanelFreq1_2);
            app.text101_2.Tag = 'text101';
            app.text101_2.Text = 'Frequency 2';
            app.text102_2 = uilabel(app.uipanelFreq1_2);
            app.text102_2.Tag = 'text102';
            app.text102_2.Text = {'Compute the first'; 'frequency between'};
            app.editGG3_max_Freq2 = uieditfield(app.uipanelFreq1_2, 'text');
            app.editGG3_max_Freq2.Tag = 'editGG3_max_Freq1';
            app.editGG3_max_Freq2.Value = '0.7';
            app.text103_2 = uilabel(app.uipanelFreq1_2);
            app.text103_2.Tag = 'text103';
            app.text103_2.Text = 'and';
            app.text156_2 = uilabel(app.uipanelFreq1_2);
            app.text156_2.Tag = 'text156';
            app.text156_2.Text = 'Step number for FMI 2';
            app.nstep2LGG3 = uieditfield(app.uipanelFreq1_2, 'text');
            app.nstep2LGG3.Tag = 'nstep1LGG3';
            app.nstep2LGG3.Value = '0';
            app.uipanelFreq1_3 = uipanel(app.uipanel_Freq3D_LGG3);
            app.uipanelFreq1_3.Tag = 'uipanelFreq1';
            app.editGG3_min_Freq3 = uieditfield(app.uipanelFreq1_3, 'text');
            app.editGG3_min_Freq3.Tag = 'editGG3_min_Freq1';
            app.editGG3_min_Freq3.Value = '-0.7';
            app.text101_3 = uilabel(app.uipanelFreq1_3);
            app.text101_3.Tag = 'text101';
            app.text101_3.Text = 'Frequency 3';
            app.text102_3 = uilabel(app.uipanelFreq1_3);
            app.text102_3.Tag = 'text102';
            app.text102_3.Text = {'Compute the first'; 'frequency between'};
            app.editGG3_max_Freq3 = uieditfield(app.uipanelFreq1_3, 'text');
            app.editGG3_max_Freq3.Tag = 'editGG3_max_Freq1';
            app.editGG3_max_Freq3.Value = '0.7';
            app.text103_3 = uilabel(app.uipanelFreq1_3);
            app.text103_3.Tag = 'text103';
            app.text103_3.Text = 'and';
            app.text156_3 = uilabel(app.uipanelFreq1_3);
            app.text156_3.Tag = 'text156';
            app.text156_3.Text = 'Step number for FMI 3';
            app.nstep3LGG3 = uieditfield(app.uipanelFreq1_3, 'text');
            app.nstep3LGG3.Tag = 'nstep1LGG3';
            app.nstep3LGG3.Value = '0';
            app.uipanel68_2 = uipanel(app.uipanel_Freq3D_LGG3);
            app.uipanel68_2.Tag = 'uipanel68';
            app.edit_Init_G3prime = uieditfield(app.uipanel68_2, 'text');
            app.edit_Init_G3prime.Tag = 'edit_Init_Gprime';
            app.edit_Init_G3prime.Value = '0.60';
            app.edit_Final_G3prime = uieditfield(app.uipanel68_2, 'text');
            app.edit_Final_G3prime.Tag = 'edit_Final_Gprime';
            app.edit_Final_G3prime.Value = '0.70';
            app.text97_2 = uilabel(app.uipanel68_2);
            app.text97_2.Tag = 'text97';
            app.text97_2.Text = 'with step number ';
            app.edit_Step_G3prime = uieditfield(app.uipanel68_2, 'text');
            app.edit_Step_G3prime.ValueChangedFcn = createCallbackFcn(app, @Step_G3prime_change, true);
            app.edit_Step_G3prime.Tag = 'edit_Step_Gprime';
            app.edit_Step_G3prime.Value = '4';
            app.leqG_3LleqLabel = uilabel(app.uipanel68_2);
            app.leqG_3LleqLabel.Interpreter = 'latex';
            app.leqG_3LleqLabel.Text = '$\leq G_3/L  \leq$';
            app.uipanel_PoincFreq_2D = uipanel(app.KEPLERUIFigure);
            app.uipanel_PoincFreq_2D.Title = ' Poincaré section and frequency analysis (plane system or with axial symmetry) ';
            app.uipanel_PoincFreq_2D.Visible = 'off';
            app.uipanel_PoincFreq_2D.Tag = 'uipanel_PoincFreq_2D';
            app.uipanel65 = uipanel(app.uipanel_PoincFreq_2D);
            app.uipanel65.Tag = 'uipanel65';
            app.checkbox_2D_Poinc = uicheckbox(app.uipanel65);
            app.checkbox_2D_Poinc.Tag = 'checkbox_2D_Poinc';
            app.checkbox_2D_Poinc.Text = 'Poincaré map';
            app.checkbox_2D_Poinc.Value = true;
            app.checkbox_append = uicheckbox(app.uipanel65);
            app.checkbox_append.Tag = 'checkbox_append';
            app.checkbox_append.Text = 'Append';
            app.pushbutton_clear2D = uibutton(app.uipanel65, 'push');
            app.pushbutton_clear2D.ButtonPushedFcn = createCallbackFcn(app, @clear_append_2D, true);
            app.pushbutton_clear2D.Tag = 'pushbutton_clear';
            app.pushbutton_clear2D.Text = 'Clear append';
            app.checkbox_2D_freq = uicheckbox(app.uipanel65);
            app.checkbox_2D_freq.ValueChangedFcn = createCallbackFcn(app, @checkbox_2D_freq_change, true);
            app.checkbox_2D_freq.Tag = 'checkbox_2D_freq';
            app.checkbox_2D_freq.Text = 'Frequency map, or single orbit if step number = 0';
            app.text68 = uilabel(app.uipanel_PoincFreq_2D);
            app.text68.Tag = 'text68';
            app.text68.Text = 'with step number =';
            app.edit_2D_init = uieditfield(app.uipanel_PoincFreq_2D, 'text');
            app.edit_2D_init.Tag = 'edit_2D_final';
            app.edit_2D_init.Value = '0.50';
            app.edit_2D_final = uieditfield(app.uipanel_PoincFreq_2D, 'text');
            app.edit_2D_final.Tag = 'edit_2D_init';
            app.edit_2D_final.Value = '0.90';
            app.edit_2D_step = uieditfield(app.uipanel_PoincFreq_2D, 'text');
            app.edit_2D_step.ValueChangedFcn = createCallbackFcn(app, @step2D_change, true);
            app.edit_2D_step.Tag = 'edit_2D_step';
            app.edit_2D_step.Value = '4';
            app.edit_2D_omega = uieditfield(app.uipanel_PoincFreq_2D, 'text');
            app.edit_2D_omega.Tag = 'edit_2D_omega';
            app.edit_2D_omega.Value = '0';
            app.pushbutton_CalcPlot_Poincare = uibutton(app.uipanel_PoincFreq_2D, 'push');
            app.pushbutton_CalcPlot_Poincare.ButtonPushedFcn = createCallbackFcn(app, @exec_CalcPlot_Poincare, true);
            app.pushbutton_CalcPlot_Poincare.Tag = 'pushbutton_CalcPlot_Poincare';
            app.pushbutton_CalcPlot_Poincare.Text = {'Calculate'; 'and plot'};
            app.text70 = uilabel(app.uipanel_PoincFreq_2D);
            app.text70.Tag = 'text70';
            app.text70.Text = 'Argument of pericentre (°)';
            app.uipanel40 = uipanel(app.uipanel_PoincFreq_2D);
            app.uipanel40.Tag = 'uipanel40';
            app.text77 = uilabel(app.uipanel40);
            app.text77.Tag = 'text77';
            app.text77.Text = 'L-frequency';
            app.text78 = uilabel(app.uipanel40);
            app.text78.Tag = 'text78';
            app.text78.Text = 'Compute the first frequency between';
            app.maxLfreq_2D = uieditfield(app.uipanel40, 'text');
            app.maxLfreq_2D.Tag = 'maxLfreq_2D';
            app.maxLfreq_2D.Enable = 'off';
            app.maxLfreq_2D.Value = '2.0';
            app.minLfreq_2D = uieditfield(app.uipanel40, 'text');
            app.minLfreq_2D.Tag = 'minLfreq_2D';
            app.minLfreq_2D.Enable = 'off';
            app.minLfreq_2D.Value = '0.01';
            app.text79 = uilabel(app.uipanel40);
            app.text79.Tag = 'text79';
            app.text79.Text = 'and';
            app.uipanel46 = uibuttongroup(app.uipanel_PoincFreq_2D);
            app.uipanel46.Tag = 'uipanel46';
            app.CheckBoxPlane = uicheckbox(app.uipanel46);
            app.CheckBoxPlane.ValueChangedFcn = createCallbackFcn(app, @CheckBoxPlane_change, true);
            app.CheckBoxPlane.Text = '2D plane system';
            app.CheckBox3D = uicheckbox(app.uipanel46);
            app.CheckBox3D.ValueChangedFcn = createCallbackFcn(app, @CheckBox3D_change, true);
            app.CheckBox3D.Text = '3D system with axial symmetry';
            app.CheckBox3D.Value = true;
            app.edit_EnergyPoincare = uieditfield(app.uipanel_PoincFreq_2D, 'text');
            app.edit_EnergyPoincare.Tag = 'edit_EnergyPoincare';
            app.edit_EnergyPoincare.Editable = 'off';
            app.edit_EnergyPoincare.Value = '-0.5';
            app.text128 = uilabel(app.uipanel_PoincFreq_2D);
            app.text128.Tag = 'text128';
            app.text128.Text = 'Total Energy';
            app.panelTrueNorm = uibuttongroup(app.uipanel_PoincFreq_2D);
            app.panelTrueNorm.Tag = 'panelTrueNorm';
            app.TrueG3 = uiradiobutton(app.panelTrueNorm);
            app.TrueG3.Tag = 'TrueG3';
            app.TrueG3.Text = 'True';
            app.NormG3 = uiradiobutton(app.panelTrueNorm);
            app.NormG3.Tag = 'NormG3';
            app.NormG3.Text = 'Normalized';
            app.NormG3.Value = true;
            app.edit_2D_G3 = uieditfield(app.panelTrueNorm, 'text');
            app.edit_2D_G3.ValueChangedFcn = createCallbackFcn(app, @G3_change, true);
            app.edit_2D_G3.Tag = 'edit_2D_G3';
            app.edit_2D_G3.Value = '0.2';
            app.G_3Label = uilabel(app.panelTrueNorm);
            app.G_3Label.Interpreter = 'latex';
            app.G_3Label.Text = '$G_3=$';
            app.uipanel40_2 = uipanel(app.uipanel_PoincFreq_2D);
            app.uipanel40_2.Tag = 'uipanel40';
            app.text77_2 = uilabel(app.uipanel40_2);
            app.text77_2.Tag = 'text77';
            app.text77_2.Text = 'G-frequency';
            app.text78_2 = uilabel(app.uipanel40_2);
            app.text78_2.Tag = 'text78';
            app.text78_2.Text = 'Compute the first frequency between';
            app.maxGfreq_2D = uieditfield(app.uipanel40_2, 'text');
            app.maxGfreq_2D.Tag = 'maxLfreq_2D';
            app.maxGfreq_2D.Enable = 'off';
            app.maxGfreq_2D.Value = '0.6';
            app.minGfreq_2D = uieditfield(app.uipanel40_2, 'text');
            app.minGfreq_2D.Tag = 'minLfreq_2D';
            app.minGfreq_2D.Enable = 'off';
            app.minGfreq_2D.Value = '-0.6';
            app.text79_2 = uilabel(app.uipanel40_2);
            app.text79_2.Tag = 'text79';
            app.text79_2.Text = 'and';
            app.leqGLleqLabel = uilabel(app.uipanel_PoincFreq_2D);
            app.leqGLleqLabel.Interpreter = 'latex';
            app.leqGLleqLabel.Text = '$\leq G/L  \leq$';
            app.uipanel_Pert_NonPert_2D = uibuttongroup(app.uipanel_PoincFreq_2D);
            app.uipanel_Pert_NonPert_2D.Tag = 'uipanel_Pert_NonPert';
            app.check_2D_pert = uicheckbox(app.uipanel_Pert_NonPert_2D);
            app.check_2D_pert.ValueChangedFcn = createCallbackFcn(app, @check_2D_pert_change, true);
            app.check_2D_pert.Text = {'    Perturbative method'; 'with Moser regularization'};
            app.check_2D_pert.Value = true;
            app.check_2D_nonpert = uicheckbox(app.uipanel_Pert_NonPert_2D);
            app.check_2D_nonpert.ValueChangedFcn = createCallbackFcn(app, @check_2D_nonpert_change, true);
            app.check_2D_nonpert.Text = {'Non perturbative method'; '  with        regularization'};
            app.Image2D = uiimage(app.uipanel_Pert_NonPert_2D);
            app.Image2D.ImageSource = fullfile(pathToMLAPP, 'UserSaved', 'KS.jpg');
            app.uipanel_Freq3D_LSD = uipanel(app.KEPLERUIFigure);
            app.uipanel_Freq3D_LSD.Title = ' Frequency analysis and FMI for Rotated and Normalized Pauli variables ';
            app.uipanel_Freq3D_LSD.Visible = 'off';
            app.uipanel_Freq3D_LSD.Tag = 'uipanel_Freq3D_LSD';
            app.pushbutton_StartSlave_LSD = uibutton(app.uipanel_Freq3D_LSD, 'push');
            app.pushbutton_StartSlave_LSD.ButtonPushedFcn = createCallbackFcn(app, @exec_StartSlaveLSD, true);
            app.pushbutton_StartSlave_LSD.Tag = 'pushbutton_StartSlave_LGG3';
            app.pushbutton_StartSlave_LSD.Visible = 'off';
            app.pushbutton_StartSlave_LSD.Text = {'   Start'; '   Slave'};
            app.pushbutton_calc_LSDprime = uibutton(app.uipanel_Freq3D_LSD, 'push');
            app.pushbutton_calc_LSDprime.ButtonPushedFcn = createCallbackFcn(app, @exec_calcLSD, true);
            app.pushbutton_calc_LSDprime.Tag = 'pushbutton_CalcPlot_LGG3prime';
            app.pushbutton_calc_LSDprime.Text = 'Calculate ...';
            app.uipanelFreq1_4 = uipanel(app.uipanel_Freq3D_LSD);
            app.uipanelFreq1_4.Tag = 'uipanelFreq1';
            app.editLSD_min_Freq1 = uieditfield(app.uipanelFreq1_4, 'text');
            app.editLSD_min_Freq1.Tag = 'editGG3_min_Freq1';
            app.editLSD_min_Freq1.Value = '0.01';
            app.text101_4 = uilabel(app.uipanelFreq1_4);
            app.text101_4.Tag = 'text101';
            app.text101_4.Text = 'Frequency 1';
            app.text102_4 = uilabel(app.uipanelFreq1_4);
            app.text102_4.Tag = 'text102';
            app.text102_4.Text = {'Compute the first'; 'frequency between'};
            app.editLSD_max_Freq1 = uieditfield(app.uipanelFreq1_4, 'text');
            app.editLSD_max_Freq1.Tag = 'editGG3_max_Freq1';
            app.editLSD_max_Freq1.Value = '2.0';
            app.text103_4 = uilabel(app.uipanelFreq1_4);
            app.text103_4.Tag = 'text103';
            app.text103_4.Text = 'and';
            app.text156_4 = uilabel(app.uipanelFreq1_4);
            app.text156_4.Tag = 'text156';
            app.text156_4.Text = 'Step number for FMI 1';
            app.nstep1LSD = uieditfield(app.uipanelFreq1_4, 'text');
            app.nstep1LSD.Tag = 'nstep1LGG3';
            app.nstep1LSD.Value = '6';
            app.edit_phiR = uieditfield(app.uipanel_Freq3D_LSD, 'text');
            app.edit_phiR.Tag = 'edit_LGG3_perprime';
            app.edit_phiR.Value = '90';
            app.uipanel56_2 = uipanel(app.uipanel_Freq3D_LSD);
            app.uipanel56_2.Title = '... and plot';
            app.uipanel56_2.Tag = 'uipanel56';
            app.pushbutton_FreqLSD = uibutton(app.uipanel56_2, 'push');
            app.pushbutton_FreqLSD.ButtonPushedFcn = createCallbackFcn(app, @plot_FreqLSD, true);
            app.pushbutton_FreqLSD.Tag = 'pushbutton_FreqLGG3';
            app.pushbutton_FreqLSD.Enable = 'off';
            app.pushbutton_FreqLSD.Text = 'Frequencies';
            app.pushbutton_FMILSD = uibutton(app.uipanel56_2, 'push');
            app.pushbutton_FMILSD.ButtonPushedFcn = createCallbackFcn(app, @plot_FMILSD, true);
            app.pushbutton_FMILSD.Tag = 'pushbutton_FMILGG3';
            app.pushbutton_FMILSD.Enable = 'off';
            app.pushbutton_FMILSD.Text = 'F M I';
            app.pushbutton_FreqlevelLSD = uibutton(app.uipanel56_2, 'push');
            app.pushbutton_FreqlevelLSD.ButtonPushedFcn = createCallbackFcn(app, @plot_FreqLevelLSD, true);
            app.pushbutton_FreqlevelLSD.Tag = 'pushbutton_FreqlevelLGG3';
            app.pushbutton_FreqlevelLSD.Enable = 'off';
            app.pushbutton_FreqlevelLSD.Text = 'Frequencies (level lines)';
            app.uipanel68_3 = uipanel(app.uipanel_Freq3D_LSD);
            app.uipanel68_3.Tag = 'uipanel68';
            app.edit_3D_init_S = uieditfield(app.uipanel68_3, 'text');
            app.edit_3D_init_S.Tag = 'edit_Init_Gprime';
            app.edit_3D_init_S.Value = '0.70';
            app.edit_3D_final_S = uieditfield(app.uipanel68_3, 'text');
            app.edit_3D_final_S.Tag = 'edit_Final_Gprime';
            app.edit_3D_final_S.Value = '0.90';
            app.text97_3 = uilabel(app.uipanel68_3);
            app.text97_3.Tag = 'text97';
            app.text97_3.Text = 'with step number ';
            app.edit_3D_step_S = uieditfield(app.uipanel68_3, 'text');
            app.edit_3D_step_S.ValueChangedFcn = createCallbackFcn(app, @StepS_change, true);
            app.edit_3D_step_S.Tag = 'edit_Step_Gprime';
            app.edit_3D_step_S.Value = '4';
            app.leq2S_3LleqLabel = uilabel(app.uipanel68_3);
            app.leq2S_3LleqLabel.Interpreter = 'latex';
            app.leq2S_3LleqLabel.Text = '$\leq 2S_3/L \leq$';
            app.edit_EnergyLSD = uieditfield(app.uipanel_Freq3D_LSD, 'text');
            app.edit_EnergyLSD.Tag = 'edit_EnergyLGG3';
            app.edit_EnergyLSD.Editable = 'off';
            app.edit_EnergyLSD.Value = '-0.5';
            app.text129_2 = uilabel(app.uipanel_Freq3D_LSD);
            app.text129_2.Tag = 'text129';
            app.text129_2.Text = 'Total Energy';
            app.pushbutton_clearLSD = uibutton(app.uipanel_Freq3D_LSD, 'push');
            app.pushbutton_clearLSD.ButtonPushedFcn = createCallbackFcn(app, @clear_append_LSD, true);
            app.pushbutton_clearLSD.Tag = 'pushbutton_ClearAppend';
            app.pushbutton_clearLSD.Text = 'Clear Append';
            app.uipanel73_2 = uipanel(app.uipanel_Freq3D_LSD);
            app.uipanel73_2.Title = 'Unimodular transformation';
            app.uipanel73_2.Tag = 'uipanel73';
            app.Label_2 = uilabel(app.uipanel73_2);
            app.Label_2.Interpreter = 'latex';
            app.Label_2.Text = '$\left[\begin{array}{cc}\varphi_1\\ \varphi_2\\ \varphi_3\end{array}\right]=\left[\begin{array}{cc}\qquad\\ \qquad\\ \qquad\quad\end{array}\right]\left[\begin{array}{cc}l_P\\ \varphi_S\\ \varphi_D\end{array}\right]$';
            app.editSD_uni_31 = uieditfield(app.uipanel73_2, 'text');
            app.editSD_uni_31.Tag = 'editGG3_uni_31';
            app.editSD_uni_31.Value = '0';
            app.editSD_uni_21 = uieditfield(app.uipanel73_2, 'text');
            app.editSD_uni_21.Tag = 'editGG3_uni_21';
            app.editSD_uni_21.Value = '0';
            app.editSD_uni_12 = uieditfield(app.uipanel73_2, 'text');
            app.editSD_uni_12.Tag = 'editGG3_uni_12';
            app.editSD_uni_12.Value = '0';
            app.editSD_uni_32 = uieditfield(app.uipanel73_2, 'text');
            app.editSD_uni_32.Tag = 'editGG3_uni_32';
            app.editSD_uni_32.Value = '0';
            app.editSD_uni_23 = uieditfield(app.uipanel73_2, 'text');
            app.editSD_uni_23.Tag = 'editGG3_uni_23';
            app.editSD_uni_23.Value = '0';
            app.editSD_uni_11 = uieditfield(app.uipanel73_2, 'text');
            app.editSD_uni_11.Tag = 'editGG3_uni_11';
            app.editSD_uni_11.Value = '1';
            app.editSD_uni_33 = uieditfield(app.uipanel73_2, 'text');
            app.editSD_uni_33.Tag = 'editGG3_uni_33';
            app.editSD_uni_33.Value = '1';
            app.editSD_uni_22 = uieditfield(app.uipanel73_2, 'text');
            app.editSD_uni_22.Tag = 'editGG3_uni_22';
            app.editSD_uni_22.Value = '1';
            app.editSD_uni_13 = uieditfield(app.uipanel73_2, 'text');
            app.editSD_uni_13.Tag = 'editGG3_uni_13';
            app.editSD_uni_13.Value = '0';
            app.uipanel_Pert_NonPert_LSD = uibuttongroup(app.uipanel_Freq3D_LSD);
            app.uipanel_Pert_NonPert_LSD.Tag = 'uipanel_Pert_NonPert';
            app.check_pert_LSD = uicheckbox(app.uipanel_Pert_NonPert_LSD);
            app.check_pert_LSD.ValueChangedFcn = createCallbackFcn(app, @check_pert_LSD_change, true);
            app.check_pert_LSD.Text = {'  Perturbative method'; 'with Moser regularization'};
            app.check_pert_LSD.Value = true;
            app.check_nonpert_LSD = uicheckbox(app.uipanel_Pert_NonPert_LSD);
            app.check_nonpert_LSD.ValueChangedFcn = createCallbackFcn(app, @check_nonpert_LSD_change, true);
            app.check_nonpert_LSD.Text = {'Non perturbative method'; '  with        regularization'};
            app.ImageLSD = uiimage(app.uipanel_Pert_NonPert_LSD);
            app.ImageLSD.ImageSource = fullfile(pathToMLAPP, 'UserSaved', 'KS.jpg');
            app.uipanelFreq1_5 = uipanel(app.uipanel_Freq3D_LSD);
            app.uipanelFreq1_5.Tag = 'uipanelFreq1';
            app.editLSD_min_Freq2 = uieditfield(app.uipanelFreq1_5, 'text');
            app.editLSD_min_Freq2.Tag = 'editGG3_min_Freq1';
            app.editLSD_min_Freq2.Value = '-0.7';
            app.text101_5 = uilabel(app.uipanelFreq1_5);
            app.text101_5.Tag = 'text101';
            app.text101_5.Text = 'Frequency 2';
            app.text102_5 = uilabel(app.uipanelFreq1_5);
            app.text102_5.Tag = 'text102';
            app.text102_5.Text = {'Compute the first'; 'frequency between'};
            app.editLSD_max_Freq2 = uieditfield(app.uipanelFreq1_5, 'text');
            app.editLSD_max_Freq2.Tag = 'editGG3_max_Freq1';
            app.editLSD_max_Freq2.Value = '0.7';
            app.text103_5 = uilabel(app.uipanelFreq1_5);
            app.text103_5.Tag = 'text103';
            app.text103_5.Text = 'and';
            app.text156_5 = uilabel(app.uipanelFreq1_5);
            app.text156_5.Tag = 'text156';
            app.text156_5.Text = 'Step number for FMI 2';
            app.nstep2LSD = uieditfield(app.uipanelFreq1_5, 'text');
            app.nstep2LSD.Tag = 'nstep1LGG3';
            app.nstep2LSD.Value = '0';
            app.uipanelFreq1_6 = uipanel(app.uipanel_Freq3D_LSD);
            app.uipanelFreq1_6.Tag = 'uipanelFreq1';
            app.editLSD_min_Freq3 = uieditfield(app.uipanelFreq1_6, 'text');
            app.editLSD_min_Freq3.Tag = 'editGG3_min_Freq1';
            app.editLSD_min_Freq3.Value = '-0.7';
            app.text101_6 = uilabel(app.uipanelFreq1_6);
            app.text101_6.Tag = 'text101';
            app.text101_6.Text = 'Frequency 3';
            app.text102_6 = uilabel(app.uipanelFreq1_6);
            app.text102_6.Tag = 'text102';
            app.text102_6.Text = {'Compute the first'; 'frequency between'};
            app.editLSD_max_Freq3 = uieditfield(app.uipanelFreq1_6, 'text');
            app.editLSD_max_Freq3.Tag = 'editGG3_max_Freq1';
            app.editLSD_max_Freq3.Value = '0.7';
            app.text103_6 = uilabel(app.uipanelFreq1_6);
            app.text103_6.Tag = 'text103';
            app.text103_6.Text = 'and';
            app.text156_6 = uilabel(app.uipanelFreq1_6);
            app.text156_6.Tag = 'text156';
            app.text156_6.Text = 'Step number for FMI 3';
            app.nstep3LSD = uieditfield(app.uipanelFreq1_6, 'text');
            app.nstep3LSD.Tag = 'nstep1LGG3';
            app.nstep3LSD.Value = '0';
            app.uipanel68_4 = uipanel(app.uipanel_Freq3D_LSD);
            app.uipanel68_4.Tag = 'uipanel68';
            app.edit_3D_init_D = uieditfield(app.uipanel68_4, 'text');
            app.edit_3D_init_D.Tag = 'edit_Init_Gprime';
            app.edit_3D_init_D.Value = '0.70';
            app.edit_3D_final_D = uieditfield(app.uipanel68_4, 'text');
            app.edit_3D_final_D.Tag = 'edit_Final_Gprime';
            app.edit_3D_final_D.Value = '0.90';
            app.text97_4 = uilabel(app.uipanel68_4);
            app.text97_4.Tag = 'text97';
            app.text97_4.Text = 'with step number ';
            app.edit_3D_step_D = uieditfield(app.uipanel68_4, 'text');
            app.edit_3D_step_D.ValueChangedFcn = createCallbackFcn(app, @StepD_change, true);
            app.edit_3D_step_D.Tag = 'edit_Step_Gprime';
            app.edit_3D_step_D.Value = '4';
            app.leq2D_3LleqLabel = uilabel(app.uipanel68_4);
            app.leq2D_3LleqLabel.Interpreter = 'latex';
            app.leq2D_3LleqLabel.Text = '$\leq 2D_3/L \leq$';
            app.phi_RLabel = uilabel(app.uipanel_Freq3D_LSD);
            app.phi_RLabel.Interpreter = 'latex';
            app.phi_RLabel.Text = '$\phi_R=$';
            app.uipanel_AA_FMFT = uipanel(app.KEPLERUIFigure);
            app.uipanel_AA_FMFT.Title = ' Action-Angle variables and Time-Frequency Analysis on KAM Tori ';
            app.uipanel_AA_FMFT.Tag = 'uipanel_AA_FMFT';
            app.uipanel_User = uibuttongroup(app.uipanel_AA_FMFT);
            app.uipanel_User.Tag = 'uipanel_User';
            app.radiobutton_Delaunay = uiradiobutton(app.uipanel_User);
            app.radiobutton_Delaunay.Tag = 'radiobutton_Delaunay';
            app.radiobutton_Delaunay.Text = {'Delaunay'; 'Action-Angle'};
            app.radiobutton_Delaunay.Value = true;
            app.radiobutton_Delaunayprime = uiradiobutton(app.uipanel_User);
            app.radiobutton_Delaunayprime.Tag = 'radiobutton_Delaunayprime';
            app.radiobutton_Delaunayprime.Text = {'Rotated Delaumay'; 'Action-Angle'};
            app.radiobutton_Pauli = uiradiobutton(app.uipanel_User);
            app.radiobutton_Pauli.Tag = 'radiobutton_Pauli';
            app.radiobutton_Pauli.Text = 'Pauli Action-Angle';
            app.radiobutton_Pauliprime = uiradiobutton(app.uipanel_User);
            app.radiobutton_Pauliprime.Tag = 'radiobutton_Pauliprime';
            app.radiobutton_Pauliprime.Text = {'Rotated Pauli'; 'Action-Angle'};
            app.radiobutton_User = uiradiobutton(app.uipanel_User);
            app.radiobutton_User.Tag = 'radiobutton_User';
            app.radiobutton_User.Text = 'User Action-Angle';
            app.text64 = uilabel(app.uipanel_User);
            app.text64.Tag = 'text64';
            app.text64.Text = {'◄  FFT - FMFT - WT'; '   will be calculated for ▼'};
            app.uipanel_FMFT = uipanel(app.uipanel_AA_FMFT);
            app.uipanel_FMFT.Tag = 'uipanel_FMFT';
            app.pushbutton_FMFT = uibutton(app.uipanel_FMFT, 'push');
            app.pushbutton_FMFT.ButtonPushedFcn = createCallbackFcn(app, @exec_FMFT, true);
            app.pushbutton_FMFT.Tag = 'pushbutton_FMFT';
            app.pushbutton_FMFT.Enable = 'off';
            app.pushbutton_FMFT.Text = 'FMFT';
            app.ComputethefirstfrequenciesbetweenfreqandfreqwithmethodLabel = uilabel(app.uipanel_FMFT);
            app.ComputethefirstfrequenciesbetweenfreqandfreqwithmethodLabel.Text = {'Compute the first              frequencies'; ''; 'between freq              and freq.              '; ''; 'with method'};
            app.edit_nfreq = uieditfield(app.uipanel_FMFT, 'text');
            app.edit_nfreq.Tag = 'edit_nfreq';
            app.edit_nfreq.Value = '8';
            app.edit_fromfreq = uieditfield(app.uipanel_FMFT, 'text');
            app.edit_fromfreq.Tag = 'edit_fromfreq';
            app.edit_fromfreq.Value = '-5';
            app.edit_tofreq = uieditfield(app.uipanel_FMFT, 'text');
            app.edit_tofreq.Tag = 'edit_tofreq';
            app.edit_tofreq.Value = '5';
            app.popupmenu_FMFT_flag = uidropdown(app.uipanel_FMFT);
            app.popupmenu_FMFT_flag.Items = {'FMFT base', 'FMFT linear correction', 'FMFT nonlinear correction'};
            app.popupmenu_FMFT_flag.ItemsData = {'1', '2', '3'};
            app.popupmenu_FMFT_flag.Tag = 'popupmenu_FMFT_flag';
            app.popupmenu_FMFT_flag.Value = '1';
            app.uipanel_FFT = uipanel(app.uipanel_AA_FMFT);
            app.uipanel_FFT.Tag = 'uipanel_FFT';
            app.pushbutton_FFT = uibutton(app.uipanel_FFT, 'push');
            app.pushbutton_FFT.ButtonPushedFcn = createCallbackFcn(app, @exec_FFT, true);
            app.pushbutton_FFT.Tag = 'pushbutton_FFT';
            app.pushbutton_FFT.Enable = 'off';
            app.pushbutton_FFT.Text = '  FFT';
            app.check_log = uicheckbox(app.uipanel_FFT);
            app.check_log.Tag = 'check_log';
            app.check_log.Text = 'log scale';
            app.check_log.Value = true;
            app.uipanel52 = uipanel(app.uipanel_AA_FMFT);
            app.uipanel52.Tag = 'uipanel52';
            app.pushbutton_wavelet = uibutton(app.uipanel52, 'push');
            app.pushbutton_wavelet.ButtonPushedFcn = createCallbackFcn(app, @exec_Wavelet, true);
            app.pushbutton_wavelet.Tag = 'pushbutton_wavelet';
            app.pushbutton_wavelet.Enable = 'off';
            app.pushbutton_wavelet.Text = {'Wavelet'; 'Transform'; ''};
            app.BetweenfreqandfreqVarianceLabel = uilabel(app.uipanel52);
            app.BetweenfreqandfreqVarianceLabel.Text = {'Between freq.              and freq.              '; ''; '        Variance'};
            app.edit_min_freq = uieditfield(app.uipanel52, 'text');
            app.edit_min_freq.Tag = 'edit_min_freq';
            app.edit_min_freq.Value = '0.5';
            app.edit_max_freq = uieditfield(app.uipanel52, 'text');
            app.edit_max_freq.Tag = 'edit_max_freq';
            app.edit_max_freq.Value = '1.8';
            app.edit_Variance = uieditfield(app.uipanel52, 'text');
            app.edit_Variance.Tag = 'edit_Variance';
            app.edit_Variance.Value = '30';
            app.uipanel54 = uipanel(app.uipanel_AA_FMFT);
            app.uipanel54.Tag = 'uipanel54';
            app.pushbutton_Pauli = uibutton(app.uipanel54, 'push');
            app.pushbutton_Pauli.ButtonPushedFcn = createCallbackFcn(app, @plot_Pauli_vector, true);
            app.pushbutton_Pauli.Tag = 'pushbutton_Pauli';
            app.pushbutton_Pauli.Enable = 'off';
            app.pushbutton_Pauli.Text = 'Pauli vectors';
            app.pushbutton_Pauliprime = uibutton(app.uipanel54, 'push');
            app.pushbutton_Pauliprime.ButtonPushedFcn = createCallbackFcn(app, @plot_Pauli_prime, true);
            app.pushbutton_Pauliprime.Tag = 'pushbutton_Pauliprime';
            app.pushbutton_Pauliprime.Enable = 'off';
            app.pushbutton_Pauliprime.Text = 'Rotated Pauli vectors';
            app.uipanel_Pauli = uipanel(app.uipanel_AA_FMFT);
            app.uipanel_Pauli.Title = 'Normalized  Pauli vectors of the fixed point';
            app.uipanel_Pauli.Tag = 'uipanel_Pauli';
            app.edit_S01 = uieditfield(app.uipanel_Pauli, 'text');
            app.edit_S01.ValueChangedFcn = createCallbackFcn(app, @check_s1, true);
            app.edit_S01.Tag = 'edit_S01';
            app.edit_S01.Value = '-0.41737';
            app.edit_S02 = uieditfield(app.uipanel_Pauli, 'text');
            app.edit_S02.ValueChangedFcn = createCallbackFcn(app, @check_s2, true);
            app.edit_S02.Tag = 'edit_S02';
            app.edit_S02.Value = '0';
            app.edit_S03 = uieditfield(app.uipanel_Pauli, 'text');
            app.edit_S03.ValueChangedFcn = createCallbackFcn(app, @check_s3, true);
            app.edit_S03.Tag = 'edit_S03';
            app.edit_S03.Value = '0.90874';
            app.edit_D01 = uieditfield(app.uipanel_Pauli, 'text');
            app.edit_D01.ValueChangedFcn = createCallbackFcn(app, @check_d1, true);
            app.edit_D01.Tag = 'edit_D01';
            app.edit_D01.Value = '0.41737';
            app.edit_D02 = uieditfield(app.uipanel_Pauli, 'text');
            app.edit_D02.ValueChangedFcn = createCallbackFcn(app, @check_d2, true);
            app.edit_D02.Tag = 'edit_D02';
            app.edit_D02.Value = '0';
            app.edit_D03 = uieditfield(app.uipanel_Pauli, 'text');
            app.edit_D03.ValueChangedFcn = createCallbackFcn(app, @check_d3, true);
            app.edit_D03.Tag = 'edit_D03';
            app.edit_D03.Value = '0.90874';
            app.pushbutton_UpdateInit = uibutton(app.uipanel_Pauli, 'push');
            app.pushbutton_UpdateInit.ButtonPushedFcn = createCallbackFcn(app, @update_init, true);
            app.pushbutton_UpdateInit.Tag = 'pushbutton_UpdateInit';
            app.pushbutton_UpdateInit.Enable = 'off';
            app.pushbutton_UpdateInit.Text = {'Update    ▲'; 'initial conditions'};
            app.text46 = uilabel(app.uipanel_Pauli);
            app.text46.Tag = 'text46';
            app.text46.Text = {'with these values of ◄ Pauli vectors'; 'and Total'; 'Energy ►'};
            app.edit_Energy = uieditfield(app.uipanel_Pauli, 'text');
            app.edit_Energy.ValueChangedFcn = createCallbackFcn(app, @All_EnergyValueChanged, true);
            app.edit_Energy.Tag = 'edit_Energy';
            app.edit_Energy.Value = '-0.50';
            app.s_1Label = uilabel(app.uipanel_Pauli);
            app.s_1Label.Interpreter = 'latex';
            app.s_1Label.Text = '$s_1$';
            app.s_2Label = uilabel(app.uipanel_Pauli);
            app.s_2Label.Interpreter = 'latex';
            app.s_2Label.Text = '$s_2$';
            app.s_3Label = uilabel(app.uipanel_Pauli);
            app.s_3Label.Interpreter = 'latex';
            app.s_3Label.Text = '$s_3$';
            app.d_1Label = uilabel(app.uipanel_Pauli);
            app.d_1Label.Interpreter = 'latex';
            app.d_1Label.Text = '$d_1$';
            app.d_2Label = uilabel(app.uipanel_Pauli);
            app.d_2Label.Interpreter = 'latex';
            app.d_2Label.Text = '$d_2$';
            app.d_3Label = uilabel(app.uipanel_Pauli);
            app.d_3Label.Interpreter = 'latex';
            app.d_3Label.Text = '$d_3$';
            app.EnableUpdatebuttonCheckBox = uicheckbox(app.uipanel_Pauli);
            app.EnableUpdatebuttonCheckBox.ValueChangedFcn = createCallbackFcn(app, @enable_Update, true);
            app.EnableUpdatebuttonCheckBox.Text = 'Enable Update button';
            app.Panel = uipanel(app.uipanel_AA_FMFT);
            app.pushbutton_AA_Pauliprime = uibutton(app.Panel, 'push');
            app.pushbutton_AA_Pauliprime.ButtonPushedFcn = createCallbackFcn(app, @plot_AA_Pauliprime, true);
            app.pushbutton_AA_Pauliprime.Tag = 'pushbutton_AA_Pauliprime';
            app.pushbutton_AA_Pauliprime.Enable = 'off';
            app.pushbutton_AA_Pauliprime.Text = '.Rotated Pauli Ac-An';
            app.pushbutton_AA_Pauli = uibutton(app.Panel, 'push');
            app.pushbutton_AA_Pauli.ButtonPushedFcn = createCallbackFcn(app, @plot_AA_Pauli, true);
            app.pushbutton_AA_Pauli.Tag = 'pushbutton_AA_Pauli';
            app.pushbutton_AA_Pauli.Enable = 'off';
            app.pushbutton_AA_Pauli.Text = 'Pauli Action-Angle';
            app.pushbutton_AA_Delaunayprime = uibutton(app.Panel, 'push');
            app.pushbutton_AA_Delaunayprime.ButtonPushedFcn = createCallbackFcn(app, @plot_AA_Delaunay_prime, true);
            app.pushbutton_AA_Delaunayprime.Tag = 'pushbutton_AA_Delaunayprime';
            app.pushbutton_AA_Delaunayprime.Enable = 'off';
            app.pushbutton_AA_Delaunayprime.Text = 'Rotated Delaunay Ac-An';
            app.pushbutton_AA_Delaunay = uibutton(app.Panel, 'push');
            app.pushbutton_AA_Delaunay.ButtonPushedFcn = createCallbackFcn(app, @plot_AA_Delaynay, true);
            app.pushbutton_AA_Delaunay.Tag = 'pushbutton_AA_Delaunay';
            app.pushbutton_AA_Delaunay.Enable = 'off';
            app.pushbutton_AA_Delaunay.Text = 'Delaunay Action-Angle';
            app.pushbutton_AA_User = uibutton(app.Panel, 'push');
            app.pushbutton_AA_User.ButtonPushedFcn = createCallbackFcn(app, @plot_AAuser, true);
            app.pushbutton_AA_User.Tag = 'pushbutton_AA_User';
            app.pushbutton_AA_User.Enable = 'off';
            app.pushbutton_AA_User.Text = 'User Action-Angle';
            app.uipanel_StandMethod = uipanel(app.KEPLERUIFigure);
            app.uipanel_StandMethod.Title = ' Standard method ';
            app.uipanel_StandMethod.Tag = 'uipanel_StandMethod';
            app.pushbutton_StandTrajectories = uibutton(app.uipanel_StandMethod, 'push');
            app.pushbutton_StandTrajectories.ButtonPushedFcn = createCallbackFcn(app, @plot_StandTrajectories, true);
            app.pushbutton_StandTrajectories.Tag = 'pushbutton_StandTrajectories';
            app.pushbutton_StandTrajectories.Enable = 'off';
            app.pushbutton_StandTrajectories.Text = 'Trajectories';
            app.pushbutton_KeplerParameters = uibutton(app.uipanel_StandMethod, 'push');
            app.pushbutton_KeplerParameters.ButtonPushedFcn = createCallbackFcn(app, @plot_KeplerParameters, true);
            app.pushbutton_KeplerParameters.Tag = 'pushbutton_KeplerParameters';
            app.pushbutton_KeplerParameters.Enable = 'off';
            app.pushbutton_KeplerParameters.Text = 'Kepler Parameters';
            app.pushbutton_Error = uibutton(app.uipanel_StandMethod, 'push');
            app.pushbutton_Error.ButtonPushedFcn = createCallbackFcn(app, @plot_Error, true);
            app.pushbutton_Error.Tag = 'pushbutton_Error';
            app.pushbutton_Error.Enable = 'off';
            app.pushbutton_Error.Text = 'Integration Error';
            app.pushbutton_t_Step = uibutton(app.uipanel_StandMethod, 'push');
            app.pushbutton_t_Step.ButtonPushedFcn = createCallbackFcn(app, @plot_t_step, true);
            app.pushbutton_t_Step.Tag = 'pushbutton_t_Step';
            app.pushbutton_t_Step.Enable = 'off';
            app.pushbutton_t_Step.Text = 'Time vs Step number';
            app.pushbutton_StandIntegrate = uibutton(app.uipanel_StandMethod, 'push');
            app.pushbutton_StandIntegrate.ButtonPushedFcn = createCallbackFcn(app, @exec_StandIntegrate, true);
            app.pushbutton_StandIntegrate.Tag = 'pushbutton_StandIntegrate';
            app.pushbutton_StandIntegrate.Text = 'Integrate';
            app.pushbutton_Stand_UserFunction = uibutton(app.uipanel_StandMethod, 'push');
            app.pushbutton_Stand_UserFunction.ButtonPushedFcn = createCallbackFcn(app, @plot_StandardUser, true);
            app.pushbutton_Stand_UserFunction.Tag = 'pushbutton_Stand_UserFunction';
            app.pushbutton_Stand_UserFunction.Enable = 'off';
            app.pushbutton_Stand_UserFunction.Text = 'User Functions';
            app.uipanel_RegMethod = uipanel(app.KEPLERUIFigure);
            app.uipanel_RegMethod.Title = ' Regularization method ';
            app.uipanel_RegMethod.Tag = 'uipanel_RegMethod';
            app.pushbutton_Integrate = uibutton(app.uipanel_RegMethod, 'push');
            app.pushbutton_Integrate.ButtonPushedFcn = createCallbackFcn(app, @exec_Integrate, true);
            app.pushbutton_Integrate.Tag = 'pushbutton_Integrate';
            app.pushbutton_Integrate.Text = 'Integrate';
            app.pushbutton_Trajectories = uibutton(app.uipanel_RegMethod, 'push');
            app.pushbutton_Trajectories.ButtonPushedFcn = createCallbackFcn(app, @plot_Trajectories, true);
            app.pushbutton_Trajectories.Tag = 'pushbutton_Trajectories';
            app.pushbutton_Trajectories.Enable = 'off';
            app.pushbutton_Trajectories.Text = 'Space and momentum trajectories';
            app.pushbutton_tauKepler = uibutton(app.uipanel_RegMethod, 'push');
            app.pushbutton_tauKepler.ButtonPushedFcn = createCallbackFcn(app, @plot_tauKepler, true);
            app.pushbutton_tauKepler.Tag = 'pushbutton_tauKepler';
            app.pushbutton_tauKepler.Enable = 'off';
            app.pushbutton_tauKepler.Text = 'Kepler Parameters vs False Time';
            app.pushbutton_tKepler = uibutton(app.uipanel_RegMethod, 'push');
            app.pushbutton_tKepler.ButtonPushedFcn = createCallbackFcn(app, @plot_tKepler, true);
            app.pushbutton_tKepler.Tag = 'pushbutton_tKepler';
            app.pushbutton_tKepler.Enable = 'off';
            app.pushbutton_tKepler.Text = 'Kepler Parameters vs TrueTime';
            app.pushbutton_Time = uibutton(app.uipanel_RegMethod, 'push');
            app.pushbutton_Time.ButtonPushedFcn = createCallbackFcn(app, @plot_Time, true);
            app.pushbutton_Time.Tag = 'pushbutton_Time';
            app.pushbutton_Time.Enable = 'off';
            app.pushbutton_Time.Text = 'True Time vs False Time';
            app.pushbutton_Errors = uibutton(app.uipanel_RegMethod, 'push');
            app.pushbutton_Errors.ButtonPushedFcn = createCallbackFcn(app, @plot_Errors, true);
            app.pushbutton_Errors.Tag = 'pushbutton_Errors';
            app.pushbutton_Errors.Enable = 'off';
            app.pushbutton_Errors.Text = 'Integration Errors';
            app.pushbutton_UserFunction = uibutton(app.uipanel_RegMethod, 'push');
            app.pushbutton_UserFunction.ButtonPushedFcn = createCallbackFcn(app, @plot_User, true);
            app.pushbutton_UserFunction.Tag = 'pushbutton_UserFunction';
            app.pushbutton_UserFunction.Enable = 'off';
            app.pushbutton_UserFunction.Text = 'User Functions';
            app.pushbutton_tau_Step = uibutton(app.uipanel_RegMethod, 'push');
            app.pushbutton_tau_Step.ButtonPushedFcn = createCallbackFcn(app, @plot_tau_Step, true);
            app.pushbutton_tau_Step.Tag = 'pushbutton_tau_Step';
            app.pushbutton_tau_Step.Enable = 'off';
            app.pushbutton_tau_Step.Text = 'False time vs Step number';
            app.uipanel_Pert_NonPert = uibuttongroup(app.uipanel_RegMethod);
            app.uipanel_Pert_NonPert.Tag = 'uipanel_Pert_NonPert';
            app.check_nonpert = uicheckbox(app.uipanel_Pert_NonPert);
            app.check_nonpert.ValueChangedFcn = createCallbackFcn(app, @check_nonpert_change, true);
            app.check_nonpert.Text = {'Non perturbative method'; '  with        regularization'};
            app.check_pert = uicheckbox(app.uipanel_Pert_NonPert);
            app.check_pert.ValueChangedFcn = createCallbackFcn(app, @check_pert_change, true);
            app.check_pert.Text = {'   Perturbative method'; 'with Moser regularization'};
            app.check_pert.Value = true;
            app.Image = uiimage(app.uipanel_Pert_NonPert);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UserSaved', 'KS.jpg');
            app.uipanel53 = uipanel(app.KEPLERUIFigure);
            app.uipanel53.Tag = 'uipanel53';
            app.popupmenu_head = uidropdown(app.uipanel53);
            app.popupmenu_head.Items = {' Single orbit analysis ', ' Global analysis: Poincaré section ', ' Global analysis: Rotated Delaunay ', ' Global analysis: Rotated Pauli '};
            app.popupmenu_head.ItemsData = {'1', '2', '3', '4'};
            app.popupmenu_head.ValueChangedFcn = createCallbackFcn(app, @head_change, true);
            app.popupmenu_head.Tag = 'popupmenu_head';
            app.popupmenu_head.Value = '1';
            app.uiPanelHp = uipanel(app.KEPLERUIFigure);
            app.uiPanelHp.Title = ' Perturbation Hamiltonian ';
            app.uiPanelHp.Tag = 'uiPanelHp';
            app.popupmenuHp1 = uidropdown(app.uiPanelHp);
            app.popupmenuHp1.Items = {'Zeeman1', 'Zeeman2', 'Zeeman3', 'QZ1', 'QZ2', 'QZ3', 'Stark1', 'Stark2', 'Stark3', 'Hill', 'Euler1', 'Euler2', 'Euler3', 'Satellite', 'overR2', 'overR3', 'p_2', 'Oscillator', 'Anisotropic', 'Zero'};
            app.popupmenuHp1.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.popupmenuHp1.ValueChangedFcn = createCallbackFcn(app, @Hp1_change, true);
            app.popupmenuHp1.Tag = 'popupmenuHp1';
            app.popupmenuHp1.Value = '6';
            app.popupmenuHp2 = uidropdown(app.uiPanelHp);
            app.popupmenuHp2.Items = {'Zeeman1', 'Zeeman2', 'Zeeman3', 'QZ1', 'QZ2', 'QZ3', 'Stark1', 'Stark2', 'Stark3', 'Hill', 'Euler1', 'Euler2', 'Euler3', 'Satellite', 'overR2', 'overR3', 'p_2', 'Oscillator', 'Anisotropic', 'Zero'};
            app.popupmenuHp2.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.popupmenuHp2.ValueChangedFcn = createCallbackFcn(app, @Hp2_change, true);
            app.popupmenuHp2.Tag = 'popupmenuHp2';
            app.popupmenuHp2.Value = '20';
            app.popupmenuHp3 = uidropdown(app.uiPanelHp);
            app.popupmenuHp3.Items = {'Zeeman1', 'Zeeman2', 'Zeeman3', 'QZ1', 'QZ2', 'QZ3', 'Stark1', 'Stark2', 'Stark3', 'Hill', 'Euler1', 'Euler2', 'Euler3', 'Satellite', 'overR2', 'overR3', 'p_2', 'Oscillator', 'Anisotropic', 'Zero'};
            app.popupmenuHp3.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.popupmenuHp3.ValueChangedFcn = createCallbackFcn(app, @Hp3_change, true);
            app.popupmenuHp3.Tag = 'popupmenuHp3';
            app.popupmenuHp3.Value = '20';
            app.popupmenuHp4 = uidropdown(app.uiPanelHp);
            app.popupmenuHp4.Items = {'Zeeman1', 'Zeeman2', 'Zeeman3', 'QZ1', 'QZ2', 'QZ3', 'Stark1', 'Stark2', 'Stark3', 'Hill', 'Euler1', 'Euler2', 'Euler3', 'Satellite', 'overR2', 'overR3', 'p_2', 'Oscillator', 'Anisotropic', 'Zero'};
            app.popupmenuHp4.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.popupmenuHp4.ValueChangedFcn = createCallbackFcn(app, @Hp4_change, true);
            app.popupmenuHp4.Tag = 'popupmenuHp4';
            app.popupmenuHp4.Value = '20';
            app.edit_epsilon1 = uieditfield(app.uiPanelHp, 'text');
            app.edit_epsilon1.ValueChangedFcn = createCallbackFcn(app, @epsilon1_change, true);
            app.edit_epsilon1.Tag = 'edit_epsilon1';
            app.edit_epsilon1.Value = '0.2';
            app.edit_epsilon2 = uieditfield(app.uiPanelHp, 'text');
            app.edit_epsilon2.ValueChangedFcn = createCallbackFcn(app, @epsilon2_change, true);
            app.edit_epsilon2.Tag = 'edit_epsilon2';
            app.edit_epsilon2.Value = '0';
            app.edit_epsilon3 = uieditfield(app.uiPanelHp, 'text');
            app.edit_epsilon3.ValueChangedFcn = createCallbackFcn(app, @epsilon3_change, true);
            app.edit_epsilon3.Tag = 'edit_epsilon3';
            app.edit_epsilon3.Value = '0';
            app.edit_epsilon4 = uieditfield(app.uiPanelHp, 'text');
            app.edit_epsilon4.ValueChangedFcn = createCallbackFcn(app, @epsilon4_change, true);
            app.edit_epsilon4.Tag = 'edit_epsilon4';
            app.edit_epsilon4.Value = '0';
            app.textHp = uilabel(app.uiPanelHp);
            app.textHp.Tag = 'textHp';
            app.textHp.Text = 'is the sum of the following:';
            app.text_epsilon = uilabel(app.uiPanelHp);
            app.text_epsilon.Tag = 'text_epsilon';
            app.text_epsilon.Text = {'with perturbative'; 'parameters'};
            app.popupmenuHp5 = uidropdown(app.uiPanelHp);
            app.popupmenuHp5.Items = {'Zeeman1', 'Zeeman2', 'Zeeman3', 'QZ1', 'QZ2', 'QZ3', 'Stark1', 'Stark2', 'Stark3', 'Hill', 'Euler1', 'Euler2', 'Euler3', 'Satellite', 'overR2', 'overR3', 'p_2', 'Oscillator', 'Anisotropic', 'Zero'};
            app.popupmenuHp5.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.popupmenuHp5.ValueChangedFcn = createCallbackFcn(app, @Hp5_change, true);
            app.popupmenuHp5.Tag = 'popupmenuHp5';
            app.popupmenuHp5.Value = '20';
            app.edit_epsilon5 = uieditfield(app.uiPanelHp, 'text');
            app.edit_epsilon5.ValueChangedFcn = createCallbackFcn(app, @epsilon5_change, true);
            app.edit_epsilon5.Tag = 'edit_epsilon5';
            app.edit_epsilon5.Value = '0';
            app.uipanel18 = uipanel(app.KEPLERUIFigure);
            app.uipanel18.Title = ' Revolution number ';
            app.uipanel18.Tag = 'uipanel18';
            app.uipanel_from_to = uipanel(app.uipanel18);
            app.uipanel_from_to.Tag = 'uipanel_from_to';
            app.text_from = uilabel(app.uipanel_from_to);
            app.text_from.Tag = 'text_from';
            app.text_from.Text = {'Plot from revolution'; ''; 'to revolution'};
            app.edit_from = uieditfield(app.uipanel_from_to, 'text');
            app.edit_from.ValueChangedFcn = createCallbackFcn(app, @edit_from_change, true);
            app.edit_from.Tag = 'edit_from';
            app.edit_from.Value = '0';
            app.edit_to = uieditfield(app.uipanel_from_to, 'text');
            app.edit_to.ValueChangedFcn = createCallbackFcn(app, @edit_to_change, true);
            app.edit_to.Tag = 'edit_to';
            app.edit_to.Value = '100';
            app.uipanel_ClearMemory = uipanel(app.uipanel18);
            app.uipanel_ClearMemory.Enable = 'off';
            app.uipanel_ClearMemory.Tag = 'uipanel_ClearMemory';
            app.text151 = uilabel(app.uipanel_ClearMemory);
            app.text151.Tag = 'text151';
            app.text151.Text = {'Clear memory and update Demodulation'; ''; 'window every                 revolutions'; ''};
            app.edit_ClearMemory = uieditfield(app.uipanel_ClearMemory, 'text');
            app.edit_ClearMemory.Tag = 'edit_ClearMemory';
            app.edit_ClearMemory.Value = '200';
            app.LongtimeFA = uipanel(app.uipanel18);
            app.LongtimeFA.Enable = 'off';
            app.LongtimeFA.Tag = 'LongtimeFA';
            app.text154 = uilabel(app.LongtimeFA);
            app.text154.Tag = 'text154';
            app.text154.Text = {'Compute Demodulation'; ''; 'on                 revolutions'; ''};
            app.ComputeLFA = uieditfield(app.LongtimeFA, 'text');
            app.ComputeLFA.Tag = 'ComputeLFA';
            app.ComputeLFA.Value = '164';
            app.uipanel4 = uipanel(app.uipanel18);
            app.uipanel4.Tag = 'uipanel4';
            app.total_cycles = uieditfield(app.uipanel4, 'text');
            app.total_cycles.ValueChangedFcn = createCallbackFcn(app, @total_cycle_change, true);
            app.total_cycles.Tag = 'total_cycles';
            app.total_cycles.Value = '100';
            app.text_tot_cycles = uilabel(app.uipanel4);
            app.text_tot_cycles.Tag = 'text_tot_cycles';
            app.text_tot_cycles.Text = {'Total integration'; 'number of revolutions'};
            app.uipanelInit = uipanel(app.KEPLERUIFigure);
            app.uipanelInit.Title = ' Initial conditions ';
            app.uipanelInit.Tag = 'uipanelInit';
            app.uipanel15 = uipanel(app.uipanelInit);
            app.uipanel15.Title = 'Position and momentum';
            app.uipanel15.Tag = 'uipanel15';
            app.q1_value = uieditfield(app.uipanel15, 'text');
            app.q1_value.ValueChangedFcn = createCallbackFcn(app, @q1_change, true);
            app.q1_value.Tag = 'q1_value';
            app.q1_value.Value = '-0.4470206874';
            app.q2_value = uieditfield(app.uipanel15, 'text');
            app.q2_value.ValueChangedFcn = createCallbackFcn(app, @q2_change, true);
            app.q2_value.Tag = 'q2_value';
            app.q2_value.Value = '0.0';
            app.q3_value = uieditfield(app.uipanel15, 'text');
            app.q3_value.ValueChangedFcn = createCallbackFcn(app, @q3_chnge, true);
            app.q3_value.Tag = 'q3_value';
            app.q3_value.Value = '0.0';
            app.p1_value = uieditfield(app.uipanel15, 'text');
            app.p1_value.ValueChangedFcn = createCallbackFcn(app, @p1_change, true);
            app.p1_value.Tag = 'p1_value';
            app.p1_value.Value = '0.0';
            app.p2_value = uieditfield(app.uipanel15, 'text');
            app.p2_value.ValueChangedFcn = createCallbackFcn(app, @p2_change, true);
            app.p2_value.Tag = 'p2_value';
            app.p2_value.Value = '-1.7888004831';
            app.p3_value = uieditfield(app.uipanel15, 'text');
            app.p3_value.ValueChangedFcn = createCallbackFcn(app, @p3_change, true);
            app.p3_value.Tag = 'p3_value';
            app.p3_value.Value = '0.0';
            app.q_1Label = uilabel(app.uipanel15);
            app.q_1Label.Interpreter = 'latex';
            app.q_1Label.Text = '$q_1=$';
            app.q_2Label = uilabel(app.uipanel15);
            app.q_2Label.Interpreter = 'latex';
            app.q_2Label.Text = '$q_2=$';
            app.q_3Label = uilabel(app.uipanel15);
            app.q_3Label.Interpreter = 'latex';
            app.q_3Label.Text = '$q_3=$';
            app.p_1Label = uilabel(app.uipanel15);
            app.p_1Label.Interpreter = 'latex';
            app.p_1Label.Text = '$p_1=$';
            app.p_2Label = uilabel(app.uipanel15);
            app.p_2Label.Interpreter = 'latex';
            app.p_2Label.Text = '$p_2=$';
            app.p_3Label = uilabel(app.uipanel15);
            app.p_3Label.Interpreter = 'latex';
            app.p_3Label.Text = '$p_3=$';
            app.uipanel16 = uipanel(app.uipanelInit);
            app.uipanel16.Title = 'Kepler parameters';
            app.uipanel16.Tag = 'uipanel16';
            app.text31 = uilabel(app.uipanel16);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Semimajor axis';
            app.text32 = uilabel(app.uipanel16);
            app.text32.Tag = 'text32';
            app.text32.Text = 'Eccentricity';
            app.text33 = uilabel(app.uipanel16);
            app.text33.Tag = 'text33';
            app.text33.Text = {'Longitude of'; 'ascending node (°)'};
            app.edit_smaxis = uieditfield(app.uipanel16, 'text');
            app.edit_smaxis.ValueChangedFcn = createCallbackFcn(app, @Sma_change, true);
            app.edit_smaxis.Tag = 'edit_smaxis';
            app.edit_smaxis.Value = '0.8';
            app.edit_Eccentricity = uieditfield(app.uipanel16, 'text');
            app.edit_Eccentricity.ValueChangedFcn = createCallbackFcn(app, @Ecc_change, true);
            app.edit_Eccentricity.Tag = 'edit_Eccentricity';
            app.edit_Eccentricity.Value = '0.6';
            app.edit_AscNode = uieditfield(app.uipanel16, 'text');
            app.edit_AscNode.ValueChangedFcn = createCallbackFcn(app, @AscNode_change, true);
            app.edit_AscNode.Tag = 'edit_AscNode';
            app.edit_AscNode.Value = '0';
            app.text34 = uilabel(app.uipanel16);
            app.text34.Tag = 'text34';
            app.text34.Text = {'Argument of'; 'pericenter (°)'};
            app.text35 = uilabel(app.uipanel16);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Inclination (°)';
            app.text36 = uilabel(app.uipanel16);
            app.text36.Tag = 'text36';
            app.text36.Text = 'True anomaly (°)';
            app.edit_ArgPericenter = uieditfield(app.uipanel16, 'text');
            app.edit_ArgPericenter.ValueChangedFcn = createCallbackFcn(app, @ArgPeri_change, true);
            app.edit_ArgPericenter.Tag = 'edit_ArgPericenter';
            app.edit_ArgPericenter.Value = '0';
            app.edit_Inclination = uieditfield(app.uipanel16, 'text');
            app.edit_Inclination.ValueChangedFcn = createCallbackFcn(app, @Incl_change, true);
            app.edit_Inclination.Tag = 'edit_Inclination';
            app.edit_Inclination.Value = '0';
            app.edit_TrueAnomaly = uieditfield(app.uipanel16, 'text');
            app.edit_TrueAnomaly.ValueChangedFcn = createCallbackFcn(app, @TrueAn_change, true);
            app.edit_TrueAnomaly.Tag = 'edit_TrueAnomaly';
            app.edit_TrueAnomaly.Value = '0';
            app.pushbutton_UpdateKepler = uibutton(app.uipanelInit, 'push');
            app.pushbutton_UpdateKepler.ButtonPushedFcn = createCallbackFcn(app, @update_Kepler, true);
            app.pushbutton_UpdateKepler.Tag = 'pushbutton_UpdateKepler';
            app.pushbutton_UpdateKepler.Enable = 'off';
            app.pushbutton_UpdateKepler.Text = {'Update ►'; 'Kepler par.'};
            app.pushbutton_UpdateCartesian = uibutton(app.uipanelInit, 'push');
            app.pushbutton_UpdateCartesian.ButtonPushedFcn = createCallbackFcn(app, @update_qp, true);
            app.pushbutton_UpdateCartesian.Tag = 'pushbutton_UpdateCartesian';
            app.pushbutton_UpdateCartesian.Enable = 'off';
            app.pushbutton_UpdateCartesian.Text = {'◄ Update'; 'q and p'};
            app.pushbutton_Up_Pauli = uibutton(app.uipanelInit, 'push');
            app.pushbutton_Up_Pauli.ButtonPushedFcn = createCallbackFcn(app, @update_Pauli, true);
            app.pushbutton_Up_Pauli.Tag = 'pushbutton_Up_Pauli';
            app.pushbutton_Up_Pauli.Enable = 'off';
            app.pushbutton_Up_Pauli.Text = {'Update Energy and'; ' Pauli vectors ▼'};
            app.uipanel_ODE = uipanel(app.KEPLERUIFigure);
            app.uipanel_ODE.Title = ' ODE solver ';
            app.uipanel_ODE.Tag = 'uipanel_ODE';
            app.uipanel_GniCodes = uipanel(app.uipanel_ODE);
            app.uipanel_GniCodes.Title = 'GniCodes Options';
            app.uipanel_GniCodes.Visible = 'off';
            app.uipanel_GniCodes.Tag = 'uipanel_GniCodes';
            app.edit_StepSize2 = uieditfield(app.uipanel_GniCodes, 'text');
            app.edit_StepSize2.ValueChangedFcn = createCallbackFcn(app, @Step_ODE2_change, true);
            app.edit_StepSize2.Tag = 'edit_StepSize2';
            app.edit_StepSize2.Value = '0.01';
            app.text59 = uilabel(app.uipanel_GniCodes);
            app.text59.Tag = 'text59';
            app.text59.Text = 'Stepsize';
            app.opt_ODE = uipanel(app.uipanel_ODE);
            app.opt_ODE.Title = 'Options';
            app.opt_ODE.Tag = 'opt_ODE';
            app.RelativeTolEditFieldLabel = uilabel(app.opt_ODE);
            app.RelativeTolEditFieldLabel.Text = 'Relative Tol';
            app.RelTol = uieditfield(app.opt_ODE, 'numeric');
            app.RelTol.ValueChangedFcn = createCallbackFcn(app, @RelTol_change, true);
            app.RelTol.Value = 1e-10;
            app.AbsoluteTolLabel = uilabel(app.opt_ODE);
            app.AbsoluteTolLabel.Text = 'Absolute Tol';
            app.AbsTol = uieditfield(app.opt_ODE, 'numeric');
            app.AbsTol.ValueChangedFcn = createCallbackFcn(app, @AbsTol_change, true);
            app.AbsTol.Value = 1e-14;
            app.OutputStepEditFieldLabel = uilabel(app.opt_ODE);
            app.OutputStepEditFieldLabel.Text = 'Output Step';
            app.step_ODE = uieditfield(app.opt_ODE, 'text');
            app.step_ODE.ValueChangedFcn = createCallbackFcn(app, @step_ODE_change, true);
            app.step_ODE.Value = '0.01';
            app.popupmenuODE2 = uidropdown(app.uipanel_ODE);
            app.popupmenuODE2.Items = {'gni_irk2', 'gni_lmm2', 'gni_comp'};
            app.popupmenuODE2.ValueChangedFcn = createCallbackFcn(app, @ODE2_change, true);
            app.popupmenuODE2.Tag = 'popupmenuODE2';
            app.popupmenuODE2.Value = 'gni_irk2';
            app.text_block = uilabel(app.uipanel_ODE);
            app.text_block.Tag = 'text_block';
            app.text_block.Text = {'Project on the constraints'; 'every                revolutions'};
            app.edit_block = uieditfield(app.uipanel_ODE, 'text');
            app.edit_block.ValueChangedFcn = createCallbackFcn(app, @block_change, true);
            app.edit_block.Tag = 'edit_block';
            app.edit_block.Value = '25';
            app.popupmenuODE = uidropdown(app.uipanel_ODE);
            app.popupmenuODE.Items = {'ode113', 'ode45', 'ode78', 'ode89', 'ode15s', 'dop853', 'BulirschStoer', 'ABM8', 'ShampineGordon', 'RungeKutta4', 'RungeKutta5'};
            app.popupmenuODE.ValueChangedFcn = createCallbackFcn(app, @ODE_change, true);
            app.popupmenuODE.Tag = 'popupmenuODE';
            app.popupmenuODE.Value = 'ode113';
            % Show the figure after all components are created
            app.KEPLERUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kepler_source

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.KEPLERUIFigure)

            % Execute the startup function
            runStartupFcn(app, @KEPLER_startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KEPLERUIFigure)
        end
    end
end