classdef Lagrange_source < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LAGRANGEUIFigure               matlab.ui.Figure
        Savesetting                    matlab.ui.container.Menu
        Save_exit                      matlab.ui.container.Menu
        Save_now                       matlab.ui.container.Menu
        View                           matlab.ui.container.Menu
        Percentage_of_RowColumn        matlab.ui.container.Menu
        RowColumn_Completed            matlab.ui.container.Menu
        Total_bars                     matlab.ui.container.Menu
        Wait_bars                      matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        AboutLAGRANGEMenu              matlab.ui.container.Menu
        HelpMenu_2                     matlab.ui.container.Menu
        uipanel_FMI                    matlab.ui.container.Panel
        I_3Label                       matlab.ui.control.Label
        I3_o                           matlab.ui.control.NumericEditField
        pushbutton_Slave               matlab.ui.control.Button
        uipanel10                      matlab.ui.container.Panel
        step_2                         matlab.ui.control.NumericEditField
        I2_fin                         matlab.ui.control.NumericEditField
        I2_in                          matlab.ui.control.NumericEditField
        leqI_2leqLabel                 matlab.ui.control.Label
        text15                         matlab.ui.control.Label
        uipanel9                       matlab.ui.container.Panel
        step_1                         matlab.ui.control.NumericEditField
        I1_fin                         matlab.ui.control.NumericEditField
        I1_in                          matlab.ui.control.NumericEditField
        leqI_1leqLabel                 matlab.ui.control.Label
        text14                         matlab.ui.control.Label
        uipanel8                       matlab.ui.container.Panel
        pushbutton_level               matlab.ui.control.Button
        pushbutton_FMI                 matlab.ui.control.Button
        pushbutton_freq                matlab.ui.control.Button
        pushbutton_Calculate           matlab.ui.control.Button
        uipanel7                       matlab.ui.container.Panel
        Step_FMI1                      matlab.ui.control.NumericEditField
        freq1_max                      matlab.ui.control.NumericEditField
        freq1_min                      matlab.ui.control.NumericEditField
        text19                         matlab.ui.control.Label
        text18                         matlab.ui.control.Label
        text17                         matlab.ui.control.Label
        text16                         matlab.ui.control.Label
        uipanel6                       matlab.ui.container.Panel
        Step_FMI3                      matlab.ui.control.NumericEditField
        freq3_max                      matlab.ui.control.NumericEditField
        freq3_min                      matlab.ui.control.NumericEditField
        text27                         matlab.ui.control.Label
        text26                         matlab.ui.control.Label
        text25                         matlab.ui.control.Label
        text24                         matlab.ui.control.Label
        uipanel5                       matlab.ui.container.Panel
        Step_FMI2                      matlab.ui.control.NumericEditField
        freq2_max                      matlab.ui.control.NumericEditField
        freq2_min                      matlab.ui.control.NumericEditField
        text23                         matlab.ui.control.Label
        text22                         matlab.ui.control.Label
        text21                         matlab.ui.control.Label
        text20                         matlab.ui.control.Label
        uipanel12                      matlab.ui.container.Panel
        opt_ODE                        matlab.ui.container.Panel
        step_ODE                       matlab.ui.control.NumericEditField
        AbsoluteTolLabel_2             matlab.ui.control.Label
        AbsTol                         matlab.ui.control.NumericEditField
        AbsoluteTolLabel               matlab.ui.control.Label
        RelTol                         matlab.ui.control.NumericEditField
        RelativeTolEditFieldLabel      matlab.ui.control.Label
        popupmenuODE                   matlab.ui.control.DropDown
        uipanel1                       matlab.ui.container.Panel
        pushbutton_error               matlab.ui.control.Button
        MassLabel                      matlab.ui.control.Label
        edit_time                      matlab.ui.control.NumericEditField
        IntegrationtimeEditFieldLabel  matlab.ui.control.Label
        edit_mass                      matlab.ui.control.EditField
        MassmuLabel                    matlab.ui.control.Label
        pushbutton_AA                  matlab.ui.control.Button
        pushbutton_Orbits              matlab.ui.control.Button
        pushbutton_Integrate           matlab.ui.control.Button
        uipanel2                       matlab.ui.container.Panel
        p3_o                           matlab.ui.control.NumericEditField
        v_xLabel_3                     matlab.ui.control.Label
        p2_o                           matlab.ui.control.NumericEditField
        v_xLabel_2                     matlab.ui.control.Label
        p1_o                           matlab.ui.control.NumericEditField
        v_xLabel                       matlab.ui.control.Label
        q3_o                           matlab.ui.control.NumericEditField
        DeltayLabel_2                  matlab.ui.control.Label
        q2_o                           matlab.ui.control.NumericEditField
        DeltayLabel                    matlab.ui.control.Label
        q1_o                           matlab.ui.control.NumericEditField
        DeltaxEditFieldLabel           matlab.ui.control.Label
        uipanel11                      matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        text28                         matlab.ui.control.Label
        popupmenu_Eql                  matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        LagrangeDir
        PhSpGeoDir
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function LAGRANGE_startup(app)
app.LagrangeDir = cd;
cd ..
cd ..
cd ..
app.PhSpGeoDir = cd;
cd(app.LagrangeDir);

if isdeployed
    % do nothing
else
    path([app.PhSpGeoDir,'/AddedODEsolver/bin'], path);
    path([app.PhSpGeoDir,'/Util'], path);
    path([app.PhSpGeoDir,'/Util/Multicore'], path);
    path([app.PhSpGeoDir,'/Portraits'], path);
    path([app.PhSpGeoDir,'/AddedODEsolver/dop853'], path);
    path([app.PhSpGeoDir,'/AddedODEsolver/BulirschStoer'], path);
    path([app.PhSpGeoDir,'/AddedODEsolver/ABM8'], path);
    path([app.PhSpGeoDir,'/AddedODEsolver/ShampineGordon'], path);    
    path([app.LagrangeDir,'/Help'], path);
    path([app.LagrangeDir,'/FunctionScript'], path);
    path(app.LagrangeDir, path);
end

app.Image.ImageSource = 'Lpoints.jpg';
app.pushbutton_Integrate.Icon = 'Integrate.jpg';
app.pushbutton_Calculate.Icon = 'PhSpGeo.jpg';
app.pushbutton_Slave.Icon = 'Schiavo.jpg';

%%%%%%%%%%%%%%%% Load what was saved %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
cd(app.LagrangeDir);
cd ..
if exist('Setting.mat','file')
    load('Setting.mat')                                                    %#ok<*LOAD>

    set(app.popupmenu_Eql,'Value',Eql);
    set(app.edit_mass,'Value',mass);
    set(app.edit_time,'Value',time);    
    set(app.q1_o,'Value',q1_o);
    set(app.q2_o,'Value',q2_o);
    set(app.q3_o,'Value',q3_o);
    set(app.p1_o,'Value',p1_o);
    set(app.p2_o,'Value',p2_o);
    set(app.p3_o,'Value',p3_o);

    set(app.popupmenuODE,'Value',ODE);
    set(app.RelTol,'Value',RelTol);
    set(app.AbsTol,'Value',AbsTol);
    set(app.step_ODE,'Value',stepODE);

    set(app.I1_in,'Value',I1_in);
    set(app.I1_fin,'Value',I1_fin);
    set(app.step_1,'Value',step_1);
    set(app.I2_in,'Value',I2_in);
    set(app.I2_fin,'Value',I2_fin);
    set(app.step_2,'Value',step_2);
    set(app.I3_o,'Value',I3_o);
    set(app.freq1_min,'Value',freq1_min);
    set(app.freq1_max,'Value',freq1_max);
    set(app.Step_FMI1,'Value',Step_FMI1);
    set(app.freq2_min,'Value',freq2_min);
    set(app.freq2_max,'Value',freq2_max);
    set(app.Step_FMI2,'Value',Step_FMI2);
    set(app.freq3_min,'Value',freq3_min);
    set(app.freq3_max,'Value',freq3_max);
    set(app.Step_FMI3,'Value',Step_FMI3);

    set(app.Percentage_of_RowColumn, 'Checked',View1);
    set(app.RowColumn_Completed, 'Checked',View2);
    set(app.Total_bars, 'Checked',View3);
    set(app.Wait_bars, 'Checked',View4);
    set(app.uipanel_FMI,'Visible',Visible);

    if get(app.step_1,'Value') == 0
        set(app.I1_fin,'Enable','off')
        set(app.leqI_1leqLabel,'Text','$=I_1$','Position',[84 34 50 22])
    end
    if get(app.step_2,'Value') == 0
        set(app.I2_fin,'Enable','off')
        set(app.leqI_2leqLabel,'Text','$=I_2$','Position',[84 34 50 22])
    end
end
cd(app.LagrangeDir);
 
%%%%%%%%%%%%%%% In case make Slave button visible %%%%%%%%%%%%%%%%%%%%%%%%
if ~isnan(findstr(app.LagrangeDir, 'Slave'))                               %#ok<*FSTR> 
    set(app.pushbutton_Slave, 'Visible', 'on')
    set(app.pushbutton_Calculate, 'Visible', 'off')

    set(app.popupmenu_Eql, 'Enable', 'off');
    set(app.edit_mass, 'Enable', 'off');
    set(app.edit_time, 'Enable', 'off');
    set(app.q1_o, 'Enable', 'off');
    set(app.q2_o, 'Enable', 'off');
    set(app.q3_o, 'Enable', 'off');
    set(app.p1_o, 'Enable', 'off');
    set(app.p2_o, 'Enable', 'off');
    set(app.p3_o, 'Enable', 'off');
    set(app.pushbutton_Integrate, 'Enable', 'off')
    
    set(app.popupmenuODE, 'Enable', 'off');
    set(app.RelTol, 'Enable', 'off');
    set(app.AbsTol, 'Enable', 'off');
    set(app.step_ODE, 'Enable', 'off');

    set(app.I1_in, 'Enable', 'off');
    set(app.I1_fin, 'Enable', 'off');
    set(app.step_1, 'Enable', 'off');
    set(app.I2_in, 'Enable', 'off');
    set(app.I2_fin, 'Enable', 'off');
    set(app.step_2, 'Enable', 'off');
    set(app.I3_o, 'Enable', 'off');
    set(app.freq1_min, 'Enable', 'off');
    set(app.freq1_max, 'Enable', 'off');
    set(app.Step_FMI1, 'Enable', 'off');
    set(app.freq2_min, 'Enable', 'off');
    set(app.freq2_max, 'Enable', 'off');
    set(app.Step_FMI2, 'Enable', 'off');
    set(app.freq3_min, 'Enable', 'off');
    set(app.freq3_max, 'Enable', 'off');
    set(app.Step_FMI3, 'Enable', 'off');
  
else
    cd ..
    if exist('multicorefiles','dir')
        rmdir('multicorefiles','s')
    end
    mkdir('multicorefiles')
    cd(app.LagrangeDir)
end
             
%%%%%%%%%%%%%%%%%%%%%% Set Name %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
list = strread(cd, '%s','delimiter',strrep(filesep,'\','\\'));             %#ok<*DSTRRD> 
dim = size(list);
curdir = [list{dim(1,1), dim(1,2)},' - ',list{dim(1,1) - 1, dim(1,2)}];
set(app.LAGRANGEUIFigure, 'Name', curdir)

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        end

        % Value changed function: popupmenu_Eql
        function popupmenu_Eql_choose(app, event)
item = str2num(get(app.popupmenu_Eql,'Value'));
if item == 1
    set(app.uipanel_FMI,'Visible','off');
elseif item == 2
    set(app.uipanel_FMI,'Visible','off');
elseif item == 3
    set(app.uipanel_FMI,'Visible','off');
elseif item == 4;
    set(app.uipanel_FMI,'Visible','on');
elseif item == 5;
    set(app.uipanel_FMI,'Visible','off');
end
Disable_all   
        end

        % Button pushed function: pushbutton_Integrate
        function exec_Integrate(app, event)
global mu pick time Eql Xo X tau
mu =  str2num(get(app.edit_mass,'Value'));
time = get(app.edit_time,'Value');
opts = optimset('Display','off','TolX',eps);
item = str2num(get(app.popupmenu_Eql,'Value'));
if item == 1
    f = @(x)x + (1-mu)/(x-mu)^2 - mu/(x-mu+1)^2;  
    xeq = fzero(f, mu-1/2, opts);
    Eql = [xeq  0  0  0  xeq  0];
    set(app.uipanel_FMI,'Visible','off');
elseif item == 2
    f = @(x)x + (1-mu)/(x-mu)^2 + mu/(x-mu+1)^2;
    xeq = fzero(f, 1.1*(mu-1), opts);
    Eql = [xeq  0  0  0  xeq  0];
    set(app.uipanel_FMI,'Visible','off');
elseif item == 3
    f = @(x)x - (1-mu)/(x-mu)^2 - mu/(x-mu+1)^2;
    xeq = fzero(f, 1.1*mu, opts);
    Eql = [xeq  0  0  0  xeq  0];
    set(app.uipanel_FMI,'Visible','off');
elseif item == 4;
    Eql = [mu-1/2   sqrt(3)/2  0  -sqrt(3)/2  mu-1/2  0];
    set(app.uipanel_FMI,'Visible','on');
elseif item == 5;
    Eql = [mu-1/2  -sqrt(3)/2  0   sqrt(3)/2  mu-1/2  0];
    set(app.uipanel_FMI,'Visible','off');
end

deltax = get(app.q1_o,'Value');
deltay = get(app.q2_o,'Value');
deltaz = get(app.q3_o,'Value');
vx =     get(app.p1_o,'Value');
vy =     get(app.p2_o,'Value');
vz =     get(app.p3_o,'Value');

q1 = Eql(1) + deltax;
q2 = Eql(2) + deltay;
q3 = Eql(3) + deltaz;
p1 = Eql(4) - deltay + vx;
p2 = Eql(5) + deltax + vy;
p3 = Eql(6) + vz;

ODE = get(app.popupmenuODE,'Value');

hhh = waitbar(0,'Please wait..., LAGRANGE is calculating.'); %%%%%     waitbar
pick = -time/100;                                            %%%%%     waitbar

Rel_Tol =   get(app.RelTol,'Value');
Abs_Tol =   get(app.AbsTol,'Value');
OutStep =   get(app.step_ODE,'Value');
Xo = [q1 q2 q3 p1 p2 p3];
tic
%{
if strcmp(ODE, 'BulirschStoer')
    tau = [0 : OutStep : time];
    [X,info] = BulirschStoer(@DiffEquation, tau, [q1; q2; q3; p1; p2; p3], Rel_Tol);
elseif strcmp(ODE, 'ABM8')
    n_eq = 6;
    y_0 = [q1; q2; q3; p1; p2; p3];
    h = OutStep;
    t_end = time;
    driver_ABM8
elseif strcmp(ODE, 'ShampineGordon')
    if Abs_Tol < 1e-7
        warndlg('The Absolute Tolerance must be greater than or equal to 1e-7')
        return
    end
    tot_time = time;
    n_eq = 6;
    y_init = [q1; q2; q3; p1; p2; p3];
    driver_ShampineGordon
else
%}
    if strcmp(ODE,'BulirschStoer')
        options = Rel_Tol;
    else
        options = odeset('RelTol',[Rel_Tol], 'AbsTol',[Abs_Tol]);
    end
    if (strcmp(ODE,'ShampineGordon') & Abs_Tol < 1e-7)
        warndlg('The Absolute Tolerance for ShampineGordon method must be greater than or equal to 1e-7')
        return
    end
    [tau,X] = eval([ODE, '(@DiffEquation, [0 :',num2str(OutStep),': time], [q1; q2; q3; p1; p2; p3; 0; 0], options)']);
    X = X';
%end
toc
set(app.pushbutton_Orbits, 'Enable', 'on')
set(app.pushbutton_error, 'Enable', 'on')
if item == 4;
    set(app.pushbutton_AA, 'Enable', 'on')
end
close(hhh);           
        end

        % Button pushed function: pushbutton_Orbits
        function plot_Orbits(app, event)
global X mu Eql

figure
hold on
plot3(X(1,:),X(2,:),X(3,:));
plot3(mu-1,0,0,'-mo','MarkerFaceColor',[0.6  0.6  0.6],'MarkerSize',20*(mu)^(1/3))
plot3(mu,0,0,'-mo','MarkerFaceColor','b','MarkerSize',20*(1-mu)^(1/3))
plot3(Eql(1), Eql(2), 0, '-*r', 'MarkerSize',14);
grid off
axis equal
title('Space trajectory','FontSize',14)
xlabel('\itx', 'FontSize',14)
ylabel('\ity', 'FontSize',14)
zlabel('\itz', 'FontSize',14)

figure
plot3(X(4,:)+X(2), X(5,:)-X(1), X(6,:));
title('Relative velocity trajectory','FontSize',14)
xlabel('\itv_x', 'FontSize',14)
ylabel('\itv_y', 'FontSize',14)
zlabel('\itv_z', 'FontSize',14)            
        end

        % Button pushed function: pushbutton_AA
        function plot_AA(app, event)
global mu X tau
Y = [X(1,:)-mu+1/2; X(2,:)-sqrt(3)/2; X(3,:); X(4,:)+sqrt(3)/2; X(5,:)-mu+1/2; X(6,:)];
diagH
x = inv(T)*Y;

q1 = x(1,:) + x(2,:);   p1 = i*(x(1,:) - x(2,:));

I1 = 1/2*real(q1.*q1 + p1.*p1);
figure
plot(tau,I1)
title('First Action','FontSize',14)
xlabel('\ittime', 'FontSize',14)
ylabel('\itI_1', 'FontSize',14)

phi1 = -180/pi*atan2(real(p1),real(q1));
figure
plot(tau,phi1)
title('First Angle','FontSize',14)
xlabel('\ittime', 'FontSize',14)
ylabel('\it\phi_1 \rm(degrees)', 'FontSize',14)

q2 = x(3,:) + x(4,:);   p2 = i*(x(3,:) - x(4,:));

I2 = 1/2*real(q2.*q2 + p2.*p2);
figure
plot(tau,I2)
title('Second Action','FontSize',14)
xlabel('\ittime', 'FontSize',14)
ylabel('\itI_2', 'FontSize',14)

phi2 = 180/pi*atan2(real(p2),real(q2));
figure
plot(tau,phi2)
title('Second Angle','FontSize',14)
xlabel('\ittime', 'FontSize',14)
ylabel('\it\phi_2 \rm(degrees)', 'FontSize',14)

q3 = x(5,:) + x(6,:);   p3 = i*(x(5,:) - x(6,:));

I3 = 1/2*real(q3.*q3 + p3.*p3);
figure
plot(tau,I3)
title('Third Action','FontSize',14)
xlabel('\ittime', 'FontSize',14)
ylabel('\itI_3', 'FontSize',14)

phi3 = -180/pi*atan2(real(p3),real(q3));
figure
plot(tau,phi3)
title('Third Angle','FontSize',14)
xlabel('\ittime', 'FontSize',14)
ylabel('\it\phi_3 \rm(degrees)', 'FontSize',14)            
        end

        % Button pushed function: pushbutton_error
        function plot_error(app, event)
            global mu X Xo tau
            Error = Hamiltonian(X) - Hamiltonian_o(Xo);
            figure
            plot(tau,Error)
        end

        % Button pushed function: pushbutton_Calculate
        function exec_CalculateFMI(app, event)
LagrangeDir = app.LagrangeDir;
cd(LagrangeDir)
Disable_all
set(app.pushbutton_Calculate,'Enable','off')

Eql =         get(app.popupmenu_Eql,'Value');
mu =          str2num(get(app.edit_mass,'Value'));
total_time =  get(app.edit_time,'Value');
q1_o =        get(app.q1_o, 'Value');
q2_o =        get(app.q2_o, 'Value');
q3_o =        get(app.q3_o, 'Value');
p1_o =        get(app.p1_o, 'Value');
p2_o =        get(app.p2_o, 'Value');
p3_o =        get(app.p3_o, 'Value');

ODE =         get(app.popupmenuODE,'Value');
RelTol =      get(app.RelTol,'Value');
AbsTol =      get(app.AbsTol,'Value');
OutStep =     get(app.step_ODE,'Value');

I1_in =      get(app.I1_in,'Value');
I1_fin =     get(app.I1_fin,'Value');
numstep_I1 = get(app.step_1,'Value');
I2_in =      get(app.I2_in,'Value');
I2_fin =     get(app.I2_fin,'Value');
numstep_I2 = get(app.step_2,'Value');
I3_o =       get(app.I3_o,'Value');

freq1_min =  get(app.freq1_min,'Value');
freq1_max =  get(app.freq1_max,'Value');
Step_FMI1 =  get(app.Step_FMI1,'Value');
freq2_min =  get(app.freq2_min,'Value');
freq2_max =  get(app.freq2_max,'Value');
Step_FMI2 =  get(app.Step_FMI2,'Value');
freq3_min =  get(app.freq3_min,'Value');
freq3_max =  get(app.freq3_max,'Value');
Step_FMI3 =  get(app.Step_FMI3,'Value');

View1 =  get(app.Percentage_of_RowColumn, 'Checked');
View2 =  get(app.RowColumn_Completed, 'Checked');
View3 =  get(app.Total_bars, 'Checked');
View4 =  get(app.Wait_bars, 'Checked');

Init_Data ={Eql,mu,total_time,q1_o,q2_o,q3_o,p1_o,p2_o,p3_o, ...
               ODE,RelTol,AbsTol,OutStep, ...
                   I1_in,I1_fin,numstep_I1,I2_in,I2_fin,numstep_I2,I3_o, ...
                       freq1_min,freq1_max,Step_FMI1,freq2_min,freq2_max,Step_FMI2,freq3_min,freq3_max,Step_FMI3, ...
                           View1,View2,View3,View4};

row_tot = numstep_I2 + 1;
col_tot = numstep_I1 + 1;
ext_tot = max(row_tot, col_tot);
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
begin = datestr(now, 'dd mmm HH:MM');
if ~isnan(findstr(LagrangeDir, 'Slave'))
% do nothing
else
    Totalbar = waitbar(0,'Please wait..., Lagrange is calculating.',...
        'Units', 'pixel', 'Position', [392 570 380 70]);
end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
display('                                                                 ')
display('###################################################################')
display(['    STARTING COMPUTATION with ',ODE,'!!!'])
disp('    ')
tic
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%---------------------- Start multicore -----------------------------------
parameterCell = cell(1,ext_tot);
subcell = cell(1,4);
for ext = 1:ext_tot
    subcell{1,1} = [ext];
    subcell{1,2} = [Init_Data];
    subcell{1,3} = [Totalbar];
    subcell{1,4} = [begin];      
    parameterCell{1,ext} = {subcell};
end

cd ..
fid = fopen('WaitBar.txt','w');
fwrite(fid,'0');
fclose(fid);
cd(LagrangeDir)

warning('off')
settings.multicoreDir = '../multicorefiles';
settings.useWaitbar = false;
settings.nrOfEvalsAtOnce = 1;
resultCell = startmulticoremaster(@paralfor, parameterCell, settings);
warning('on')
%---------------------- End multicore -----------------------------------

freq_1 = [resultCell{1,1}(1,:)];
for k = 2 : ext_tot
    freq_1 = [freq_1; resultCell{1,k}(1,:)];
end
freq_2 = [resultCell{1,1}(2,:)];
for k = 2 : ext_tot
    freq_2 = [freq_2; resultCell{1,k}(2,:)];
end
freq_3 = [resultCell{1,1}(3,:)];
for k = 2 : ext_tot
    freq_3 = [freq_3; resultCell{1,k}(3,:)];
end
FMI_1 = [resultCell{1,1}(4,:)];
for k = 2 : ext_tot
    FMI_1 = [FMI_1; resultCell{1,k}(4,:)];
end
FMI_2 = [resultCell{1,1}(5,:)];
for k = 2 : ext_tot
    FMI_2 = [FMI_2; resultCell{1,k}(5,:)];
end
FMI_3 = [resultCell{1,1}(6,:)];
for k = 2 : ext_tot
    FMI_3 = [FMI_3; resultCell{1,k}(6,:)];
end

if row_tot <= col_tot
    freq_1 = freq_1';
    freq_2 = freq_2';
    freq_3 = freq_3';
    FMI_1 = FMI_1';
    FMI_2 = FMI_2';
    FMI_3 = FMI_3';
end
                   
%%%%%%%%%%%%%%%%%%%%% Save to file %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    save freq1.dat freq_1 -ascii
    save freq2.dat freq_2 -ascii
    save freq3.dat freq_3 -ascii
    save FMI1.dat FMI_1 -ascii
    save FMI2.dat FMI_2 -ascii
    save FMI3.dat FMI_3 -ascii
    delete *.txt
%%%%%%%%%%%%%%%%%%%%% Enable buttons %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
set(app.pushbutton_Calculate,'Enable','on')
set(app.pushbutton_FMI, 'Enable', 'on')
set(app.pushbutton_freq, 'Enable', 'on')
if (numstep_I1 ~= 0) & (numstep_I2 ~= 0)
    set(app.pushbutton_level, 'Enable', 'on')
end
display('-------------------------------------------------------------------')
display(['            FINISH with ',ODE,'!!!'])
toc
display('###################################################################')            
        end

        % Button pushed function: pushbutton_Slave
        function exec_Start_Slave(app, event)
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
list = strread(cd, '%s','delimiter',strrep(filesep,'\','\\'));             %#ok<*DSTRRD> 
dim = size(list);
Slave = list{dim(1,1), dim(1,2)};
set(app.pushbutton_Slave, 'Enable', 'off', 'Text', [Slave,' is running'], ...
      'FontSize', 12, 'Icon', 'Runner.jpg', 'Position', [66,75,152,52])
startmulticoreslave('../multicorefiles')
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                
        end

        % Button pushed function: pushbutton_FMI
        function plot_FMI(app, event)
load FMI1.dat
load FMI2.dat
load FMI3.dat

I1_in =      get(app.I1_in,'Value');
I1_fin =     get(app.I1_fin,'Value');
numstep_I1 = get(app.step_1,'Value');
I2_in =      get(app.I2_in,'Value');
I2_fin =     get(app.I2_fin,'Value');
numstep_I2 = get(app.step_2,'Value');
I1 = I1_in : (I1_fin - I1_in)/numstep_I1 : I1_fin;
I2 = I2_in : (I2_fin - I2_in)/numstep_I2 : I2_fin;


if (numstep_I1 == 0) & (numstep_I2 > 0)
    if get(app.Step_FMI1,'Value') > 0;
        figure
        plot(I2, FMI1(:,:))
        title(['FMI 1 \itvs \itI_2 \rmwith \itI_1 = ', num2str(get(app.I1_in,'Value'))], 'FontSize',12)
        xlabel('\itI_2', 'FontSize',14), ylabel('FMI 1', 'FontSize',14)
    end

    if get(app.Step_FMI2,'Value') > 0;
        figure
        plot(I2, FMI2(:,:))
        title(['FMI 2 \itvs \itI_2 \rmwith \itI_1 = ', num2str(get(app.I1_in,'Value'))], 'FontSize',12)
        xlabel('\itI_2', 'FontSize',14), ylabel('FMI 2', 'FontSize',14)
    end

    if get(app.Step_FMI3,'Value') > 0;
        figure
        plot(I2, FMI3(:,:))
        title(['FMI 3 \itvs \itI_2 \rmwith \itI_1 = ', num2str(get(app.I1_in,'Value'))], 'FontSize',12)
        xlabel('\itI_2', 'FontSize',14), ylabel('FMI 3', 'FontSize',14)
    end
elseif (numstep_I2 == 0) & (numstep_I1 > 0)
    if get(app.Step_FMI1,'Value') > 0;
        figure
        plot(I1, FMI1(:,:))
        title(['FMI 1 \itvs \itI_1 \rmwith \itI_2 = ', num2str(get(app.I2_in,'Value'))], 'FontSize',12)
        xlabel('\itI_1', 'FontSize',14), ylabel('FMI 1', 'FontSize',14)
    end

    if get(app.Step_FMI2,'Value') > 0;
        figure
        plot(I1, FMI2(:,:))
        title(['FMI 2 \itvs \itI_1 \rmwith \itI_2 = ', num2str(get(app.I2_in,'Value'))], 'FontSize',12)
        xlabel('\itI_1', 'FontSize',14), ylabel('FMI 2', 'FontSize',14)
    end

    if get(app.Step_FMI3,'Value') > 0;
        figure
        plot(I1, FMI3(:,:))
        title(['FMI 3 \itvs \itI_1 \rmwith \itI_2 = ', num2str(get(app.I2_in,'Value'))], 'FontSize',12)
        xlabel('\itI_1', 'FontSize',14), ylabel('FMI 3', 'FontSize',14)
    end
else (numstep_I1 > 0) & (numstep_I2 > 0);
    if get(app.Step_FMI1,'Value') > 0;
        figure
        colormap(gray)
        surf(I1, I2, FMI1, 'FaceColor', 'interp', 'LineStyle', 'none');
        view(2)
        xlabel('\itI_1', 'FontSize',14), ylabel('\itI_2', 'FontSize',14)
        title({'FMI 1'}, 'FontSize',14)
    end

    if get(app.Step_FMI2,'Value') > 0;
        figure
        colormap(gray)
        surf(I1, I2, FMI2, 'FaceColor', 'interp', 'LineStyle', 'none');
        view(2)
        xlabel('\itI_1', 'FontSize',14), ylabel('\itI_2', 'FontSize',14)
        title({'FMI 2'}, 'FontSize',14)
    end

    if get(app.Step_FMI3,'Value') > 0;
        figure
        colormap(gray)
        surf(I1, I2, FMI3, 'FaceColor', 'interp', 'LineStyle', 'none');
        view(2)
        xlabel('\itI_1', 'FontSize',14), ylabel('\itI_2', 'FontSize',14)
        title({'FMI 3'}, 'FontSize',14)
    end
end          
        end

        % Button pushed function: pushbutton_freq
        function plot_Frequency(app, event)
load freq1.dat
load freq2.dat
load freq3.dat

I1_in =      get(app.I1_in,'Value');
I1_fin =     get(app.I1_fin,'Value');
numstep_I1 = get(app.step_1,'Value');
I2_in =      get(app.I2_in,'Value');
I2_fin =     get(app.I2_fin,'Value');
numstep_I2 = get(app.step_2,'Value');
I1 = I1_in : (I1_fin - I1_in)/numstep_I1 : I1_fin;
I2 = I2_in : (I2_fin - I2_in)/numstep_I2 : I2_fin;

if (numstep_I1 == 0) & (numstep_I2 > 0)
    figure
    hold on
    plot(I2, freq1(:,:),'r')
    plot(I2, freq2(:,:),'k')
%    plot(I2, freq3(:,:),'b')
    legend('Frequency 1','Frequency 2')
    hold off
    title(['Frequencies \times1000 \itvs \itI_2 \rmwith \itI_1 = \rm',num2str(get(app.I1_in,'Value'))],'FontSize',12)
    xlabel('\itI_2', 'FontSize',14), ylabel('Frequencies', 'FontSize',14)
elseif (numstep_I2 == 0) & (numstep_I1 > 0)
    figure
    hold on
    plot(I1, freq1(:,:),'r')
    plot(I1, freq2(:,:),'k')
%    plot(I1, freq3(:,:),'b')
    legend('Frequency 1','Frequency 2')
    hold off
    title(['Frequencies \times1000 \itvs \itI_1 \rmwith \itI_2 = \rm',num2str(get(app.I2_in,'Value'))],'FontSize',12)
    xlabel('\itI_1', 'FontSize',14), ylabel('Frequencies', 'FontSize',14)
else (numstep_I1 > 0) & (numstep_I2 > 0);
    figure
    colormap(gray)
    surfc(I1, I2, freq1, 'FaceColor', 'interp', 'LineStyle', ':');
    xlabel('\itI_1', 'FontSize',14), ylabel('\itI_2', 'FontSize',14)
    title('Frequency 1', 'FontSize',14)

    figure
    colormap(gray)
    surfc(I1, I2, freq2, 'FaceColor', 'interp', 'LineStyle', ':');
    xlabel('\itI_1', 'FontSize',14), ylabel('\itI_2', 'FontSize',14)
    title('Frequency 2', 'FontSize',14)
%{
    figure
    colormap(gray)
    surfc(I1, I2, freq3, 'FaceColor', 'interp', 'LineStyle', ':');
    xlabel('\itI_1', 'FontSize',14), ylabel('\itI_2', 'FontSize',14)
    title('Frequency 3', 'FontSize',14)
%}      
end             
        end

        % Button pushed function: pushbutton_freq
        function plot_frequency(app, event)
load freq1.dat
load freq2.dat
load freq3.dat

I1_in =      get(app.I1_in,'Value');
I1_fin =     get(app.I1_fin,'Value');
numstep_I1 = get(app.step_1,'Value');
I2_in =      get(app.I2_in,'Value');
I2_fin =     get(app.I2_fin,'Value');
numstep_I2 = get(app.step_2,'Value');
I1 = I1_in : (I1_fin - I1_in)/numstep_I1 : I1_fin;
I2 = I2_in : (I2_fin - I2_in)/numstep_I2 : I2_fin;

if (numstep_I1 == 0) & (numstep_I2 > 0)
    figure
    hold on
    plot(I2, freq1(:,:),'r')
    plot(I2, freq2(:,:),'k')
%    plot(I2, freq3(:,:),'b')
    legend('Frequency 1','Frequency 2')
    hold off
    title(['Frequencies \times1000 \itvs \itI_2 \rmwith \itI_1 = \rm',num2str(get(app.I1_in,'Value'))],'FontSize',12)
    xlabel('\itI_2', 'FontSize',14), ylabel('Frequencies', 'FontSize',14)
elseif (numstep_I2 == 0) & (numstep_I1 > 0)
    figure
    hold on
    plot(I1, freq1(:,:),'r')
    plot(I1, freq2(:,:),'k')
%    plot(I1, freq3(:,:),'b')
    legend('Frequency 1','Frequency 2')
    hold off
    title(['Frequencies \times1000 \itvs \itI_1 \rmwith \itI_2 = \rm',num2str(get(app.I2_in,'Value'))],'FontSize',12)
    xlabel('\itI_1', 'FontSize',14), ylabel('Frequencies', 'FontSize',14)
else (numstep_I1 > 0) & (numstep_I2 > 0);
    figure
    colormap(gray)
    surfc(I1, I2, freq1, 'FaceColor', 'interp', 'LineStyle', ':');
    xlabel('\itI_1', 'FontSize',14), ylabel('\itI_2', 'FontSize',14)
    title('Frequency 1', 'FontSize',14)

    figure
    colormap(gray)
    surfc(I1, I2, freq2, 'FaceColor', 'interp', 'LineStyle', ':');
    xlabel('\itI_1', 'FontSize',14), ylabel('\itI_2', 'FontSize',14)
    title('Frequency 2', 'FontSize',14)
%{
    figure
    colormap(gray)
    surfc(I1, I2, freq3, 'FaceColor', 'interp', 'LineStyle', ':');
    xlabel('\itI_1', 'FontSize',14), ylabel('\itI_2', 'FontSize',14)
    title('Frequency 3', 'FontSize',14)
%}      
end             
        end

        % Button pushed function: pushbutton_level
        function plot_frequency_level(app, event)
load freq1.dat
load freq2.dat
load freq3.dat

I1_in =      get(app.I1_in,'Value');
I1_fin =     get(app.I1_fin,'Value');
numstep_I1 = get(app.step_1,'Value');
I2_in =      get(app.I2_in,'Value');
I2_fin =     get(app.I2_fin,'Value');
numstep_I2 = get(app.step_2,'Value');
I1 = I1_in : (I1_fin - I1_in)/numstep_I1 : I1_fin;
I2 = I2_in : (I2_fin - I2_in)/numstep_I2 : I2_fin;

figure
[C,h] = contour(I1,I2, freq1);
clabel(C,h);
xlabel('\itI_1', 'FontSize',14), ylabel('\itI_2', 'FontSize',14)
title('Frequency 1', 'FontSize',14)

figure
[C,h] = contour(I1,I2, freq2);
clabel(C,h);
xlabel('\itI_1', 'FontSize',14), ylabel('\itI_2', 'FontSize',14)
title('Frequency 2', 'FontSize',14)

%{
figure
[C,h] = contour(I1,I2, freq3);
clabel(C,h);
xlabel('\itI_1', 'FontSize',14), ylabel('\itI_2', 'FontSize',14)
title('Frequency 3', 'FontSize',14)
%}            
        end

        % Menu selected function: Save_now
        function Save_now_Menu(app, event)
InitStructure
cd(app.LagrangeDir);
cd ..        
save('Setting.mat', '-struct', 'InitStr')
cd(app.LagrangeDir);                  
        end

        % Menu selected function: Save_exit
        function Save_setting_Menu(app, event)
if strcmp(get(gcbo, 'Checked'),'on')
    set(gcbo, 'Checked','off');
else
    set(gcbo, 'Checked','on')
end            
        end

        % Menu selected function: Percentage_of_RowColumn
        function Percentage_of_RowColumnMenuSelected(app, event)
if strcmp(get(gcbo, 'Checked'),'on')
    set(gcbo, 'Checked','off');
else
    set(gcbo, 'Checked','on')
end                             
        end

        % Menu selected function: RowColumn_Completed
        function RowColumn_CompletedMenuSelected(app, event)
if strcmp(get(gcbo, 'Checked'),'on')
    set(gcbo, 'Checked','off');
else
    set(gcbo, 'Checked','on')
end                   
        end

        % Menu selected function: Total_bars
        function Total_barsMenuSelected(app, event)
if strcmp(get(gcbo, 'Checked'),'on')
    set(gcbo, 'Checked','off');
else
    set(gcbo, 'Checked','on')
end                  
        end

        % Menu selected function: Wait_bars
        function Wait_barsMenuSelected(app, event)
if strcmp(get(gcbo, 'Checked'),'on')
    set(gcbo, 'Checked','off');
else
    set(gcbo, 'Checked','on')
end                       
        end

        % Value changed function: edit_mass
        function edit_massValueChanged(app, event)
Disable_all
        end

        % Value changed function: edit_time
        function edit_timeValueChanged(app, event)
Disable_all
        end

        % Value changed function: q1_o
        function q1_oValueChanged(app, event)
Disable_all
        end

        % Value changed function: q2_o
        function q2_oValueChanged(app, event)
Disable_all
        end

        % Value changed function: q3_o
        function q3_oValueChanged(app, event)

        end

        % Value changed function: p1_o
        function p1_oValueChanged(app, event)
Disable_all
        end

        % Value changed function: p2_o
        function p2_oValueChanged(app, event)
Disable_all      
        end

        % Value changed function: p3_o
        function p3_oValueChanged(app, event)
Disable_all
        end

        % Value changed function: popupmenuODE
        function popupmenuODEValueChanged(app, event)
Disable_all
if strcmp(get(app.popupmenuODE,'Value'),'ShampineGordon')
    set(app.AbsTol,'Value', 1e-07);
else
    set(app.AbsTol,'Value', 1e-14);
end
        end

        % Value changed function: RelTol
        function RelTolValueChanged(app, event)
Disable_all
        end

        % Value changed function: AbsTol
        function AbsTolValueChanged(app, event)
Disable_all
        end

        % Value changed function: step_ODE
        function step_ODEValueChanged(app, event)
Disable_all
        end

        % Value changed function: I1_in
        function I1_inValueChanged(app, event)
Disable_all
        end

        % Value changed function: I1_fin
        function I1_finValueChanged(app, event)
Disable_all
        end

        % Value changed function: step_1
        function step_1ValueChanged(app, event)
Disable_all
        end

        % Value changed function: I2_in
        function I2_inValueChanged(app, event)
Disable_all
        end

        % Value changed function: I2_fin
        function I2_finValueChanged(app, event)
Disable_all
        end

        % Value changed function: step_2
        function step_2ValueChanged(app, event)
Disable_all 
        end

        % Value changed function: I3_o
        function I3_oValueChanged(app, event)
Disable_all
        end

        % Value changed function: freq1_min
        function freq1_minValueChanged(app, event)
Disable_all
        end

        % Value changed function: freq1_max
        function freq1_maxValueChanged(app, event)
Disable_all
        end

        % Value changed function: Step_FMI1
        function Step_FMI1ValueChanged(app, event)
Disable_all
        end

        % Value changed function: freq2_min
        function freq2_minValueChanged(app, event)
Disable_all    
        end

        % Value changed function: freq2_max
        function freq2_maxValueChanged(app, event)
Disable_all     
        end

        % Value changed function: Step_FMI2
        function Step_FMI2ValueChanged(app, event)
Disable_all
        end

        % Value changed function: freq3_min
        function freq3_minValueChanged(app, event)
Disable_all      
        end

        % Value changed function: freq3_max
        function freq3_maxValueChanged(app, event)
Disable_all  
        end

        % Value changed function: Step_FMI3
        function Step_FMI3ValueChanged(app, event)
Disable_all  
        end

        % Menu selected function: AboutLAGRANGEMenu
        function About_LAGRANGE(app, event)
          About_LAGRANGE  
        end

        % Menu selected function: HelpMenu_2
        function Help_menu(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
popupmessage('Help.txt', 'Lagrange Help');
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
        end

        % Close request function: LAGRANGEUIFigure
        function LAGRANGE_closing(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
if ~isnan(findstr(app.LagrangeDir, 'Slave'))
    %do nothing
else
    if strcmp(get(app.Save_exit, 'Checked'),'on')    
        InitStructure
        cd(app.LagrangeDir);
        cd ..        
        save('Setting.mat', '-struct', 'InitStr')
        cd(app.LagrangeDir);
    end
end
delete('*.dat')
delete(app)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LAGRANGEUIFigure = uifigure('Visible', 'off');
            app.LAGRANGEUIFigure.Name = 'LAGRANGE';
            app.LAGRANGEUIFigure.Icon = 'Lagrange.jpg';
            app.LAGRANGEUIFigure.CloseRequestFcn = createCallbackFcn(app, @LAGRANGE_closing, true);
            app.Savesetting = uimenu(app.LAGRANGEUIFigure);
            app.Savesetting.Text = 'Save setting';
            app.Save_exit = uimenu(app.Savesetting);
            app.Save_exit.MenuSelectedFcn = createCallbackFcn(app, @Save_setting_Menu, true);
            app.Save_exit.Checked = 'on';
            app.Save_exit.Text = 'Save setting on exit';
            app.Save_now = uimenu(app.Savesetting);
            app.Save_now.MenuSelectedFcn = createCallbackFcn(app, @Save_now_Menu, true);
            app.Save_now.Text = 'Save setting now';
            app.View = uimenu(app.LAGRANGEUIFigure);
            app.View.Text = '    View';
            app.Percentage_of_RowColumn = uimenu(app.View);
            app.Percentage_of_RowColumn.MenuSelectedFcn = createCallbackFcn(app, @Percentage_of_RowColumnMenuSelected, true);
            app.Percentage_of_RowColumn.Checked = 'on';
            app.Percentage_of_RowColumn.Text = 'Percentage of Row/Column';
            app.RowColumn_Completed = uimenu(app.View);
            app.RowColumn_Completed.MenuSelectedFcn = createCallbackFcn(app, @RowColumn_CompletedMenuSelected, true);
            app.RowColumn_Completed.Checked = 'on';
            app.RowColumn_Completed.Text = 'Row/Column Completed';
            app.Total_bars = uimenu(app.View);
            app.Total_bars.MenuSelectedFcn = createCallbackFcn(app, @Total_barsMenuSelected, true);
            app.Total_bars.Checked = 'on';
            app.Total_bars.Text = 'Total progress';
            app.Wait_bars = uimenu(app.View);
            app.Wait_bars.MenuSelectedFcn = createCallbackFcn(app, @Wait_barsMenuSelected, true);
            app.Wait_bars.Checked = 'on';
            app.Wait_bars.Text = 'Total progress waitbar';
            app.HelpMenu = uimenu(app.LAGRANGEUIFigure);
            app.HelpMenu.Text = '   Help';
            app.AboutLAGRANGEMenu = uimenu(app.HelpMenu);
            app.AboutLAGRANGEMenu.MenuSelectedFcn = createCallbackFcn(app, @About_LAGRANGE, true);
            app.AboutLAGRANGEMenu.Text = 'About LAGRANGE';
            app.HelpMenu_2 = uimenu(app.HelpMenu);
            app.HelpMenu_2.MenuSelectedFcn = createCallbackFcn(app, @Help_menu, true);
            app.HelpMenu_2.Text = 'Help';
            app.uipanel11 = uipanel(app.LAGRANGEUIFigure);
            app.uipanel11.Tag = 'uipanel11';
            app.popupmenu_Eql = uidropdown(app.uipanel11);
            app.popupmenu_Eql.Items = {' L1  (unstable)', ' L2  (unstable)', ' L3  (unstable)', ' L4  (stable)', ' L5  (stable)'};
            app.popupmenu_Eql.ItemsData = {'1', '2', '3', '4', '5'};
            app.popupmenu_Eql.ValueChangedFcn = createCallbackFcn(app, @popupmenu_Eql_choose, true);
            app.popupmenu_Eql.Tag = 'popupmenu_Eql';
            app.popupmenu_Eql.Value = '4';
            app.text28 = uilabel(app.uipanel11);
            app.text28.Tag = 'text28';
            app.text28.Text = 'Lagrangian Equilibrium Points';
            app.Image = uiimage(app.uipanel11);
            app.uipanel1 = uipanel(app.LAGRANGEUIFigure);
            app.uipanel1.Title = ' Single orbit analysis ';
            app.uipanel1.Tag = 'uipanel1';
            app.uipanel2 = uipanel(app.uipanel1);
            app.uipanel2.Title = 'Initial conditions';
            app.uipanel2.Tag = 'uipanel2';
            app.DeltaxEditFieldLabel = uilabel(app.uipanel2);
            app.DeltaxEditFieldLabel.Interpreter = 'latex';
            app.DeltaxEditFieldLabel.Text = '$\Delta x=$';
            app.q1_o = uieditfield(app.uipanel2, 'numeric');
            app.q1_o.ValueDisplayFormat = '%5.8g';
            app.q1_o.ValueChangedFcn = createCallbackFcn(app, @q1_oValueChanged, true);
            app.q1_o.Value = 0.002;
            app.DeltayLabel = uilabel(app.uipanel2);
            app.DeltayLabel.Interpreter = 'latex';
            app.DeltayLabel.Text = '$\Delta y=$';
            app.q2_o = uieditfield(app.uipanel2, 'numeric');
            app.q2_o.ValueChangedFcn = createCallbackFcn(app, @q2_oValueChanged, true);
            app.q2_o.Value = 0.002;
            app.DeltayLabel_2 = uilabel(app.uipanel2);
            app.DeltayLabel_2.Interpreter = 'latex';
            app.DeltayLabel_2.Text = '$\Delta z=$';
            app.q3_o = uieditfield(app.uipanel2, 'numeric');
            app.q3_o.ValueChangedFcn = createCallbackFcn(app, @q3_oValueChanged, true);
            app.v_xLabel = uilabel(app.uipanel2);
            app.v_xLabel.Interpreter = 'latex';
            app.v_xLabel.Text = '$v_x=$';
            app.p1_o = uieditfield(app.uipanel2, 'numeric');
            app.p1_o.ValueChangedFcn = createCallbackFcn(app, @p1_oValueChanged, true);
            app.v_xLabel_2 = uilabel(app.uipanel2);
            app.v_xLabel_2.Interpreter = 'latex';
            app.v_xLabel_2.Text = '$v_y=$';
            app.p2_o = uieditfield(app.uipanel2, 'numeric');
            app.p2_o.ValueChangedFcn = createCallbackFcn(app, @p2_oValueChanged, true);
            app.v_xLabel_3 = uilabel(app.uipanel2);
            app.v_xLabel_3.Interpreter = 'latex';
            app.v_xLabel_3.Text = '$v_z=$';
            app.p3_o = uieditfield(app.uipanel2, 'numeric');
            app.p3_o.ValueChangedFcn = createCallbackFcn(app, @p3_oValueChanged, true);
            app.pushbutton_Integrate = uibutton(app.uipanel1, 'push');
            app.pushbutton_Integrate.ButtonPushedFcn = createCallbackFcn(app, @exec_Integrate, true);
            app.pushbutton_Integrate.Tag = 'pushbutton_Integrate';
            app.pushbutton_Integrate.Text = {'Integrate'; 'and ...'};
            app.pushbutton_Orbits = uibutton(app.uipanel1, 'push');
            app.pushbutton_Orbits.ButtonPushedFcn = createCallbackFcn(app, @plot_Orbits, true);
            app.pushbutton_Orbits.Tag = 'pushbutton_Orbits';
            app.pushbutton_Orbits.Enable = 'off';
            app.pushbutton_Orbits.Text = 'Plot orbits';
            app.pushbutton_AA = uibutton(app.uipanel1, 'push');
            app.pushbutton_AA.ButtonPushedFcn = createCallbackFcn(app, @plot_AA, true);
            app.pushbutton_AA.Tag = 'pushbutton_AA';
            app.pushbutton_AA.Enable = 'off';
            app.pushbutton_AA.Text = 'Plot action-angle';
            app.MassmuLabel = uilabel(app.uipanel1);
            app.MassmuLabel.Interpreter = 'latex';
            app.MassmuLabel.Text = '$\mu=$';
            app.edit_mass = uieditfield(app.uipanel1, 'text');
            app.edit_mass.ValueChangedFcn = createCallbackFcn(app, @edit_massValueChanged, true);
            app.edit_mass.Value = '0.02';
            app.IntegrationtimeEditFieldLabel = uilabel(app.uipanel1);
            app.IntegrationtimeEditFieldLabel.Text = 'Integration time';
            app.edit_time = uieditfield(app.uipanel1, 'numeric');
            app.edit_time.ValueChangedFcn = createCallbackFcn(app, @edit_timeValueChanged, true);
            app.edit_time.Value = 100;
            app.MassLabel = uilabel(app.uipanel1);
            app.MassLabel.Text = 'Mass';
            app.pushbutton_error = uibutton(app.uipanel1, 'push');
            app.pushbutton_error.ButtonPushedFcn = createCallbackFcn(app, @plot_error, true);
            app.pushbutton_error.Enable = 'off';
            app.pushbutton_error.Text = 'Integration error';
            app.uipanel12 = uipanel(app.LAGRANGEUIFigure);
            app.uipanel12.Title = ' ODE solver ';
            app.uipanel12.Tag = 'uipanel12';
            app.popupmenuODE = uidropdown(app.uipanel12);
            app.popupmenuODE.Items = {'ode113', 'ode45', 'ode78', 'ode89', 'dop853', 'BulirschStoer', 'ABM8', 'ShampineGordon'};
            app.popupmenuODE.ValueChangedFcn = createCallbackFcn(app, @popupmenuODEValueChanged, true);
            app.popupmenuODE.Tag = 'popupmenuODE';
            app.popupmenuODE.Value = 'ode113';
            app.opt_ODE = uipanel(app.uipanel12);
            app.opt_ODE.Title = 'Options';
            app.opt_ODE.Tag = 'opt_ODE';
            app.RelativeTolEditFieldLabel = uilabel(app.opt_ODE);
            app.RelativeTolEditFieldLabel.Text = 'Relative Tol';
            app.RelTol = uieditfield(app.opt_ODE, 'numeric');
            app.RelTol.ValueChangedFcn = createCallbackFcn(app, @RelTolValueChanged, true);
            app.RelTol.Value = 1e-07;
            app.AbsoluteTolLabel = uilabel(app.opt_ODE);
            app.AbsoluteTolLabel.Text = 'Absolute Tol';
            app.AbsTol = uieditfield(app.opt_ODE, 'numeric');
            app.AbsTol.ValueChangedFcn = createCallbackFcn(app, @AbsTolValueChanged, true);
            app.AbsTol.Value = 1e-07;
            app.AbsoluteTolLabel_2 = uilabel(app.opt_ODE);
            app.AbsoluteTolLabel_2.Text = 'Output Step';
            app.step_ODE = uieditfield(app.opt_ODE, 'numeric');
            app.step_ODE.ValueChangedFcn = createCallbackFcn(app, @step_ODEValueChanged, true);
            app.step_ODE.Value = 0.01;
            app.uipanel_FMI = uipanel(app.LAGRANGEUIFigure);
            app.uipanel_FMI.Title = ' Frequency Modulation Indicator ';
            app.uipanel_FMI.Tag = 'uipanel_FMI';
            app.uipanel5 = uipanel(app.uipanel_FMI);
            app.uipanel5.Tag = 'uipanel5';
            app.text20 = uilabel(app.uipanel5);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Frequency 2';
            app.text21 = uilabel(app.uipanel5);
            app.text21.Tag = 'text21';
            app.text21.Text = {'Compute the first'; 'frequency between'};
            app.text22 = uilabel(app.uipanel5);
            app.text22.Tag = 'text22';
            app.text22.Text = 'and';
            app.text23 = uilabel(app.uipanel5);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Step number for F M I';
            app.freq2_min = uieditfield(app.uipanel5, 'numeric');
            app.freq2_min.ValueChangedFcn = createCallbackFcn(app, @freq2_minValueChanged, true);
            app.freq2_min.Value = -5;
            app.freq2_max = uieditfield(app.uipanel5, 'numeric');
            app.freq2_max.ValueChangedFcn = createCallbackFcn(app, @freq2_maxValueChanged, true);
            app.freq2_max.Value = 5;
            app.Step_FMI2 = uieditfield(app.uipanel5, 'numeric');
            app.Step_FMI2.ValueChangedFcn = createCallbackFcn(app, @Step_FMI2ValueChanged, true);
            app.uipanel6 = uipanel(app.uipanel_FMI);
            app.uipanel6.Tag = 'uipanel6';
            app.text24 = uilabel(app.uipanel6);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Frequency 3';
            app.text25 = uilabel(app.uipanel6);
            app.text25.Tag = 'text25';
            app.text25.Text = {'Compute the first'; 'frequency between'};
            app.text26 = uilabel(app.uipanel6);
            app.text26.Tag = 'text26';
            app.text26.Text = 'and';
            app.text27 = uilabel(app.uipanel6);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Step number for FM I';
            app.freq3_min = uieditfield(app.uipanel6, 'numeric');
            app.freq3_min.ValueChangedFcn = createCallbackFcn(app, @freq3_minValueChanged, true);
            app.freq3_min.Editable = 'off';
            app.freq3_min.Enable = 'off';
            app.freq3_min.Value = -5;
            app.freq3_max = uieditfield(app.uipanel6, 'numeric');
            app.freq3_max.ValueChangedFcn = createCallbackFcn(app, @freq3_maxValueChanged, true);
            app.freq3_max.Editable = 'off';
            app.freq3_max.Enable = 'off';
            app.freq3_max.Value = 5;
            app.Step_FMI3 = uieditfield(app.uipanel6, 'numeric');
            app.Step_FMI3.ValueChangedFcn = createCallbackFcn(app, @Step_FMI3ValueChanged, true);
            app.Step_FMI3.Editable = 'off';
            app.Step_FMI3.Enable = 'off';
            app.uipanel7 = uipanel(app.uipanel_FMI);
            app.uipanel7.Tag = 'uipanel7';
            app.text16 = uilabel(app.uipanel7);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Frequency 1';
            app.text17 = uilabel(app.uipanel7);
            app.text17.Tag = 'text17';
            app.text17.Text = {'Compute the first'; 'frequency between'};
            app.text18 = uilabel(app.uipanel7);
            app.text18.Tag = 'text18';
            app.text18.Text = 'and';
            app.text19 = uilabel(app.uipanel7);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Step number for FM I';
            app.freq1_min = uieditfield(app.uipanel7, 'numeric');
            app.freq1_min.ValueChangedFcn = createCallbackFcn(app, @freq1_minValueChanged, true);
            app.freq1_min.Value = -5;
            app.freq1_max = uieditfield(app.uipanel7, 'numeric');
            app.freq1_max.ValueChangedFcn = createCallbackFcn(app, @freq1_maxValueChanged, true);
            app.freq1_max.Value = 5;
            app.Step_FMI1 = uieditfield(app.uipanel7, 'numeric');
            app.Step_FMI1.ValueChangedFcn = createCallbackFcn(app, @Step_FMI1ValueChanged, true);
            app.Step_FMI1.Value = 6;
            app.pushbutton_Calculate = uibutton(app.uipanel_FMI, 'push');
            app.pushbutton_Calculate.ButtonPushedFcn = createCallbackFcn(app, @exec_CalculateFMI, true);
            app.pushbutton_Calculate.Tag = 'pushbutton_Calculate';
            app.pushbutton_Calculate.Text = 'Calculate ...';
            app.uipanel8 = uipanel(app.uipanel_FMI);
            app.uipanel8.Title = '... and plot';
            app.uipanel8.Tag = 'uipanel8';
            app.pushbutton_freq = uibutton(app.uipanel8, 'push');
            app.pushbutton_freq.ButtonPushedFcn = createCallbackFcn(app, @plot_frequency, true);
            app.pushbutton_freq.Tag = 'pushbutton_freq';
            app.pushbutton_freq.Enable = 'off';
            app.pushbutton_freq.Text = 'Frequencies';
            app.pushbutton_FMI = uibutton(app.uipanel8, 'push');
            app.pushbutton_FMI.ButtonPushedFcn = createCallbackFcn(app, @plot_FMI, true);
            app.pushbutton_FMI.Tag = 'pushbutton_FMI';
            app.pushbutton_FMI.Enable = 'off';
            app.pushbutton_FMI.Text = 'F M I';
            app.pushbutton_level = uibutton(app.uipanel8, 'push');
            app.pushbutton_level.ButtonPushedFcn = createCallbackFcn(app, @plot_frequency_level, true);
            app.pushbutton_level.Tag = 'pushbutton_level';
            app.pushbutton_level.Enable = 'off';
            app.pushbutton_level.Text = 'Frequencies (level lines)';
            app.uipanel9 = uipanel(app.uipanel_FMI);
            app.uipanel9.Tag = 'uipanel9';
            app.text14 = uilabel(app.uipanel9);
            app.text14.Tag = 'text14';
            app.text14.Text = 'with step number ';
            app.leqI_1leqLabel = uilabel(app.uipanel9);
            app.leqI_1leqLabel.Interpreter = 'latex';
            app.leqI_1leqLabel.Text = '$\leq I_1 \leq$';
            app.I1_in = uieditfield(app.uipanel9, 'numeric');
            app.I1_in.ValueChangedFcn = createCallbackFcn(app, @I1_inValueChanged, true);
            app.I1_in.Value = 0.002;
            app.I1_fin = uieditfield(app.uipanel9, 'numeric');
            app.I1_fin.ValueChangedFcn = createCallbackFcn(app, @I1_finValueChanged, true);
            app.I1_fin.Value = 0.004;
            app.step_1 = uieditfield(app.uipanel9, 'numeric');
            app.step_1.ValueChangedFcn = createCallbackFcn(app, @step_1ValueChanged, true);
            app.step_1.Value = 4;
            app.uipanel10 = uipanel(app.uipanel_FMI);
            app.uipanel10.Tag = 'uipanel10';
            app.text15 = uilabel(app.uipanel10);
            app.text15.Tag = 'text15';
            app.text15.Text = 'with step number';
            app.leqI_2leqLabel = uilabel(app.uipanel10);
            app.leqI_2leqLabel.Interpreter = 'latex';
            app.leqI_2leqLabel.Text = '$\leq I_2 \leq$';
            app.I2_in = uieditfield(app.uipanel10, 'numeric');
            app.I2_in.ValueChangedFcn = createCallbackFcn(app, @I2_inValueChanged, true);
            app.I2_in.Value = 0.002;
            app.I2_fin = uieditfield(app.uipanel10, 'numeric');
            app.I2_fin.ValueChangedFcn = createCallbackFcn(app, @I2_finValueChanged, true);
            app.I2_fin.Value = 0.004;
            app.step_2 = uieditfield(app.uipanel10, 'numeric');
            app.step_2.ValueChangedFcn = createCallbackFcn(app, @step_2ValueChanged, true);
            app.step_2.Value = 4;
            app.pushbutton_Slave = uibutton(app.uipanel_FMI, 'push');
            app.pushbutton_Slave.ButtonPushedFcn = createCallbackFcn(app, @exec_Start_Slave, true);
            app.pushbutton_Slave.Tag = 'pushbutton_StartSlave';
            app.pushbutton_Slave.Visible = 'off';
            app.pushbutton_Slave.Text = 'Start Slave';
            app.I3_o = uieditfield(app.uipanel_FMI, 'numeric');
            app.I3_o.ValueChangedFcn = createCallbackFcn(app, @I3_oValueChanged, true);
            app.I_3Label = uilabel(app.uipanel_FMI);
            app.I_3Label.Interpreter = 'latex';
            app.I_3Label.Text = '$I_3=$';
            % Show the figure after all components are created
            app.LAGRANGEUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lagrange_source

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LAGRANGEUIFigure)

            % Execute the startup function
            runStartupFcn(app, @LAGRANGE_startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LAGRANGEUIFigure)
        end
    end
end