classdef Poincare_source < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        POINCAREUIFigure               matlab.ui.Figure
        Save_setting                   matlab.ui.container.Menu
        Save_on_exit                   matlab.ui.container.Menu
        Save_now                       matlab.ui.container.Menu
        View                           matlab.ui.container.Menu
        Percentage_of_RowColumn        matlab.ui.container.Menu
        RowColumn_Completed            matlab.ui.container.Menu
        Total_bars                     matlab.ui.container.Menu
        Wait_bars                      matlab.ui.container.Menu
        Help                           matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        AboutPOINCAREMenu              matlab.ui.container.Menu
        FrequencyDemodulationPanel     matlab.ui.container.Panel
        LFA_x_2                        matlab.ui.control.NumericEditField
        LFA_x_1                        matlab.ui.control.NumericEditField
        x_2Label                       matlab.ui.control.Label
        x_1Label                       matlab.ui.control.Label
        StartFrequencyDemodulationButton  matlab.ui.control.Button
        StopFrequencyDemodulationButton  matlab.ui.control.Button
        FrequencyModulationIndicatorPanel  matlab.ui.container.Panel
        Frequency2Panel                matlab.ui.container.Panel
        step_FMI2                      matlab.ui.control.NumericEditField
        StepnumberforFMI2Label         matlab.ui.control.Label
        freq2_max                      matlab.ui.control.NumericEditField
        andLabel_2                     matlab.ui.control.Label
        freq2_min                      matlab.ui.control.NumericEditField
        ComputethefrequencybetweenLabel_2  matlab.ui.control.Label
        Frequency1Panel                matlab.ui.container.Panel
        step_FMI1                      matlab.ui.control.NumericEditField
        StepnumberforFMI1Label         matlab.ui.control.Label
        freq1_max                      matlab.ui.control.NumericEditField
        andLabel                       matlab.ui.control.Label
        freq1_min                      matlab.ui.control.NumericEditField
        ComputethefrequencybetweenLabel  matlab.ui.control.Label
        Panel_x2                       matlab.ui.container.Panel
        withstepnumberLabel_2          matlab.ui.control.Label
        step_2                         matlab.ui.control.NumericEditField
        x2_fin                         matlab.ui.control.NumericEditField
        leqx_2leqLabel                 matlab.ui.control.Label
        x2_in                          matlab.ui.control.NumericEditField
        Panel_x1                       matlab.ui.container.Panel
        x1_in                          matlab.ui.control.NumericEditField
        step_1                         matlab.ui.control.NumericEditField
        withstepnumberLabel            matlab.ui.control.Label
        leqx_1leqLabel                 matlab.ui.control.Label
        x1_fin                         matlab.ui.control.NumericEditField
        StartSlaveButton               matlab.ui.control.Button
        CalculateandplotArnoldwebButton  matlab.ui.control.Button
        MapPanel                       matlab.ui.container.Panel
        edit_m                         matlab.ui.control.NumericEditField
        edit_epsilon3                  matlab.ui.control.NumericEditField
        edit_epsilon2                  matlab.ui.control.NumericEditField
        edit_epsilon1                  matlab.ui.control.NumericEditField
        iteration_number               matlab.ui.control.NumericEditField
        IterationnumberEditFieldLabel  matlab.ui.control.Label
        mLabel                         matlab.ui.control.Label
        epsilon2Label                  matlab.ui.control.Label
        epsilon3Label                  matlab.ui.control.Label
        epsilon1Label                  matlab.ui.control.Label
        popupMAP                       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
PoincareDir
PhSpGeoDir   
    end
    
    methods (Access = private)               

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Startup_Function(app)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
app.PoincareDir = cd;
cd ..
cd ..
cd ..
app.PhSpGeoDir = cd;
cd(app.PoincareDir)

if isdeployed
    %do nothing
else
    path([app.PoincareDir,'/Help'],path);
    path([app.PoincareDir,'/Function'],path);
    path([app.PhSpGeoDir,'/Util'], path);
    path([app.PhSpGeoDir,'/Portraits'], path);
    path([app.PhSpGeoDir,'/Util/Multicore'], path);
    path(app.PoincareDir, path);
end

app.POINCAREUIFigure.Icon = 'Poincare2.jpg';
app.CalculateandplotArnoldwebButton.Icon = 'PhSpGeo.jpg';
app.StartSlaveButton.Icon = 'Schiavo.jpg';
app.StartFrequencyDemodulationButton.Icon = 'LFA2.jpg';
app.StopFrequencyDemodulationButton.Icon = 'LFA2.jpg';

%%%%%%%%%%%%%%%% Load what was saved %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
cd(app.PoincareDir)
cd ..
if exist('Setting.mat','file')
    load('Setting.mat')                                                    %#ok<*LOAD> 
    set(app.popupMAP,'Value',MAP);
    set(app.edit_epsilon1,'Value',epsilon1);
    set(app.edit_epsilon2,'Value',epsilon2);
    set(app.edit_epsilon3,'Value',epsilon3);
    set(app.edit_m,'Value',m);
    set(app.x1_in,'Value',x1_in);
    set(app.x1_fin,'Value',x1_fin);
    set(app.step_1,'Value',step_1);
    set(app.x2_in,'Value',x2_in);
    set(app.x2_fin,'Value',x2_fin);
    set(app.step_2,'Value',step_2);
    set(app.freq1_min,'Value',freq1_min);
    set(app.freq1_max,'Value',freq1_max);
    set(app.step_FMI1,'Value',Step_FMI1);
    set(app.freq2_min,'Value',freq2_min);
    set(app.freq2_max,'Value',freq2_max);
    set(app.step_FMI2,'Value',Step_FMI2);
    set(app.iteration_number,'Value',iteration);
    set(app.Percentage_of_RowColumn, 'Checked',View1);
    set(app.RowColumn_Completed, 'Checked',View2);
    set(app.Total_bars, 'Checked',View3);
    set(app.Wait_bars, 'Checked',View4);
end
cd(app.PoincareDir)

if ~isnan(findstr(app.PoincareDir, 'Slave'))
    % do nothing
else
    if strcmp(get(app.popupMAP,'Value'), 'APLE')
        set(app.edit_epsilon1,'Enable','on')
        set(app.edit_epsilon2,'Enable','off')            
        set(app.edit_epsilon3,'Enable','off')
        set(app.edit_m,'Enable','off')                       
    end                        
    if strcmp(get(app.popupMAP,'Value'), 'FGL')
        set(app.edit_epsilon1,'Enable','on')
        set(app.edit_epsilon2,'Enable','on')            
        set(app.edit_epsilon3,'Enable','on')            
        set(app.edit_m,'Enable','off')            
    end            
    if strcmp(get(app.popupMAP,'Value'), 'ROT')
        set(app.edit_epsilon1,'Enable','on')        
        set(app.edit_epsilon2,'Enable','on')             
        set(app.edit_epsilon3,'Enable','off')             
        set(app.edit_m,'Enable','off')             
    end            
    if strcmp(get(app.popupMAP,'Value'), 'FGLsteep')
        set(app.edit_epsilon1,'Enable','on')        
        set(app.edit_epsilon2,'Enable','off')             
        set(app.edit_epsilon3,'Enable','off')            
        set(app.edit_m,'Enable','on')             
    end
end
 
%%%%%%%%%%%%%%% Possibly make Slave button visible %%%%%%%%%%%%%%%%%%%%%%%%
if ~isnan(findstr(app.PoincareDir, 'Slave'))                               %#ok<*FSTR> 
    set(app.StartSlaveButton, 'Visible', 'on')
    set(app.CalculateandplotArnoldwebButton, 'Visible', 'off')

    set(app.popupMAP, 'Enable', 'off')

    set(app.edit_epsilon1, 'Editable', 'off')
    set(app.edit_epsilon2, 'Editable', 'off')
    set(app.edit_epsilon3, 'Editable', 'off')
    set(app.edit_m, 'Editable', 'off')    
    set(app.iteration_number, 'Enable', 'off')

    set(app.x1_in, 'Enable', 'off')
    set(app.x1_fin, 'Enable', 'off')
    set(app.step_1, 'Enable', 'off')
    set(app.x2_in, 'Enable', 'off')
    set(app.x2_fin, 'Enable', 'off')
    set(app.step_2, 'Enable', 'off')

    set(app.freq1_min, 'Enable', 'off')
    set(app.freq1_max, 'Enable', 'off')
    set(app.step_FMI1, 'Enable', 'off')
    set(app.freq2_min, 'Enable', 'off')
    set(app.freq2_max, 'Enable', 'off')
    set(app.step_FMI2, 'Enable', 'off')

    set(app.LFA_x_1, 'Enable', 'off')
    set(app.LFA_x_2, 'Enable', 'off')
    set(app.StartFrequencyDemodulationButton, 'Enable', 'off')
    set(app.StopFrequencyDemodulationButton, 'Visible', 'off')
else
    cd ..
    if exist('multicorefiles','dir')
        rmdir('multicorefiles','s')
    end
    mkdir('multicorefiles')
    cd(app.PoincareDir)
end
             
%%%%%%%%%%%%%%%%%%%%%% Set Name %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
list = strread(cd, '%s','delimiter',strrep(filesep,'\','\\'));             %#ok<*DSTRRD> 
dim = size(list);
curdir = [list{dim(1,1), dim(1,2)},' - ',list{dim(1,1) - 1, dim(1,2)}];
set(app.POINCAREUIFigure, 'Name', curdir)

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Callback function: MapPanel, popupMAP
        function popup_MAP_Value_changing(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
if strcmp(get(app.popupMAP,'Value'), 'APLE')
    set(app.edit_epsilon2,'Enable','off')
    set(app.edit_epsilon3,'Enable','off')
    set(app.edit_m,'Enable','off')
end
if strcmp(get(app.popupMAP,'Value'), 'FGL')
    set(app.edit_epsilon2,'Enable','on')
    set(app.edit_epsilon3,'Enable','on')
    set(app.edit_m,'Enable','off')
end
if strcmp(get(app.popupMAP,'Value'), 'ROT')
    set(app.edit_epsilon2,'Enable','on')
    set(app.edit_epsilon3,'Enable','off')
    set(app.edit_m,'Enable','off')
end
if strcmp(get(app.popupMAP,'Value'), 'FGLsteep')
    set(app.edit_epsilon2,'Enable','off')
    set(app.edit_epsilon3,'Enable','off')
    set(app.edit_m,'Enable','on')
end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Button pushed function: CalculateandplotArnoldwebButton
        function Calculate_and_plot_ButtonPushed(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
MAP =       get(app.popupMAP,'Value');                                     %#ok<*ADPROPLC> 
eps1 =      get(app.edit_epsilon1,'Value');
eps2 =      get(app.edit_epsilon2,'Value');
eps3 =      get(app.edit_epsilon3,'Value');
em =        get(app.edit_m,'Value');
tau_total = get(app.iteration_number,'Value');

x1_init =    get(app.x1_in,'Value');
x1_final =   get(app.x1_fin,'Value');
numstep_x1 = get(app.step_1,'Value');
x2_init =    get(app.x2_in,'Value');
x2_final =   get(app.x2_fin,'Value');
numstep_x2 = get(app.step_2,'Value');

min_f1 =   num2str(get(app.freq1_min,'Value'));
max_f1 =   num2str(get(app.freq1_max,'Value'));
n_step1 =  get(app.step_FMI1,'Value');
min_f2 =   num2str(1000*get(app.freq2_min,'Value'));
max_f2 =   num2str(1000*get(app.freq2_max,'Value'));
n_step2 =  get(app.step_FMI2,'Value');

View1 =  get(app.Percentage_of_RowColumn, 'Checked');
View2 =  get(app.RowColumn_Completed, 'Checked');
View3 =  get(app.Total_bars, 'Checked');
View4 =  get(app.Wait_bars, 'Checked');

Init_Data ={MAP, eps1, eps2, eps3, em, tau_total, ...
                x1_init, x1_final, numstep_x1, x2_init, x2_final, numstep_x2, ...               
                   min_f1, max_f1, n_step1, min_f2, max_f2, n_step2, ...
                   View1, View2, View3, View4};

row_tot = numstep_x2 + 1;
col_tot = numstep_x1 + 1;
ext_tot = max(row_tot, col_tot);

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
begin = datestr(now, 'dd mmm HH:MM');
if ~isnan(findstr(app.PoincareDir, 'Slave'))
    % do nothing                                                                     
else                                                                           % MY WAITBAR
    Totalbar = waitbar(0,'Please wait..., POINCARE is calculating.',...
        'Units', 'pixel', 'Position', [392 570 380 70]);
end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
disp('                                                                 ')
disp('###################################################################')
disp('    STARTING COMPUTATION !!!')
disp('    ')
tic
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%---------------------- Start multicore -----------------------------------
parameterCell = cell(1,ext_tot);
subcell = cell(1,4);
for ext = 1:ext_tot
    subcell{1,1} = [ext];                                                  %#ok<*NBRAK> 
    subcell{1,2} = [Init_Data];
    subcell{1,3} = [Totalbar];
    subcell{1,4} = [begin];    
    parameterCell{1,ext} = {subcell};
end

cd ..
fid = fopen('WaitBar.txt','w');
fwrite(fid,'0');
fclose(fid);
cd(app.PoincareDir)

warning('off')
settings.multicoreDir = '../multicorefiles';
settings.useWaitbar = false;
settings.nrOfEvalsAtOnce = 1;
resultCell = startmulticoremaster(@paralfor, parameterCell, settings);
warning('on')
%---------------------- End multicore -----------------------------------
FMI_1 = [resultCell{1,1}(1,:)];
for k = 2 : ext_tot
    FMI_1 = [FMI_1; resultCell{1,k}(1,:)];                                 %#ok<*AGROW> 
end
FMI_2 = [resultCell{1,1}(2,:)];
for k = 2 : ext_tot
    FMI_2 = [FMI_2; resultCell{1,k}(2,:)];
end

if row_tot <= col_tot
    FMI_1 = FMI_1';
    FMI_2 = FMI_2';
end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                    Save to file
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
save FMI1.dat FMI_1 -ascii
save FMI2.dat FMI_2 -ascii      
delete *.txt
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
x1 = x1_init : (x1_final - x1_init)/numstep_x1 : x1_final;
x2 = x2_init : (x2_final - x2_init)/numstep_x2 : x2_final;

if get(app.step_FMI1,'Value') > 0
    figure
    colormap(gray)
    surf(x1, x2, FMI_1, 'FaceColor', 'interp', 'LineStyle', 'none');
    view(2)
    xlabel('\itx_1', 'FontSize',14), ylabel('\itx_2', 'FontSize',14)
    title({'FMI 1'}, 'FontSize',14)
end

if get(app.step_FMI2,'Value') > 0
    figure
    colormap(gray)
    surf(x1, x2, FMI_2, 'FaceColor', 'interp', 'LineStyle', 'none');
    view(2)
    xlabel('\itx_1', 'FontSize',14), ylabel('\itx_2', 'FontSize',14)
    title({'FMI 2'}, 'FontSize',14)
end

disp('            FINISH !!!')
toc
disp('###################################################################')
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Button pushed function: StartSlaveButton
        function Start_Slave_ButtonPushed(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
list = strread(cd, '%s','delimiter',strrep(filesep,'\','\\'));             %#ok<*DSTRRD> 
dim = size(list);
Slave = list{dim(1,1), dim(1,2)};
set(app.StartSlaveButton, 'Enable', 'off', 'Text', [Slave,' is running'], ...
      'FontSize', 16, 'Icon', 'Runner.jpg', 'Position', [231 25 247 103])
startmulticoreslave('../multicorefiles')
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Button pushed function: StartFrequencyDemodulationButton
        function StartLFA_ButtonPushed(app, event)
set(app.StartFrequencyDemodulationButton,'Enable','off')
set(app.StopFrequencyDemodulationButton,'Enable','on')

cd(app.PoincareDir);

epsilon1 = get(app.edit_epsilon1,'Value');
epsilon2 = get(app.edit_epsilon2,'Value');
epsilon3 = get(app.edit_epsilon3,'Value');
m        = get(app.edit_m,'Value');

x1_o = get(app.LFA_x_1,'Value');
x2_o = get(app.LFA_x_2,'Value');
tau_total = get(app.iteration_number,'Value');
MAP = get(app.popupMAP,'Value');
ktime = 1;
W = zeros(tau_total,4);
Inpt = [x1_o  x2_o  0  0];
W(1,:) = Inpt;

while strcmp(get(app.StopFrequencyDemodulationButton,'Enable'),'on')
    for tau = 2:tau_total
        if strcmp(MAP, 'APLE')
            W(tau,:) = eval(['APLE','(Inpt,epsilon1)']);
        elseif strcmp(MAP, 'FGL')
            W(tau,:) = eval(['FGL','(Inpt,epsilon1,epsilon2,epsilon3)']);
        elseif strcmp(MAP, 'ROT')
            W(tau,:) = eval(['ROT','(Inpt,epsilon1,epsilon2)']);
        elseif strcmp(MAP, 'FGLsteep')
            W(tau,:) = eval(['FGLsteep','(Inpt,epsilon1,m)']);
        elseif strcmp(MAP, 'User')
            W(tau,:) = eval(['User','(Inpt,epsilon1)']);            
        end
        Inpt = W(tau,:);
    end    
    %%%%%%%%%%%%%%%%%% Write inputAct1.txt %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    Act1x = W(:,1).*cos(W(:,3));
    Act1y = W(:,1).*sin(W(:,3));
    tau = 1:tau_total;
    fid = fopen('inputAct1.txt', 'wt');
    fprintf(fid, '%14.10f %14.10f %14.10f\n',...
        [tau ; Act1x'; Act1y']);
    fclose(fid);
    %%%%%%%%%%%%%%%%%% Write inputAct2.txt %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    Act2x = W(:,2).*cos(W(:,4));
    Act2y = W(:,2).*sin(W(:,4));
    fid = fopen('inputAct2.txt', 'wt');
    fprintf(fid, '%14.10f %14.10f %14.10f\n',...
        [tau ; Act2x'; Act2y']);
    fclose(fid);
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    min_f = get(app.freq1_min,'Value');
    max_f = get(app.freq1_max,'Value');
    min_f = num2str(1000*min_f,'%6.12f');
    max_f = num2str(1000*max_f,'%6.12f');
    flag_FMFT = num2str(1);
    nfreq = num2str(1);
    data_sep = num2str(2*pi*100000*0.05*3.18303);  
    d = size(W(:,1),1);    
    ndata = num2str(2^(floor(log2(d))));
    cmd_string = ['!',app.PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...
        data_sep,' ',ndata,' ','<inputAct1.txt',' ','>FMFT_of_Act1.txt'];  
    eval(cmd_string) 
    freq1 = textread('FMFT_of_Act1.txt', '%f %*f %*f', 'headerlines', 4);  %#ok<*DTXTRD> 
    if numel(freq1)== 0
        warndlg('Some error occurred: the corresponding frequency value will be left blank.')
        freq1 = NaN;
        cd(app.PoincareDir)
        break
    end
    
    min_f = get(app.freq2_min,'Value');
    max_f = get(app.freq2_max,'Value');
    min_f = num2str(1000*min_f,'%6.12f');
    max_f = num2str(1000*max_f,'%6.12f');    
    cmd_string = ['!',app.PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...
        data_sep,' ',ndata,' ','<inputAct2.txt',' ','>FMFT_of_Act2.txt'];  
    eval(cmd_string) 
    freq2 = textread('FMFT_of_Act2.txt', '%f %*f %*f', 'headerlines', 4);
    if numel(freq2)== 0
        warndlg('Some error occurred: the corresponding frequency value will be left blank.')
        freq2 = NaN;
        cd(app.PoincareDir)
        break
    end
    pause(0.01)   
    tm = ktime*tau_total;
    figure(1);
    hold on
    plot(tm,freq1,'.r','MarkerSize',10)
    %plot(tm,freq1,'-r')
    title('Frequency 1')    
    xlabel('Iterations', 'FontSize',10)
    ylabel('Frequency \times 1000')
    tm = ktime*tau_total;
    
    figure(2);
    hold on
    plot(tm,freq2,'.r','MarkerSize',10)
    %plot(tm,freq2,'-r')
    title('Frequency 2')    
    xlabel('Iterations', 'FontSize',10)
    ylabel('Frequency \times 1000')    
    pause(0.1)       
    ktime = ktime + 1;   
end            
        end

        % Callback function: FrequencyDemodulationPanel, 
        % ...and 1 other component
        function StopLFA_ButtonPushed(app, event)
            set(app.StopFrequencyDemodulationButton,'Enable','off');
            set(app.StartFrequencyDemodulationButton,'Enable','on');
            delete *.txt
        end

        % Menu selected function: Save_on_exit
        function Save_on_exit_Menu(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
if strcmp(get(gcbo, 'Checked'),'on')
    set(gcbo, 'Checked','off');
else
    set(gcbo, 'Checked','on')
end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Menu selected function: Save_now
        function Save_now_Menu(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
InitStructure
cd(app.PoincareDir)
cd ..        
save('Setting.mat', '-struct', 'InitStr')                                  %#ok<*USENS> 
cd(app.PoincareDir)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Menu selected function: Percentage_of_RowColumn
        function View_percentage(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
if strcmp(get(gcbo, 'Checked'),'on')
    set(gcbo, 'Checked','off');
else
    set(gcbo, 'Checked','on')
end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Menu selected function: RowColumn_Completed
        function View_completed(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
if strcmp(get(gcbo, 'Checked'),'on')
    set(gcbo, 'Checked','off');
else
    set(gcbo, 'Checked','on')
end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
        end

        % Menu selected function: Total_bars
        function View_total_bars(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
if strcmp(get(gcbo, 'Checked'),'on')
    set(gcbo, 'Checked','off');
else
    set(gcbo, 'Checked','on')
end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
        end

        % Menu selected function: Wait_bars
        function View_wait_bars(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
if strcmp(get(gcbo, 'Checked'),'on')
    set(gcbo, 'Checked','off');
else
    set(gcbo, 'Checked','on')
end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
        end

        % Menu selected function: HelpMenu
        function Help_Menu(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
popupmessage('Help.txt', 'Poincare Help');
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Menu selected function: AboutPOINCAREMenu
        function About_POINCARE_Menu(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
About_POINCARE
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Close request function: POINCAREUIFigure
        function POINCARE_closing(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
if ~isnan(findstr(app.PoincareDir, 'Slave'))
    %do nothing
else
    if strcmp(get(app.Save_on_exit, 'Checked'),'on')    
        InitStructure        
        cd(app.PoincareDir)
        cd ..        
        save('Setting.mat', '-struct', 'InitStr')
        cd(app.PoincareDir)
    end
end
delete(app)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.POINCAREUIFigure = uifigure('Visible', 'off');
            app.POINCAREUIFigure.Name = 'POINCARE';
            app.POINCAREUIFigure.Icon = 'Poincare2.jpg';
            app.POINCAREUIFigure.CloseRequestFcn = createCallbackFcn(app, @POINCARE_closing, true);
            app.POINCAREUIFigure.Tag = 'POINCAREUIFigure';
            app.Save_setting = uimenu(app.POINCAREUIFigure);
            app.Save_setting.Text = 'Save setting';
            app.Save_on_exit = uimenu(app.Save_setting);
            app.Save_on_exit.MenuSelectedFcn = createCallbackFcn(app, @Save_on_exit_Menu, true);
            app.Save_on_exit.Checked = 'on';
            app.Save_on_exit.Text = 'Save setting on exit';
            app.Save_now = uimenu(app.Save_setting);
            app.Save_now.MenuSelectedFcn = createCallbackFcn(app, @Save_now_Menu, true);
            app.Save_now.Text = 'Save setting now';
            app.View = uimenu(app.POINCAREUIFigure);
            app.View.Text = '   View';
            app.Percentage_of_RowColumn = uimenu(app.View);
            app.Percentage_of_RowColumn.MenuSelectedFcn = createCallbackFcn(app, @View_percentage, true);
            app.Percentage_of_RowColumn.Checked = 'on';
            app.Percentage_of_RowColumn.Text = 'Percentage of Row/Column';
            app.RowColumn_Completed = uimenu(app.View);
            app.RowColumn_Completed.MenuSelectedFcn = createCallbackFcn(app, @View_completed, true);
            app.RowColumn_Completed.Checked = 'on';
            app.RowColumn_Completed.Text = 'Rows/Columns Completed';
            app.Total_bars = uimenu(app.View);
            app.Total_bars.MenuSelectedFcn = createCallbackFcn(app, @View_total_bars, true);
            app.Total_bars.Checked = 'on';
            app.Total_bars.Text = 'Total Progress';
            app.Wait_bars = uimenu(app.View);
            app.Wait_bars.MenuSelectedFcn = createCallbackFcn(app, @View_wait_bars, true);
            app.Wait_bars.Checked = 'on';
            app.Wait_bars.Text = 'Total Progress Waitbars';
            app.Help = uimenu(app.POINCAREUIFigure);
            app.Help.Text = '    Help';
            app.HelpMenu = uimenu(app.Help);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @Help_Menu, true);
            app.HelpMenu.Text = 'Help';
            app.AboutPOINCAREMenu = uimenu(app.Help);
            app.AboutPOINCAREMenu.MenuSelectedFcn = createCallbackFcn(app, @About_POINCARE_Menu, true);
            app.AboutPOINCAREMenu.Text = 'About POINCARE';
            app.MapPanel = uipanel(app.POINCAREUIFigure);
            app.MapPanel.Title = 'Map';
            app.MapPanel.SizeChangedFcn = createCallbackFcn(app, @popup_MAP_Value_changing, true);
            app.MapPanel.Tag = 'MapPanel';
            app.popupMAP = uidropdown(app.MapPanel);
            app.popupMAP.Items = {'APLE', 'FGL', 'ROT', 'FGLsteep', 'User'};
            app.popupMAP.ValueChangedFcn = createCallbackFcn(app, @popup_MAP_Value_changing, true);
            app.popupMAP.Value = 'APLE';
            app.epsilon1Label = uilabel(app.MapPanel);
            app.epsilon1Label.Interpreter = 'latex';
            app.epsilon1Label.Text = '$\epsilon_1=$';
            app.epsilon3Label = uilabel(app.MapPanel);
            app.epsilon3Label.Interpreter = 'latex';
            app.epsilon3Label.Text = '$\epsilon_3=$';
            app.epsilon2Label = uilabel(app.MapPanel);
            app.epsilon2Label.Interpreter = 'latex';
            app.epsilon2Label.Text = '$\epsilon_2=$';
            app.mLabel = uilabel(app.MapPanel);
            app.mLabel.Interpreter = 'latex';
            app.mLabel.Text = '$m =$';
            app.IterationnumberEditFieldLabel = uilabel(app.MapPanel);
            app.IterationnumberEditFieldLabel.Text = 'Iteration number';
            app.iteration_number = uieditfield(app.MapPanel, 'numeric');
            app.iteration_number.Value = 1000;
            app.edit_epsilon1 = uieditfield(app.MapPanel, 'numeric');
            app.edit_epsilon1.Value = 0.01;
            app.edit_epsilon2 = uieditfield(app.MapPanel, 'numeric');
            app.edit_epsilon2.Value = 0.001;
            app.edit_epsilon3 = uieditfield(app.MapPanel, 'numeric');
            app.edit_epsilon3.Value = 0.001;
            app.edit_m = uieditfield(app.MapPanel, 'numeric');
            app.edit_m.Value = 0.3;
            app.FrequencyModulationIndicatorPanel = uipanel(app.POINCAREUIFigure);
            app.FrequencyModulationIndicatorPanel.Title = 'Frequency Modulation Indicator';
            app.FrequencyModulationIndicatorPanel.Tag = 'FMI';
            app.CalculateandplotArnoldwebButton = uibutton(app.FrequencyModulationIndicatorPanel, 'push');
            app.CalculateandplotArnoldwebButton.ButtonPushedFcn = createCallbackFcn(app, @Calculate_and_plot_ButtonPushed, true);
            app.CalculateandplotArnoldwebButton.Text = {'Calculate and plot'; 'Arnold web'};
            app.StartSlaveButton = uibutton(app.FrequencyModulationIndicatorPanel, 'push');
            app.StartSlaveButton.ButtonPushedFcn = createCallbackFcn(app, @Start_Slave_ButtonPushed, true);
            app.StartSlaveButton.Visible = 'off';
            app.StartSlaveButton.Text = 'Start Slave';
            app.Panel_x1 = uipanel(app.FrequencyModulationIndicatorPanel);
            app.x1_fin = uieditfield(app.Panel_x1, 'numeric');
            app.x1_fin.Value = 0.8;
            app.leqx_1leqLabel = uilabel(app.Panel_x1);
            app.leqx_1leqLabel.Interpreter = 'latex';
            app.leqx_1leqLabel.Text = '$\leq x_1 \leq$';
            app.withstepnumberLabel = uilabel(app.Panel_x1);
            app.withstepnumberLabel.Text = 'with step number';
            app.step_1 = uieditfield(app.Panel_x1, 'numeric');
            app.step_1.Value = 3;
            app.x1_in = uieditfield(app.Panel_x1, 'numeric');
            app.x1_in.Value = 0.3;
            app.Panel_x2 = uipanel(app.FrequencyModulationIndicatorPanel);
            app.x2_in = uieditfield(app.Panel_x2, 'numeric');
            app.x2_in.Value = 0.1;
            app.leqx_2leqLabel = uilabel(app.Panel_x2);
            app.leqx_2leqLabel.Interpreter = 'latex';
            app.leqx_2leqLabel.Text = '$\leq x_2 \leq$';
            app.x2_fin = uieditfield(app.Panel_x2, 'numeric');
            app.x2_fin.Value = 0.5;
            app.step_2 = uieditfield(app.Panel_x2, 'numeric');
            app.step_2.Value = 3;
            app.withstepnumberLabel_2 = uilabel(app.Panel_x2);
            app.withstepnumberLabel_2.Text = 'with step number';
            app.Frequency1Panel = uipanel(app.FrequencyModulationIndicatorPanel);
            app.Frequency1Panel.Title = 'Frequency 1';
            app.ComputethefrequencybetweenLabel = uilabel(app.Frequency1Panel);
            app.ComputethefrequencybetweenLabel.Text = {'Compute the'; 'frequency between'};
            app.freq1_min = uieditfield(app.Frequency1Panel, 'numeric');
            app.freq1_min.Value = -100;
            app.andLabel = uilabel(app.Frequency1Panel);
            app.andLabel.Text = 'and';
            app.freq1_max = uieditfield(app.Frequency1Panel, 'numeric');
            app.freq1_max.Value = 100;
            app.StepnumberforFMI1Label = uilabel(app.Frequency1Panel);
            app.StepnumberforFMI1Label.Text = 'Step number for FMI1';
            app.step_FMI1 = uieditfield(app.Frequency1Panel, 'numeric');
            app.step_FMI1.Value = 6;
            app.Frequency2Panel = uipanel(app.FrequencyModulationIndicatorPanel);
            app.Frequency2Panel.Title = 'Frequency 2';
            app.ComputethefrequencybetweenLabel_2 = uilabel(app.Frequency2Panel);
            app.ComputethefrequencybetweenLabel_2.Text = {'Compute the'; 'frequency between'};
            app.freq2_min = uieditfield(app.Frequency2Panel, 'numeric');
            app.freq2_min.Value = -100;
            app.andLabel_2 = uilabel(app.Frequency2Panel);
            app.andLabel_2.Text = 'and';
            app.freq2_max = uieditfield(app.Frequency2Panel, 'numeric');
            app.freq2_max.Value = 100;
            app.StepnumberforFMI2Label = uilabel(app.Frequency2Panel);
            app.StepnumberforFMI2Label.Text = 'Step number for FMI2';
            app.step_FMI2 = uieditfield(app.Frequency2Panel, 'numeric');
            app.FrequencyDemodulationPanel = uipanel(app.POINCAREUIFigure);
            app.FrequencyDemodulationPanel.Title = 'Frequency Demodulation';
            app.FrequencyDemodulationPanel.ButtonDownFcn = createCallbackFcn(app, @StopLFA_ButtonPushed, true);
            app.FrequencyDemodulationPanel.Tag = 'LFA';
            app.StopFrequencyDemodulationButton = uibutton(app.FrequencyDemodulationPanel, 'push');
            app.StopFrequencyDemodulationButton.ButtonPushedFcn = createCallbackFcn(app, @StopLFA_ButtonPushed, true);
            app.StopFrequencyDemodulationButton.Enable = 'off';
            app.StopFrequencyDemodulationButton.Text = {'Stop Frequency'; 'Demodulation'};
            app.StartFrequencyDemodulationButton = uibutton(app.FrequencyDemodulationPanel, 'push');
            app.StartFrequencyDemodulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartLFA_ButtonPushed, true);
            app.StartFrequencyDemodulationButton.Text = {'Start Frequency '; 'Demodulation'};
            app.x_1Label = uilabel(app.FrequencyDemodulationPanel);
            app.x_1Label.Interpreter = 'latex';
            app.x_1Label.Text = '$x_1=$';
            app.x_2Label = uilabel(app.FrequencyDemodulationPanel);
            app.x_2Label.Interpreter = 'latex';
            app.x_2Label.Text = '$x_2=$';
            app.LFA_x_1 = uieditfield(app.FrequencyDemodulationPanel, 'numeric');
            app.LFA_x_1.Value = 0.3;
            app.LFA_x_2 = uieditfield(app.FrequencyDemodulationPanel, 'numeric');
            app.LFA_x_2.Value = 0.1;
            % Show the figure after all components are created
            app.POINCAREUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Poincare_source

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.POINCAREUIFigure)

            % Execute the startup function
            runStartupFcn(app, @Startup_Function)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.POINCAREUIFigure)
        end
    end
end